classdef P2ROUD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        P2ROUD_UIFigure                 matlab.ui.Figure
        Hyperlink                       matlab.ui.control.Hyperlink
        MessagesTabGroup                matlab.ui.container.TabGroup
        MessagesTab                     matlab.ui.container.Tab
        StatusImage                     matlab.ui.control.Image
        StatusLabel                     matlab.ui.control.Label
        StatusBox                       matlab.ui.control.TextArea
        MessageBox                      matlab.ui.control.TextArea
        SettingsTab                     matlab.ui.container.Tab
        MessagesLabel                   matlab.ui.control.Label
        MessageLevelSpinnerLabel        matlab.ui.control.Label
        MessageLevelSpinner             matlab.ui.control.Spinner
        ColorsButtonGroup               matlab.ui.container.ButtonGroup
        BlackWhiteButton                matlab.ui.control.RadioButton
        ClassicButton                   matlab.ui.control.RadioButton
        ColorsLabel                     matlab.ui.control.Label
        AutoSoundsCheckBoxLabel         matlab.ui.control.Label
        AutoSoundsCheckBox              matlab.ui.control.CheckBox
        SoundsLabel                     matlab.ui.control.Label
        ReconstructionTabGroup          matlab.ui.container.TabGroup
        ImagesTab                       matlab.ui.container.Tab
        MaskButton                      matlab.ui.control.Button
        OpenButton                      matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        DilateButton                    matlab.ui.control.Button
        ErodeButton                     matlab.ui.control.Button
        ThresholdSlider                 matlab.ui.control.Slider
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        OrientationSpinner              matlab.ui.control.Spinner
        OrientationSpinnerLabel         matlab.ui.control.Label
        MovieImage                      matlab.ui.control.Image
        AutoIntensityCheckBoxLabel      matlab.ui.control.Label
        AutoIntensityCheckBox           matlab.ui.control.CheckBox
        MovieStopButton                 matlab.ui.control.Button
        MoviePlayButton                 matlab.ui.control.Button
        SetButton                       matlab.ui.control.Button
        WindowEditField                 matlab.ui.control.NumericEditField
        LevelEditField                  matlab.ui.control.NumericEditField
        WindowSlider                    matlab.ui.control.Slider
        LLabel                          matlab.ui.control.Label
        WLabel                          matlab.ui.control.Label
        LevelSlider                     matlab.ui.control.Slider
        Dim3Label                       matlab.ui.control.Label
        Dim4Label                       matlab.ui.control.Label
        Dim5Label                       matlab.ui.control.Label
        Dim6Label                       matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        Dim3EditField                   matlab.ui.control.NumericEditField
        Dim4EditField                   matlab.ui.control.NumericEditField
        Dim5EditField                   matlab.ui.control.NumericEditField
        Dim6EditField                   matlab.ui.control.NumericEditField
        Dim3Slider                      matlab.ui.control.Slider
        Dim4Slider                      matlab.ui.control.Slider
        Dim5Slider                      matlab.ui.control.Slider
        Dim6Slider                      matlab.ui.control.Slider
        RecoFig                         matlab.ui.control.UIAxes
        MIPTab                          matlab.ui.container.Tab
        MIPResizeButton                 matlab.ui.control.StateButton
        MIPxPlayButton                  matlab.ui.control.Button
        MIPyPlayButton                  matlab.ui.control.Button
        MIPOrientationSpinner           matlab.ui.control.Spinner
        MIPOrientationSpinnerLabel      matlab.ui.control.Label
        MIPXLabel                       matlab.ui.control.Label
        MIPYLabel                       matlab.ui.control.Label
        MIPSetButton                    matlab.ui.control.Button
        MIPWindowEditField              matlab.ui.control.NumericEditField
        MIPLevelEditField               matlab.ui.control.NumericEditField
        MIPWindowSlider                 matlab.ui.control.Slider
        MIPLLabel                       matlab.ui.control.Label
        MIPWLabel                       matlab.ui.control.Label
        MIPLevelSlider                  matlab.ui.control.Slider
        MIPAngleResetButton             matlab.ui.control.Button
        MIPRotateLabel                  matlab.ui.control.Label
        MIPxAngleLabel                  matlab.ui.control.Label
        MIPyAngleLabel                  matlab.ui.control.Label
        MIPxAngleSlider                 matlab.ui.control.Slider
        MIPyAngleSlider                 matlab.ui.control.Slider
        MIPFig                          matlab.ui.control.UIAxes
        PhaseTab                        matlab.ui.container.Tab
        PhaseMinLabel                   matlab.ui.control.Label
        PhaseMaxLabel                   matlab.ui.control.Label
        PhaseUnwrapButton               matlab.ui.control.Button
        PhaseOrientationSpinner         matlab.ui.control.Spinner
        PhaseOrientationSpinnerLabel    matlab.ui.control.Label
        PhaseMovieImage                 matlab.ui.control.Image
        PhaseDim6Label                  matlab.ui.control.Label
        PhaseDim5Label                  matlab.ui.control.Label
        PhaseDim4Label                  matlab.ui.control.Label
        PhaseDim3Label                  matlab.ui.control.Label
        PhaseDim6Slider                 matlab.ui.control.Slider
        PhaseDim6EditField              matlab.ui.control.NumericEditField
        PhaseDim4EditField              matlab.ui.control.NumericEditField
        PhaseDim5EditField              matlab.ui.control.NumericEditField
        PhaseDim3EditField              matlab.ui.control.NumericEditField
        PhaseDim4Slider                 matlab.ui.control.Slider
        PhaseDim5Slider                 matlab.ui.control.Slider
        PhaseDim3Slider                 matlab.ui.control.Slider
        PhaseMovieStopButton            matlab.ui.control.Button
        PhaseMovieStartButton           matlab.ui.control.Button
        PhaseXLabel                     matlab.ui.control.Label
        PhaseYLabel                     matlab.ui.control.Label
        PhaseRecoFig                    matlab.ui.control.UIAxes
        FlowTab                         matlab.ui.container.Tab
        FlowMaxLabel                    matlab.ui.control.Label
        FlowMinLabel                    matlab.ui.control.Label
        FlowVencLamp                    matlab.ui.control.Lamp
        FlowDirectionSpinner            matlab.ui.control.Spinner
        FlowDirectionSpinnerLabel       matlab.ui.control.Label
        FlowLoadVencTableButton         matlab.ui.control.Button
        FlowCalcButton                  matlab.ui.control.Button
        FlowMovieStopButton             matlab.ui.control.Button
        FlowMovieStartButton            matlab.ui.control.Button
        FlowOrientationSpinner          matlab.ui.control.Spinner
        FlowOrientationSpinnerLabel     matlab.ui.control.Label
        FlowMovieImage                  matlab.ui.control.Image
        FlowDim6Label                   matlab.ui.control.Label
        FlowDim5Label                   matlab.ui.control.Label
        FlowDim4Label                   matlab.ui.control.Label
        FlowDim3Label                   matlab.ui.control.Label
        FlowDim6Slider                  matlab.ui.control.Slider
        FlowDim6EditField               matlab.ui.control.NumericEditField
        FlowDim4EditField               matlab.ui.control.NumericEditField
        FlowDim5EditField               matlab.ui.control.NumericEditField
        FlowDim3EditField               matlab.ui.control.NumericEditField
        FlowDim4Slider                  matlab.ui.control.Slider
        FlowDim5Slider                  matlab.ui.control.Slider
        FlowDim3Slider                  matlab.ui.control.Slider
        FlowXLabel                      matlab.ui.control.Label
        FlowYLabel                      matlab.ui.control.Label
        FlowRecoFig                     matlab.ui.control.UIAxes
        ScanParameterTabGroup           matlab.ui.container.TabGroup
        ScanParametersTab               matlab.ui.container.Tab
        VencViewField                   matlab.ui.control.EditField
        VencViewFieldLabel              matlab.ui.control.Label
        TimeDynViewField                matlab.ui.control.EditField
        TimeDynViewFieldLabel           matlab.ui.control.Label
        ScanTimeViewField               matlab.ui.control.EditField
        ScanTimeViewFieldLabel          matlab.ui.control.Label
        TrajectoryViewField             matlab.ui.control.EditField
        TrajectoryViewFieldLabel        matlab.ui.control.Label
        FAViewField                     matlab.ui.control.EditField
        FAViewFieldLabel                matlab.ui.control.Label
        MRDfileViewField                matlab.ui.control.EditField
        MRDfileViewFieldLabel           matlab.ui.control.Label
        NEViewField                     matlab.ui.control.NumericEditField
        NEViewFieldLabel                matlab.ui.control.Label
        NFAViewField                    matlab.ui.control.NumericEditField
        NFAViewFieldLabel               matlab.ui.control.Label
        NAViewField                     matlab.ui.control.NumericEditField
        NAViewFieldLabel                matlab.ui.control.Label
        TEViewField                     matlab.ui.control.NumericEditField
        TEViewFieldLabel                matlab.ui.control.Label
        TRViewField                     matlab.ui.control.NumericEditField
        TRViewFieldLabel                matlab.ui.control.Label
        FOVViewField1                   matlab.ui.control.NumericEditField
        FOVViewField1Label              matlab.ui.control.Label
        KMatrixViewField1               matlab.ui.control.NumericEditField
        KMatrixViewField1Label          matlab.ui.control.Label
        SequenceViewField               matlab.ui.control.EditField
        SequenceViewFieldLabel          matlab.ui.control.Label
        NRViewField                     matlab.ui.control.NumericEditField
        NRViewFieldLabel                matlab.ui.control.Label
        MZLabel                         matlab.ui.control.Label
        MYLabel                         matlab.ui.control.Label
        MXLabel                         matlab.ui.control.Label
        FOVViewField3                   matlab.ui.control.NumericEditField
        FOVViewField2                   matlab.ui.control.NumericEditField
        KMatrixViewField3               matlab.ui.control.NumericEditField
        KMatrixViewField2               matlab.ui.control.NumericEditField
        GeometryTab                     matlab.ui.container.Tab
        SlabOverlapEditField            matlab.ui.control.NumericEditField
        SlabOverlapEditFieldLabel       matlab.ui.control.Label
        SliceGapViewField               matlab.ui.control.NumericEditField
        SliceGapViewFieldLabel          matlab.ui.control.Label
        AngleZViewFieldLabel            matlab.ui.control.Label
        AngleZViewField                 matlab.ui.control.NumericEditField
        AngleYViewFieldLabel            matlab.ui.control.Label
        AngleYViewField                 matlab.ui.control.NumericEditField
        AngleXViewFieldLabel            matlab.ui.control.Label
        AngleXViewField                 matlab.ui.control.NumericEditField
        OffsetZViewFieldLabel           matlab.ui.control.Label
        OffsetZViewField                matlab.ui.control.NumericEditField
        OffsetYViewField                matlab.ui.control.NumericEditField
        OffsetYViewFieldLabel           matlab.ui.control.Label
        OffsetXViewField                matlab.ui.control.NumericEditField
        OffsetXViewFieldLabel           matlab.ui.control.Label
        SliceThicknessViewField         matlab.ui.control.NumericEditField
        SliceThicknessViewFieldLabel    matlab.ui.control.Label
        PhaseOrientationViewField       matlab.ui.control.NumericEditField
        PhaseOrientationViewFieldLabel  matlab.ui.control.Label
        AspectRatioViewField            matlab.ui.control.NumericEditField
        AspectRatioViewFieldLabel       matlab.ui.control.Label
        FieldOfViewViewField2           matlab.ui.control.NumericEditField
        FieldOfViewViewField2Label      matlab.ui.control.Label
        ReconstructionTab               matlab.ui.container.Tab
        SumEchosLabel                   matlab.ui.control.Label
        SumEchosCheckBox                matlab.ui.control.CheckBox
        MultiEchoLabel                  matlab.ui.control.Label
        ZTEIterationsLabel              matlab.ui.control.Label
        ZTEIterationsEditField          matlab.ui.control.NumericEditField
        DLModelDropDown                 matlab.ui.control.DropDown
        DLModelDropDownLabel            matlab.ui.control.Label
        DeNoisingLabel                  matlab.ui.control.Label
        DeeplearningLabel               matlab.ui.control.Label
        DLdenoiseCheckBox               matlab.ui.control.CheckBox
        CompressedsensingLabel          matlab.ui.control.Label
        LLRxyzEditFieldLabel            matlab.ui.control.Label
        LLRxyzEditField                 matlab.ui.control.NumericEditField
        WVxyzEditFieldLabel             matlab.ui.control.Label
        WVxyzEditField                  matlab.ui.control.NumericEditField
        TVtimeEditFieldLabel            matlab.ui.control.Label
        TVtimeEditField                 matlab.ui.control.NumericEditField
        TVxyzEditFieldLabel             matlab.ui.control.Label
        TVxyzEditField                  matlab.ui.control.NumericEditField
        PCAdeNoiseWindowEditField       matlab.ui.control.NumericEditField
        PCAdeNoiseWindowLabel           matlab.ui.control.Label
        PCAdeNoiseCheckBox              matlab.ui.control.CheckBox
        PCAdeNoiseLabel                 matlab.ui.control.Label
        AFGPdeNoiseIterationsEditField  matlab.ui.control.NumericEditField
        AFGPdeNoiseIterationsLabel      matlab.ui.control.Label
        AFGPdeNoiseCheckBox             matlab.ui.control.CheckBox
        AFGPdeNoiseLabel                matlab.ui.control.Label
        CDTVdeNoiseLambdaEditField      matlab.ui.control.NumericEditField
        CDTVdeNoiseLamdaLabel           matlab.ui.control.Label
        CDTVdeNoiseCheckBox             matlab.ui.control.CheckBox
        CDTVdeNoiseLabel                matlab.ui.control.Label
        NLMFdeNoiseFilterStrengthEditField  matlab.ui.control.NumericEditField
        NLMFdeNoiseFilterStrengthLabel  matlab.ui.control.Label
        NLMFdeNoiseCheckBox             matlab.ui.control.CheckBox
        NLMFdeNoiseLabel                matlab.ui.control.Label
        DeRingLabel                     matlab.ui.control.Label
        DeRingCheckBox                  matlab.ui.control.CheckBox
        RadialTab                       matlab.ui.container.Tab
        ZTEPanel                        matlab.ui.container.Panel
        ZTEShiftLabel                   matlab.ui.control.Label
        ZTEShiftEditField               matlab.ui.control.NumericEditField
        ZTEDeadTimeLabel                matlab.ui.control.Label
        ZTEDeadTimeEditField            matlab.ui.control.NumericEditField
        ZTEFullEchoLabel                matlab.ui.control.Label
        ZTEFullEchoCheckBox             matlab.ui.control.CheckBox
        CorrectionsPanel                matlab.ui.container.Panel
        TukeyFilterCheckBoxLabel        matlab.ui.control.Label
        TukeyFilterCheckBox             matlab.ui.control.CheckBox
        AmplitudeCorrectionCheckBoxLabel  matlab.ui.control.Label
        AmplitudeCorrectionCheckBox     matlab.ui.control.CheckBox
        DensityCorrectCheckBoxLabel     matlab.ui.control.Label
        DensityCorrectCheckBox          matlab.ui.control.CheckBox
        CenterEchoCheckBoxLabel         matlab.ui.control.Label
        CenterEchoCheckBox              matlab.ui.control.CheckBox
        PhaseCorrectCheckBoxLabel       matlab.ui.control.Label
        PhaseCorrectCheckBox            matlab.ui.control.CheckBox
        GradientdelaysPanel             matlab.ui.container.Panel
        DataOffsetRadialEditField       matlab.ui.control.NumericEditField
        DataOffsetRadialEditFieldLabel  matlab.ui.control.Label
        GzDelayEditField                matlab.ui.control.NumericEditField
        GzdelayEditFieldLabel           matlab.ui.control.Label
        GyDelayEditField                matlab.ui.control.NumericEditField
        GydelayEditFieldLabel           matlab.ui.control.Label
        GxDelayEditField                matlab.ui.control.NumericEditField
        GxdelayEditFieldLabel           matlab.ui.control.Label
        AbortGradCalButton              matlab.ui.control.Button
        RingMethodCheckBoxLabel         matlab.ui.control.Label
        RingMethodCheckBox              matlab.ui.control.CheckBox
        GradDelayCalibrationCheckBoxLabel  matlab.ui.control.Label
        GradDelayCalibrationCheckBox    matlab.ui.control.CheckBox
        KspaceTabGroup                  matlab.ui.container.TabGroup
        KspaceTab                       matlab.ui.container.Tab
        CoilSpinner                     matlab.ui.control.Spinner
        CoilSpinnerLabel                matlab.ui.control.Label
        KFlipAngleEditField             matlab.ui.control.NumericEditField
        KFlipAngleEditFieldLabel        matlab.ui.control.Label
        KOrientationSpinner             matlab.ui.control.Spinner
        KOrientationSpinnerLabel        matlab.ui.control.Label
        KEchoEditField                  matlab.ui.control.NumericEditField
        KEchoEditFieldLabel             matlab.ui.control.Label
        KWindowEditField                matlab.ui.control.NumericEditField
        KLevelEditField                 matlab.ui.control.NumericEditField
        KWindowSlider                   matlab.ui.control.Slider
        KLLabel                         matlab.ui.control.Label
        KWLabel                         matlab.ui.control.Label
        KLevelSlider                    matlab.ui.control.Slider
        FastSlowSwitch                  matlab.ui.control.RockerSwitch
        KspaceMovieLabel                matlab.ui.control.Label
        KStopButton                     matlab.ui.control.Button
        KStartButton                    matlab.ui.control.Button
        FillingPercLabel                matlab.ui.control.Label
        FillingViewField                matlab.ui.control.NumericEditField
        KFlipAngleSlider                matlab.ui.control.Slider
        KDim4Label                      matlab.ui.control.Label
        KEchoSlider                     matlab.ui.control.Slider
        KDim3Label                      matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        senseMapButton                  matlab.ui.control.RadioButton
        fillingButton                   matlab.ui.control.RadioButton
        averagesButton                  matlab.ui.control.RadioButton
        kValuesButton                   matlab.ui.control.RadioButton
        KyLabel                         matlab.ui.control.Label
        KxLabel                         matlab.ui.control.Label
        KDim4EditField                  matlab.ui.control.NumericEditField
        KDim4Slider                     matlab.ui.control.Slider
        KDim3Slider                     matlab.ui.control.Slider
        KDim3EditField                  matlab.ui.control.NumericEditField
        KspaceFig                       matlab.ui.control.UIAxes
        CoilsTab                        matlab.ui.container.Tab
        KDim3EditFieldS                 matlab.ui.control.NumericEditField
        KDim3LabelS                     matlab.ui.control.Label
        SensitivityMapsPanel            matlab.ui.container.Panel
        KDim3SliderS                    matlab.ui.control.Slider
        Label5                          matlab.ui.control.Label
        Label1                          matlab.ui.control.Label
        Label8                          matlab.ui.control.Label
        Label7                          matlab.ui.control.Label
        Label6                          matlab.ui.control.Label
        Label4                          matlab.ui.control.Label
        Label3                          matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        SenseFig8                       matlab.ui.control.UIAxes
        SenseFig7                       matlab.ui.control.UIAxes
        SenseFig6                       matlab.ui.control.UIAxes
        SenseFig5                       matlab.ui.control.UIAxes
        SenseFig4                       matlab.ui.control.UIAxes
        SenseFig3                       matlab.ui.control.UIAxes
        SenseFig2                       matlab.ui.control.UIAxes
        SenseFig1                       matlab.ui.control.UIAxes
        CoilSelectionPanel              matlab.ui.container.Panel
        AutoSensitivityCheckBox         matlab.ui.control.CheckBox
        RelativeSensitivityLabel        matlab.ui.control.Label
        CoilLabel                       matlab.ui.control.Label
        S8EditField                     matlab.ui.control.NumericEditField
        S7EditField                     matlab.ui.control.NumericEditField
        S6EditField                     matlab.ui.control.NumericEditField
        S5EditField                     matlab.ui.control.NumericEditField
        S4EditField                     matlab.ui.control.NumericEditField
        S3EditField                     matlab.ui.control.NumericEditField
        S2EditField                     matlab.ui.control.NumericEditField
        S1EditField                     matlab.ui.control.NumericEditField
        C8CheckBox                      matlab.ui.control.CheckBox
        C7CheckBox                      matlab.ui.control.CheckBox
        C6CheckBox                      matlab.ui.control.CheckBox
        C5CheckBox                      matlab.ui.control.CheckBox
        C4CheckBox                      matlab.ui.control.CheckBox
        C3CheckBox                      matlab.ui.control.CheckBox
        C2CheckBox                      matlab.ui.control.CheckBox
        C1CheckBox                      matlab.ui.control.CheckBox
        DashboardTabGroup               matlab.ui.container.TabGroup
        DashboardTab                    matlab.ui.container.Tab
        CSRecoLabel                     matlab.ui.control.Label
        CSRecoCheckBox                  matlab.ui.control.CheckBox
        ExitButton                      matlab.ui.control.Button
        ExportPanel                     matlab.ui.container.Panel
        Lamp6                           matlab.ui.control.Lamp
        Lamp5                           matlab.ui.control.Lamp
        Lamp4                           matlab.ui.control.Lamp
        Lamp3                           matlab.ui.control.Lamp
        ExportGIFButton                 matlab.ui.control.Button
        ExportMRDButton                 matlab.ui.control.Button
        ExportNIFTIButton               matlab.ui.control.Button
        ExportProgressGauge             matlab.ui.control.LinearGauge
        ExportDCMButton                 matlab.ui.control.Button
        ReconstructionParametersPanel   matlab.ui.container.Panel
        AbortRecoButton                 matlab.ui.control.Button
        RecoProgressGauge               matlab.ui.control.LinearGauge
        Lamp2                           matlab.ui.control.Lamp
        RecoimagesButton                matlab.ui.control.Button
        ReconstructionDimensionsPanel   matlab.ui.container.Panel
        NREditFieldLabel                matlab.ui.control.Label
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditFieldLabel                 matlab.ui.control.Label
        XEditFieldLabel                 matlab.ui.control.Label
        NREditField                     matlab.ui.control.NumericEditField
        ZEditField                      matlab.ui.control.NumericEditField
        YEditField                      matlab.ui.control.NumericEditField
        XEditField                      matlab.ui.control.NumericEditField
        Lamp1                           matlab.ui.control.Lamp
        LoadMRDButton                   matlab.ui.control.Button
        P2ROUDLabel                     matlab.ui.control.Label
        MRSImage                        matlab.ui.control.Image
        CreditsLabel                    matlab.ui.control.Label
        TulpImage                       matlab.ui.control.Image
        TitleLabel                      matlab.ui.control.Label
    end


    % ------------------------------------------------------
    %
    % P2ROUD
    % Preclinical PROspective Undersampling in multiple Dimensions
    % Matlab 2025a
    %
    % Gustav Strijkers
    % Amsterdam UMC
    % g.j.strijkers@amsterdamumc.nl
    % July 2025 
    %
    %
    % Version 1.0
    % - initial commit
    %
    % Version 1.1
    % - add scaling for k-space visualization
    % - corrected orientation of odd-echo images in multi-gradient-echo
    %
    % Version 1.2
    % - bug fixes
    % - reconstruction of CINE images, for which NE-dimension is the CINE loop
    % - GIF export
    % - Image zoom
    % - NIFTI export for 2D multislice data
    % - Bug fix in DICOM export
    % - Bug fix in NIFTI export dimensions
    %
    % Version 1.3
    % - multi coil reconstruction panel
    % - bug fixes
    % - read total acquisition time from rpr file if available
    %
    % Version 1.4
    % - Low-rank in spatial domain reconstruction added (with BART)
    % - Improved Tukey filter centered around k-space maximum
    % - rtable.rtv is renamed to origrtable.rtv.orig to prevent resorting of kspace with sur export
    % - removed CINE flipping of odd images, for some reason not needed anymore
    % - Added reconstruction parameter export fil
    %
    % Version 3.0
    % - New proudData class introduced for easier app maintenance
    % - Various bug fixes
    % - MIP visualization
    %
    % Version 3.1
    % - Noise suppression algorithm
    %
    % Version 4.0
    % - Phase and 4D-flow reconstruction
    %
    % Version 4.1 
    % - Bug fixes
    %
    % Version 4.2
    % - Some improvements to the MIP
    % - Bug fixes
    %
    % Version 4.3
    % - Bug fixes
    %
    % Version 5.0
    % - 2D radial reconstruction
    % - Image shifts for off-center acquistitions correctly incorporated
    % - Image orientation labels
    % - Mouse wheel scrolling for main image plot added
    % - Import of partially completed acquisition possible
    %
    % Version 5.2
    % - P2ROUD k-space trajectories, with pe1_order = 4 (exLUT)
    % - multi-echo, multi-experiments, multi-flip-angle, P2ROUD reconstruction
    % - Gibbs ringing suppression filter
    % - Improved reconstructions
    % - PCA denoising
    % - Segmented k-space filling
    %
    % Version 5.3
    % - Improvements to 3D image reconstruction (magnitude and phase images)
    % - Logaritmic k-space image for better visibility
    % - Fixed removal of navigator for data that has one
    % - EPI reconstruction improvements
    % - Tooltips updated
    %
    % Version 5.4
    % - Coil sensitivity maps
    %
    % Version 5.5
    % - Rewritten the reconstruction methods  
    % - Some bug fixes sensitivity maps
    %
    % Version 5.6
    % - Black/white & Classic color scheme
    % - Bug fix segmented k-space 
    % - Improved Ecalib coil sensitivity estimations
    %
    % Version 5.7
    % - FLASH sequence version check for off-center corrections
    % - P2ROUD app version check
    % - missing rtable bug with MRD export fixed
    % - DICOM export improvements
    % - AI denoising
    % - App compatible with new external navigators
    % - Fixed problem with multi-slice acquisition and zigzag k-space filling
    %
    % Version 6.0
    % - ZTE reconstruction (regridding and CS)
    % - GPU support for BART
    % - MPRAGE import
    % - Working on new 3D LUT import for undersampled k-spaces
    %
    % Version 6.1
    % - Bug fixes in MRD export, Dicom export
    % - Check for some tags in dicom export
    % - NonReg LUT import
    % 
    %
    %
    %
    %


    % --------------------------------------------------------------
    %
    %                     PUBLIC PROPERTIES
    %
    % --------------------------------------------------------------

    properties (Access = public)

        % Proud class
        pd                          proudData       % Data and meta data 

        % Version control
        appVersion = "6.1.9"        % App version
        bartVersion                 % Bart version
        GPUpresentFlag = false      % GPU present for Bart
        AI = false                  % Compile using AI modules

        % Files and directories
        appDir                      % Application directory
        oldDir                      % Starting directory
        currentDir                  % Deployed directory
        mrdImportPath               % MRD import path
        mrdExportPath               % MRD export path
        dicomExportPath             % Dicom export path
        niftiExportPath             % NIFTI export path
        gifExportPath               % GIF export path
        tag                         % Filename tag
        rtableFile                  % rtable file name
        logFile                     % log file ID
       
        % Flags
        bartDetectedFlag            % Bart toolbox detected true or false
        aiDetectedFlag = false      % Deep learning modules present true or false
        mrdFlag                     % MRD directory checked true or false
        dicomFlag                   % Dicom directory checked true or false
        forceProudFlag              % Force P2ROUD trajectory input true / false
        validNIFTIdataFlag          % Data suitable for NIFTI export true or false
        validMovieFlag              % Data with multiple repetitions true or false
        newFigFlag                  % New figure flag false or true
        newPhaseFigFlag             % New phase figure flag false or true
        newFlowFigFlag              % New flow figure flag false or true
        stopRecoFlag                % Stop the reco true or false
        stopMovieFlag               % Movie running (false) or stopping (true)
        stopPhaseMovieFlag          % Movie running (false) or stopping (true)
        stopKspaceMovieFlag         % K movie running (false) or stopping (true)
        stopMIPxMovieFlag           % MIP x movie running (false) or stopping (true)
        stopMIPyMovieFlag           % MIP y movie running (false) or stopping (true)
        stopFlowMovieFlag           % Flow movie running (false) or stopping (true)
        stopGradCalFlag             % Stop the grdient calibration (false/true)
        scrollEnableFlag            % Mouse scroll wheel enable true/false
        blockMessagesFlag = false   % block messages in message window
       
        % Figure & plot elements
        imageOrient                 % Image orientation permutation matrix
        kSpaceOrientLabel           % Orientation labels in k-space figure
        imageOrientLabel            % Orientation labels in image figure
        cbar                        % Kspace averages and filling colorbar
        rect                        % Rectangle box
        green                       % Other shade of green
        brightgreen                 % Green
        red                         % Other shade of red
        brightred                   % Red
        blue                        % Blue
        yellow                      % Yellow
        orange                      % Orange
        guiBlack                    % app black
        guiGrey                     % app grey
        guiMiddleGrey               % app middle grey
        guiDarkGrey                 % app dark grey
        guiWhite                    % app white
        guiBlackBlue                % app black/blueish
        kmovieFig                   % Kmovie fig object
        kSpaceButtonStatus          % Status of the k-space panel buttons
        imageButtonStatus           % Status of the image panel buttons
        recoImage                   % Reconstructed image object
        phaseRecoImage              % Reconstructed phase image object
        flowRecoImage               % Reconstructed flow image object
        blackImage                  % Black background image
        MIPcounter                  % MIP angle counter
        MIPstep = 5                 % MIP angle step size
        structElement               % Image morphological operation structuring element
        progressCounter             % Reconstruction progress counter
        totalCounter                % Total number of reconstruction steps

        % Sounds
        warningSound                % Warning sound
        errorSound                  % Error sound

    end


    % --------------------------------------------------------------
    %
    %                     PRIVATE FUNCTIONS
    %
    % --------------------------------------------------------------


    methods (Access = private)


        % --------------------------------------------------------------
        %
        %                     LIGHTS AND BUTTONS
        %
        % --------------------------------------------------------------

        % Set the lights
        function SetLightsFcn(app,code)

            if ~strcmp(code{1},'Null') app.Lamp1.Color = code{1}; end %#ok<*SEPEX> 
            if ~strcmp(code{2},'Null') app.Lamp2.Color = code{2}; end
            if ~strcmp(code{3},'Null') app.Lamp3.Color = code{3}; end
            if ~strcmp(code{4},'Null') app.Lamp4.Color = code{4}; end
            if ~strcmp(code{5},'Null') app.Lamp5.Color = code{5}; end
            if ~strcmp(code{6},'Null') app.Lamp6.Color = code{6}; end
            drawnow;

        end


        % Set the buttons
        function SetButtonsFcn(app,code)

            if ~strcmp(code{1},'Null') app.LoadMRDButton.Enable = code{1}; end
            if ~strcmp(code{2},'Null') app.RecoimagesButton.Enable = code{2}; end
            if ~strcmp(code{3},'Null') app.ExportDCMButton.Enable = code{3}; end
            if ~strcmp(code{4},'Null') app.ExportNIFTIButton.Enable = code{4}; end
            if ~strcmp(code{5},'Null') app.ExportMRDButton.Enable = code{5}; end
            if ~strcmp(code{6},'Null') app.ExportGIFButton.Enable = code{6}; end
            if ~strcmp(code{7},'Null') app.ExitButton.Enable = code{7}; end
            drawnow;

        end


        % Set application colors
        function SetAppColorsFcn(app, guiColors)
                
            backgroundColor = guiColors{1}; 
            fontColor = guiColors{2}; 
            boxColor = guiColors{3}; 
            gaugeColor = guiColors{4}; 
            gaugeBackgroundColor = guiColors{5}; 
            figureColor = guiColors{6}; 
            movieFigureColor = guiColors{7}; 
            % gridColor = guiColors{8}; 
        
            % Main tabs
            app.P2ROUD_UIFigure.Color = backgroundColor;
            app.DashboardTab.BackgroundColor = backgroundColor;
            app.KspaceTab.BackgroundColor = backgroundColor;
            app.CoilsTab.BackgroundColor = backgroundColor;
            app.RadialTab.BackgroundColor = backgroundColor;
            app.ScanParametersTab.BackgroundColor = backgroundColor;
            app.GeometryTab.BackgroundColor = backgroundColor;
            app.ReconstructionTab.BackgroundColor = backgroundColor;
            app.ImagesTab.BackgroundColor = backgroundColor;
            app.MIPTab.BackgroundColor = backgroundColor;
            app.PhaseTab.BackgroundColor = backgroundColor;
            app.FlowTab.BackgroundColor = backgroundColor;
            app.MessagesTab.BackgroundColor = backgroundColor;
            app.SettingsTab.BackgroundColor = backgroundColor;

            % Panels & button groups
            app.ReconstructionDimensionsPanel.BackgroundColor = backgroundColor;
            app.ReconstructionParametersPanel.BackgroundColor = backgroundColor;
            app.ExportPanel.BackgroundColor = backgroundColor;
            app.ButtonGroup.BackgroundColor = backgroundColor;
            app.CoilSelectionPanel.BackgroundColor = backgroundColor;
            app.SensitivityMapsPanel.BackgroundColor = backgroundColor;
            app.GradientdelaysPanel.BackgroundColor = backgroundColor;
            app.CorrectionsPanel.BackgroundColor = backgroundColor;
            app.ColorsButtonGroup.BackgroundColor = backgroundColor;
            app.MessageBox.FontColor = boxColor;
            app.MessageBox.BackgroundColor = figureColor;
            app.StatusBox.BackgroundColor = figureColor;
            app.ZTEPanel.BackgroundColor = backgroundColor;

            % Fonts
            app.XEditFieldLabel.FontColor = fontColor;
            app.YEditFieldLabel.FontColor = fontColor;
            app.ZEditFieldLabel.FontColor = fontColor;
            app.NREditFieldLabel.FontColor = fontColor;
            app.WVxyzEditFieldLabel.FontColor = fontColor;
            app.TVxyzEditFieldLabel.FontColor = fontColor;
            app.LLRxyzEditFieldLabel.FontColor = fontColor;
            app.TVtimeEditFieldLabel.FontColor = fontColor;
            app.CSRecoLabel.FontColor = fontColor;
            app.PCAdeNoiseLabel.FontColor = fontColor;
            app.PCAdeNoiseWindowLabel.FontColor = fontColor;
            app.AFGPdeNoiseLabel.FontColor = fontColor;
            app.AFGPdeNoiseIterationsLabel.FontColor = fontColor;
            app.CDTVdeNoiseLabel.FontColor = fontColor;
            app.CDTVdeNoiseLamdaLabel.FontColor = fontColor;
            app.NLMFdeNoiseLabel.FontColor = fontColor;
            app.NLMFdeNoiseFilterStrengthLabel.FontColor = fontColor;
            app.DeRingLabel.FontColor = fontColor;
            app.DeeplearningLabel.FontColor = fontColor;
            app.DLModelDropDownLabel.FontColor = fontColor;
            app.ZTEShiftLabel.FontColor = fontColor;
            app.ZTEDeadTimeLabel.FontColor = fontColor;
            app.ZTEFullEchoLabel.FontColor = fontColor;
            app.ZTEIterationsLabel.FontColor = fontColor;
            app.SumEchosLabel.FontColor = fontColor; 

            app.KspaceMovieLabel.FontColor = fontColor;
            app.KyLabel.FontColor = fontColor;
            app.KxLabel.FontColor = fontColor;
            app.KDim3Label.FontColor = fontColor;
            app.KDim3LabelS.FontColor = fontColor;
            app.KDim4Label.FontColor = fontColor;
            app.KFlipAngleEditFieldLabel.FontColor = fontColor;
            app.KEchoEditFieldLabel.FontColor = fontColor;
            app.KOrientationSpinnerLabel.FontColor = fontColor;
            app.FillingPercLabel.FontColor = fontColor;
            app.fillingButton.FontColor = fontColor;
            app.averagesButton.FontColor = fontColor;
            app.kValuesButton.FontColor = fontColor;
            app.senseMapButton.FontColor = fontColor;
            app.CoilSpinnerLabel.FontColor = fontColor;
            app.KLLabel.FontColor = fontColor;
            app.KWLabel.FontColor = fontColor;
            app.FastSlowSwitch.FontColor = fontColor;

            app.CoilLabel.FontColor = fontColor;
            app.RelativeSensitivityLabel.FontColor = fontColor;
            app.AutoSensitivityCheckBox.FontColor = fontColor;
            app.C1CheckBox.FontColor = fontColor;
            app.C2CheckBox.FontColor = fontColor;
            app.C3CheckBox.FontColor = fontColor;
            app.C4CheckBox.FontColor = fontColor;
            app.C5CheckBox.FontColor = fontColor;
            app.C6CheckBox.FontColor = fontColor;
            app.C7CheckBox.FontColor = fontColor;
            app.C8CheckBox.FontColor = fontColor;
            app.Label1.FontColor = fontColor;
            app.Label2.FontColor = fontColor;
            app.Label3.FontColor = fontColor;
            app.Label4.FontColor = fontColor;
            app.Label5.FontColor = fontColor;
            app.Label6.FontColor = fontColor;
            app.Label7.FontColor = fontColor;
            app.Label8.FontColor = fontColor;

            app.GradDelayCalibrationCheckBoxLabel.FontColor = fontColor;
            app.RingMethodCheckBoxLabel.FontColor = fontColor;
            app.GxdelayEditFieldLabel.FontColor = fontColor;
            app.GydelayEditFieldLabel.FontColor = fontColor;
            app.GzdelayEditFieldLabel.FontColor = fontColor;
            app.DataOffsetRadialEditFieldLabel.FontColor = fontColor;
            app.CenterEchoCheckBoxLabel.FontColor = fontColor;
            app.PhaseCorrectCheckBoxLabel.FontColor = fontColor;
            app.AmplitudeCorrectionCheckBoxLabel.FontColor = fontColor;
            app.DensityCorrectCheckBoxLabel.FontColor = fontColor;
            app.TukeyFilterCheckBoxLabel.FontColor = fontColor;

            app.TRViewFieldLabel.FontColor = fontColor;
            app.TEViewFieldLabel.FontColor = fontColor;
            app.NEViewFieldLabel.FontColor = fontColor;
            app.NAViewFieldLabel.FontColor = fontColor;
            app.NFAViewFieldLabel.FontColor = fontColor;
            app.NRViewFieldLabel.FontColor = fontColor;
            app.MRDfileViewFieldLabel.FontColor = fontColor;
            app.SequenceViewFieldLabel.FontColor = fontColor;
            app.ScanTimeViewFieldLabel.FontColor = fontColor;
            app.TimeDynViewFieldLabel.FontColor = fontColor;
            app.FAViewFieldLabel.FontColor = fontColor;
            app.TrajectoryViewFieldLabel.FontColor = fontColor;
            app.VencViewFieldLabel.FontColor = fontColor;
            app.MXLabel.FontColor = fontColor;
            app.MYLabel.FontColor = fontColor;
            app.MZLabel.FontColor = fontColor;
            app.KMatrixViewField1Label.FontColor = fontColor;
            app.FOVViewField1Label.FontColor = fontColor;
            app.MessageLevelSpinnerLabel.FontColor = fontColor;
    
            app.FieldOfViewViewField2Label.FontColor = fontColor;
            app.AspectRatioViewFieldLabel.FontColor = fontColor;
            app.PhaseOrientationViewFieldLabel.FontColor = fontColor;
            app.SliceThicknessViewFieldLabel.FontColor = fontColor;
            app.SliceGapViewFieldLabel.FontColor = fontColor;
            app.SlabOverlapEditFieldLabel.FontColor = fontColor;
            app.OffsetXViewFieldLabel.FontColor = fontColor;
            app.OffsetYViewFieldLabel.FontColor = fontColor;
            app.OffsetZViewFieldLabel.FontColor = fontColor;
            app.AngleXViewFieldLabel.FontColor = fontColor;
            app.AngleYViewFieldLabel.FontColor = fontColor;
            app.AngleZViewFieldLabel.FontColor = fontColor;

            app.XLabel.FontColor = fontColor;
            app.YLabel.FontColor = fontColor;
            app.ThresholdEditFieldLabel.FontColor = fontColor;
            app.AutoIntensityCheckBoxLabel.FontColor = fontColor;
            app.WLabel.FontColor = fontColor;
            app.LLabel.FontColor = fontColor;
            app.OrientationSpinnerLabel.FontColor = fontColor;
            app.Dim6Label.FontColor = fontColor;
            app.Dim5Label.FontColor = fontColor;
            app.Dim4Label.FontColor = fontColor;
            app.Dim3Label.FontColor = fontColor;
            
            app.MIPXLabel.FontColor = fontColor;
            app.MIPYLabel.FontColor = fontColor;
            app.MIPWLabel.FontColor = fontColor;
            app.MIPLLabel.FontColor = fontColor;
            app.MIPOrientationSpinnerLabel.FontColor = fontColor;
            app.MIPxAngleLabel.FontColor = fontColor;
            app.MIPyAngleLabel.FontColor = fontColor;
            app.MIPRotateLabel.FontColor = fontColor;

            app.PhaseXLabel.FontColor = fontColor;
            app.PhaseYLabel.FontColor = fontColor;
            app.PhaseOrientationSpinnerLabel.FontColor = fontColor;
            app.PhaseMinLabel.FontColor = fontColor;
            app.PhaseMaxLabel.FontColor = fontColor;
            app.PhaseDim6Label.FontColor = fontColor;
            app.PhaseDim5Label.FontColor = fontColor;
            app.PhaseDim4Label.FontColor = fontColor;
            app.PhaseDim3Label.FontColor = fontColor;

            app.FlowXLabel.FontColor = fontColor;
            app.FlowYLabel.FontColor = fontColor;
            app.FlowDirectionSpinnerLabel.FontColor = fontColor;
            app.FlowOrientationSpinnerLabel.FontColor = fontColor;
            app.FlowMinLabel.FontColor = fontColor;
            app.FlowMaxLabel.FontColor = fontColor;
            app.FlowDim6Label.FontColor = fontColor;
            app.FlowDim5Label.FontColor = fontColor;
            app.FlowDim4Label.FontColor = fontColor;
            app.FlowDim3Label.FontColor = fontColor;
            
            app.ClassicButton.FontColor = fontColor;
            app.BlackWhiteButton.FontColor = fontColor;
            app.AutoSoundsCheckBoxLabel.FontColor = fontColor;

            % Gauges
            app.RecoProgressGauge.FontColor = gaugeColor;
            app.RecoProgressGauge.BackgroundColor = gaugeBackgroundColor;
            app.ExportProgressGauge.FontColor = gaugeColor;
            app.ExportProgressGauge.BackgroundColor = gaugeBackgroundColor;

            % Images
            app.KspaceFig.Color = movieFigureColor;
            app.KspaceFig.XColor = movieFigureColor;
            app.KspaceFig.YColor = movieFigureColor;

            app.RecoFig.Color = movieFigureColor;
            app.RecoFig.XColor = movieFigureColor;
            app.RecoFig.YColor = movieFigureColor;

            app.MIPFig.Color = movieFigureColor;
            app.MIPFig.XColor = movieFigureColor;
            app.MIPFig.YColor = movieFigureColor;

            app.PhaseRecoFig.Color = movieFigureColor;
            app.PhaseRecoFig.XColor = movieFigureColor;
            app.PhaseRecoFig.YColor = movieFigureColor;
            
            app.FlowRecoFig.Color = movieFigureColor;
            app.FlowRecoFig.XColor = movieFigureColor;
            app.FlowRecoFig.YColor = movieFigureColor;

            app.SenseFig1.Color = movieFigureColor;
            app.SenseFig1.XColor = movieFigureColor;
            app.SenseFig1.YColor = movieFigureColor;

            app.SenseFig2.Color = movieFigureColor;
            app.SenseFig2.XColor = movieFigureColor;
            app.SenseFig2.YColor = movieFigureColor;

            app.SenseFig3.Color = movieFigureColor;
            app.SenseFig3.XColor = movieFigureColor;
            app.SenseFig3.YColor = movieFigureColor;

            app.SenseFig4.Color = movieFigureColor;
            app.SenseFig4.XColor = movieFigureColor;
            app.SenseFig4.YColor = movieFigureColor;

            app.SenseFig5.Color = movieFigureColor;
            app.SenseFig5.XColor = movieFigureColor;
            app.SenseFig5.YColor = movieFigureColor;

            app.SenseFig6.Color = movieFigureColor;
            app.SenseFig6.XColor = movieFigureColor;
            app.SenseFig6.YColor = movieFigureColor;

            app.SenseFig7.Color = movieFigureColor;
            app.SenseFig7.XColor = movieFigureColor;
            app.SenseFig7.YColor = movieFigureColor;

            app.SenseFig8.Color = movieFigureColor;
            app.SenseFig8.XColor = movieFigureColor;
            app.SenseFig8.YColor = movieFigureColor;

        end % SetAppColorsFcn


        % Switch of all buttons
        function SwitchOffAllButtonsFcn(app)

            app.SetButtonsFcn({'Off','Off','Off','Off','Off','Off','Off'});
     
        end


        % Diable all labels
        function DisableLabelsFcn(app)

            app.DisableKbuttonsFcn;
            app.DisableImageButtonsFcn;
            app.DisableCoilButtonsFcn;
            app.DisableRecoButtonsFcn;

        end

        % Enable all labels
        function EnableLabelsFcn(app)

            app.EnableKbuttonsFcn;
            app.EnableImageButtonsFcn;
            app.EnableCoilButtonsFcn;
            app.EnableRecoButtonsFcn;

        end


        % Enable/disable reco parameters
        function SetRecoFieldsFcn(app)

            if app.CSRecoCheckBox.Value == 1
                app.WVxyzEditField.Enable = 'on';
                app.TVxyzEditField.Enable = 'on';
                app.LLRxyzEditField.Enable = 'on';
                app.TVtimeEditField.Enable = 'on';
                app.WVxyzEditFieldLabel.Enable = 'on';
                app.TVxyzEditFieldLabel.Enable = 'on';
                app.LLRxyzEditFieldLabel.Enable = 'on';
                app.TVtimeEditFieldLabel.Enable = 'on';
            else
                app.WVxyzEditField.Enable = 'off';
                app.TVxyzEditField.Enable = 'off';
                app.LLRxyzEditField.Enable = 'off';
                app.TVtimeEditField.Enable = 'off';
                app.WVxyzEditFieldLabel.Enable = 'off';
                app.TVxyzEditFieldLabel.Enable = 'off';
                app.LLRxyzEditFieldLabel.Enable = 'off';
                app.TVtimeEditFieldLabel.Enable = 'off';
            end

            if app.bartDetectedFlag && app.CSRecoCheckBox.Value && contains(app.pd.dataType,["ZTE","2Dradial","3Dute"])
                app.ZTEIterationsLabel.Enable = 'on';
                app.ZTEIterationsEditField.Enable = 'on';
            end

            if ~app.bartDetectedFlag
                app.TVxyzEditField.Enable = 'off';
                app.LLRxyzEditField.Enable = 'off';
                app.TVxyzEditFieldLabel.Enable = 'off';
                app.LLRxyzEditFieldLabel.Enable = 'off';
                app.ZTEIterationsLabel.Enable = 'off';
                app.ZTEIterationsEditField.Enable = 'off';
            end

            if app.aiDetectedFlag
                app.DeeplearningLabel.Enable = 'on';
                app.DLdenoiseCheckBox.Enable = 'on';
                app.DLModelDropDown.Enable = 'on';
                app.DLModelDropDownLabel.Enable = 'on';
            else
                app.DeeplearningLabel.Enable = 'off';
                app.DLdenoiseCheckBox.Enable = 'off';
                app.DLModelDropDown.Enable = 'off';
                app.DLModelDropDownLabel.Enable = 'off';
            end

            app.EnableRadialButtonsFcn;

            drawnow;

        end


        % Disable reco buttons
        function DisableRecoButtonsFcn(app)

            app.XEditField.Enable = 'off';
            app.YEditField.Enable = 'off';
            app.ZEditField.Enable = 'off';
            app.NREditField.Enable = 'off';
            app.WVxyzEditField.Enable= 'off';
            app.TVxyzEditField.Enable = 'off';
            app.LLRxyzEditField.Enable = 'off';
            app.TVtimeEditField.Enable = 'off';
            app.CSRecoCheckBox.Enable = 'off';
            app.PCAdeNoiseCheckBox.Enable = 'off';
            app.AFGPdeNoiseCheckBox.Enable = 'off';
            app.CDTVdeNoiseCheckBox.Enable = 'off';
            app.NLMFdeNoiseCheckBox.Enable = 'off';
            app.PCAdeNoiseWindowEditField.Enable = 'off';
            app.AFGPdeNoiseIterationsEditField.Enable = 'off';
            app.CDTVdeNoiseLambdaEditField.Enable = 'off';
            app.NLMFdeNoiseFilterStrengthEditField.Enable = 'off';
            app.DeRingCheckBox.Enable = 'off';
            app.AbortRecoButton.Enable = 'off';

            app.XEditFieldLabel.Enable = 'off';
            app.YEditFieldLabel.Enable = 'off';
            app.ZEditFieldLabel.Enable = 'off';
            app.NREditFieldLabel.Enable = 'off';
            app.WVxyzEditFieldLabel.Enable = 'off';
            app.TVxyzEditFieldLabel.Enable = 'off';
            app.LLRxyzEditFieldLabel.Enable = 'off';
            app.TVtimeEditFieldLabel.Enable = 'off';
            app.CSRecoLabel.Enable = 'off';
            app.PCAdeNoiseLabel.Enable = 'off';
            app.PCAdeNoiseWindowLabel.Enable = 'off';
            app.AFGPdeNoiseLabel.Enable = 'off';
            app.AFGPdeNoiseIterationsLabel.Enable = 'off';
            app.CDTVdeNoiseLabel.Enable = 'off';
            app.CDTVdeNoiseLamdaLabel.Enable = 'off';
            app.NLMFdeNoiseLabel.Enable = 'off';
            app.NLMFdeNoiseFilterStrengthLabel.Enable = 'off';
            app.DeRingLabel.Enable = 'off';

            app.DeeplearningLabel.Enable = 'off';
            app.DLdenoiseCheckBox.Enable = 'off';
            app.DLModelDropDown.Enable = 'off';
            app.DLModelDropDownLabel.Enable = 'off';

            app.ZTEFullEchoLabel.Enable = 'off';
            app.ZTEFullEchoCheckBox.Enable = 'off';
            app.ZTEShiftLabel.Enable = 'off';
            app.ZTEShiftEditField.Enable = 'off';
            app.ZTEDeadTimeLabel.Enable = 'off';
            app.ZTEDeadTimeEditField.Enable = 'off';
            app.ZTEIterationsLabel.Enable = 'off';
            app.ZTEIterationsEditField.Enable = 'off';

            app.SumEchosCheckBox.Enable = 'off';
            app.SumEchosLabel.Enable = 'off'; 
            if app.NEViewField.Value == 1
                app.SumEchosCheckBox.Value = 0;
            end

            app.DisableRadialButtonsFcn;

            drawnow;

        end

        % Disable reco buttons
        function EnableRecoButtonsFcn(app)

            app.XEditField.Enable = 'on';
            app.YEditField.Enable = 'on';
            app.ZEditField.Enable = 'on';
            app.NREditField.Enable = 'on';
            app.WVxyzEditField.Enable= 'on';
            app.TVxyzEditField.Enable = 'on';
            app.LLRxyzEditField.Enable = 'on';
            app.TVtimeEditField.Enable = 'on';
            app.CSRecoCheckBox.Enable = 'on';
            app.PCAdeNoiseCheckBox.Enable = 'on';
            app.AFGPdeNoiseCheckBox.Enable = 'on';
            app.CDTVdeNoiseCheckBox.Enable = 'on';
            app.NLMFdeNoiseCheckBox.Enable = 'on';
            app.PCAdeNoiseWindowEditField.Enable = 'on';
            app.AFGPdeNoiseIterationsEditField.Enable = 'on';
            app.CDTVdeNoiseLambdaEditField.Enable = 'on';
            app.NLMFdeNoiseFilterStrengthEditField.Enable = 'on';
            app.DeRingCheckBox.Enable = 'on';
            app.AbortRecoButton.Enable = 'off';

            app.XEditFieldLabel.Enable = 'on';
            app.YEditFieldLabel.Enable = 'on';
            app.ZEditFieldLabel.Enable = 'on';
            app.NREditFieldLabel.Enable = 'on';
            app.WVxyzEditFieldLabel.Enable = 'on';
            app.TVxyzEditFieldLabel.Enable = 'on';
            app.LLRxyzEditFieldLabel.Enable = 'on';
            app.TVtimeEditFieldLabel.Enable = 'on';
            app.CSRecoLabel.Enable = 'on';
            app.PCAdeNoiseLabel.Enable = 'on';
            app.PCAdeNoiseWindowLabel.Enable = 'on';
            app.AFGPdeNoiseLabel.Enable = 'on';
            app.AFGPdeNoiseIterationsLabel.Enable = 'on';
            app.CDTVdeNoiseLabel.Enable = 'on';
            app.CDTVdeNoiseLamdaLabel.Enable = 'on';
            app.NLMFdeNoiseLabel.Enable = 'on';
            app.NLMFdeNoiseFilterStrengthLabel.Enable = 'on';
            app.DeRingLabel.Enable = 'on';

            if app.aiDetectedFlag
                app.DeeplearningLabel.Enable = 'on';
                app.DLdenoiseCheckBox.Enable = 'on';
                app.DLModelDropDown.Enable = 'on';
                app.DLModelDropDownLabel.Enable = 'on';
            else
                app.DeeplearningLabel.Enable = 'off';
                app.DLdenoiseCheckBox.Enable = 'off';
                app.DLModelDropDown.Enable = 'off';
                app.DLModelDropDownLabel.Enable = 'off';
            end

            if app.pd.dataType == "ZTE"
                app.ZTEFullEchoLabel.Enable = 'on';
                app.ZTEFullEchoCheckBox.Enable = 'on';
                app.ZTEShiftLabel.Enable = 'on';
                app.ZTEShiftEditField.Enable = 'on';
                app.ZTEDeadTimeLabel.Enable = 'on';
                app.ZTEDeadTimeEditField.Enable = 'on';
            else
                app.ZTEFullEchoLabel.Enable = 'off';
                app.ZTEFullEchoCheckBox.Enable = 'off';
                app.ZTEShiftLabel.Enable = 'off';
                app.ZTEShiftEditField.Enable = 'off';
                app.ZTEDeadTimeLabel.Enable = 'off';
                app.ZTEDeadTimeEditField.Enable = 'off';
            end

            if contains(app.pd.dataType,["ZTE","2Dradial","3Dute"])
                app.ZTEIterationsLabel.Enable = 'off';
                app.ZTEIterationsEditField.Enable = 'off';
                if app.bartDetectedFlag && app.CSRecoCheckBox.Value
                    app.ZTEIterationsLabel.Enable = 'on';
                    app.ZTEIterationsEditField.Enable = 'on';
                end
            else
                app.ZTEIterationsLabel.Enable = 'off';
                app.ZTEIterationsEditField.Enable = 'off';
            end
            
            if app.NEViewField.Value > 1
                app.SumEchosLabel.Enable = 'on';    
                app.SumEchosCheckBox.Enable = 'on';
            end            

            app.SetRecoFieldsFcn;
            drawnow;

        end


        % Disable k-buttons
        function DisableKbuttonsFcn(app)

            app.kSpaceButtonStatus = {app.KDim3EditField.Enable,app.KDim3Slider.Enable,app.KDim4EditField.Enable,app.KDim4Slider.Enable, ...
                app.KFlipAngleEditField.Enable,app.KFlipAngleSlider.Enable,app.KEchoEditField.Enable,app.KEchoSlider.Enable, ...
                app.KOrientationSpinner.Enable,app.fillingButton.Enable,app.averagesButton.Enable,app.kValuesButton.Enable, ...
                app.senseMapButton.Enable,app.CoilSpinner.Enable,app.KspaceMovieLabel.Enable,app.KStartButton.Enable,app.KStopButton.Enable, ...
                app.FastSlowSwitch.Enable,app.KLevelEditField.Enable,app.KWindowEditField.Enable,app.KLevelSlider.Enable,app.KWindowSlider.Enable,app.CoilSpinnerLabel.Enable, ...
                app.FillingViewField.Enable,app.FillingPercLabel.Enable,app.KOrientationSpinnerLabel.Enable,app.KEchoEditFieldLabel.Enable,app.KFlipAngleEditFieldLabel.Enable, ...
                app.KDim4Label.Enable,app.KDim3Label.Enable,app.KLLabel.Enable,app.KWLabel.Enable,app.KxLabel.Enable,app.KyLabel.Enable,app.KDim3EditFieldS.Enable,app.KDim3SliderS.Enable,app.KDim3LabelS.Enable};

            app.KDim3EditField.Enable = 'off';
            app.KDim3EditFieldS.Enable = 'off';
            app.KDim3Slider.Enable = 'off';
            app.KDim3SliderS.Enable = 'off';
            app.KDim4EditField.Enable = 'off';
            app.KDim4Slider.Enable = 'off';
            app.KFlipAngleEditField.Enable = 'off';
            app.KFlipAngleSlider.Enable = 'off';
            app.KEchoEditField.Enable = 'off';
            app.KEchoSlider.Enable = 'off';
            app.KOrientationSpinner.Enable = 'off';
            app.fillingButton.Enable = 'off';
            app.averagesButton.Enable = 'off';
            app.kValuesButton.Enable = 'off';
            app.senseMapButton.Enable = 'off';
            app.CoilSpinner.Enable = 'off';
            app.KspaceMovieLabel.Enable = 'off';
            app.KStartButton.Enable = 'off';
            app.KStopButton.Enable = 'off';
            app.FastSlowSwitch.Enable = 'off';
            app.KLevelEditField.Enable = 'off';
            app.KWindowEditField.Enable = 'off';
            app.KLevelSlider.Enable = 'off';
            app.KWindowSlider.Enable = 'off';
            app.CoilSpinnerLabel.Enable = 'off';
            app.FillingViewField.Enable = 'off';
            app.FillingPercLabel.Enable = 'off';
            app.KOrientationSpinnerLabel.Enable = 'off';
            app.KEchoEditFieldLabel.Enable = 'off';
            app.KFlipAngleEditFieldLabel.Enable = 'off';
            app.KDim4Label.Enable = 'off';
            app.KDim3Label.Enable = 'off';
            app.KDim3LabelS.Enable = 'off';
            app.KLLabel.Enable = 'off';
            app.KWLabel.Enable = 'off';
            app.KxLabel.Enable = 'off';
            app.KyLabel.Enable = 'off';

            drawnow;

        end


        % Enable k-buttons
        function EnableKbuttonsFcn(app)

            app.KDim3EditField.Enable = app.kSpaceButtonStatus{1};
            app.KDim3Slider.Enable = app.kSpaceButtonStatus{2};
            app.KDim4EditField.Enable = app.kSpaceButtonStatus{3};
            app.KDim4Slider.Enable = app.kSpaceButtonStatus{4};
            app.KFlipAngleEditField.Enable = app.kSpaceButtonStatus{5};
            app.KFlipAngleSlider.Enable = app.kSpaceButtonStatus{6};
            app.KEchoEditField.Enable = app.kSpaceButtonStatus{7};
            app.KEchoSlider.Enable = app.kSpaceButtonStatus{8};
            app.KOrientationSpinner.Enable = app.kSpaceButtonStatus{9};
            app.fillingButton.Enable = app.kSpaceButtonStatus{10};
            app.averagesButton.Enable = app.kSpaceButtonStatus{11};
            app.kValuesButton.Enable = app.kSpaceButtonStatus{12};
            app.senseMapButton.Enable = app.kSpaceButtonStatus{13};
            app.CoilSpinner.Enable = app.kSpaceButtonStatus{14};
            app.KspaceMovieLabel.Enable = app.kSpaceButtonStatus{15};
            app.KStartButton.Enable = app.kSpaceButtonStatus{16};
            app.KStopButton.Enable = app.kSpaceButtonStatus{17};
            app.FastSlowSwitch.Enable = app.kSpaceButtonStatus{18};
            app.KLevelEditField.Enable = app.kSpaceButtonStatus{19};
            app.KWindowEditField.Enable = app.kSpaceButtonStatus{20};
            app.KLevelSlider.Enable = app.kSpaceButtonStatus{21};
            app.KWindowSlider.Enable = app.kSpaceButtonStatus{22};
            app.CoilSpinnerLabel.Enable = app.kSpaceButtonStatus{23};
            app.FillingViewField.Enable = app.kSpaceButtonStatus{24};
            app.FillingPercLabel.Enable = app.kSpaceButtonStatus{25};
            app.KOrientationSpinnerLabel.Enable = app.kSpaceButtonStatus{26};
            app.KEchoEditFieldLabel.Enable = app.kSpaceButtonStatus{27};
            app.KFlipAngleEditFieldLabel.Enable = app.kSpaceButtonStatus{28};
            app.KDim4Label.Enable = app.kSpaceButtonStatus{29};
            app.KDim3Label.Enable = app.kSpaceButtonStatus{30};
            app.KLLabel.Enable = app.kSpaceButtonStatus{31};
            app.KWLabel.Enable = app.kSpaceButtonStatus{32};
            app.KxLabel.Enable = app.kSpaceButtonStatus{33};
            app.KyLabel.Enable = app.kSpaceButtonStatus{34};
            app.KDim3EditFieldS.Enable = app.kSpaceButtonStatus{35};
            app.KDim3SliderS.Enable = app.kSpaceButtonStatus{36};
            app.KDim3LabelS.Enable = app.kSpaceButtonStatus{37};

            drawnow;

        end


        % Disable image buttons
        function DisableImageButtonsFcn(app)

            app.imageButtonStatus = {app.MaskButton.Enable,app.ThresholdSlider.Enable,app.ThresholdEditField.Enable,app.ErodeButton.Enable, ...
                app.DilateButton.Enable,app.CloseButton.Enable,app.OpenButton.Enable,app.AutoIntensityCheckBox.Enable,app.SetButton.Enable, ...
                app.WindowSlider.Enable,app.LevelSlider.Enable,app.OrientationSpinner.Enable,app.Dim6Slider.Enable,app.Dim5Slider.Enable, ...
                app.Dim4Slider.Enable,app.Dim3Slider.Enable,app.Dim6EditField.Enable,app.Dim5EditField.Enable,app.Dim4EditField.Enable, ...
                app.Dim3EditField.Enable,app.XLabel.Enable,app.YLabel.Enable,app.MoviePlayButton.Enable,app.MovieStopButton.Enable, ...
                app.ThresholdEditFieldLabel.Enable,app.WindowEditField.Enable,app.LevelEditField.Enable,app.OrientationSpinnerLabel.Enable, ...
                app.Dim6Label.Enable,app.Dim5Label.Enable,app.Dim4Label.Enable,app.Dim3Label.Enable,app.AutoIntensityCheckBoxLabel.Enable, ...
                app.LLabel.Enable,app.WLabel.Enable,app.MIPXLabel.Enable,app.MIPYLabel.Enable,app.MIPWindowEditField.Enable,app.MIPLevelEditField.Enable, ...
                app.MIPSetButton.Enable,app.MIPWindowSlider.Enable,app.MIPLevelSlider.Enable,app.MIPWLabel.Enable,app.MIPLLabel.Enable,app.MIPOrientationSpinner.Enable, ...
                app.MIPOrientationSpinnerLabel.Enable,app.MIPResizeButton.Enable,app.MIPAngleResetButton.Enable,app.MIPxAngleSlider.Enable,app.MIPyAngleSlider.Enable, ...
                app.MIPxAngleLabel.Enable,app.MIPyAngleLabel.Enable,app.MIPxPlayButton.Enable,app.MIPyPlayButton.Enable,app.MIPRotateLabel.Enable, ...
                app.PhaseYLabel.Enable,app.PhaseXLabel.Enable,app.PhaseUnwrapButton.Enable,app.PhaseOrientationSpinner.Enable,app.PhaseCorrectCheckBoxLabel.Enable, ...
                app.PhaseMinLabel.Enable, app.PhaseMaxLabel.Enable,app.PhaseMovieStartButton.Enable,app.PhaseMovieStopButton.Enable,app.PhaseDim3Label.Enable, ...
                app.PhaseDim3EditField.Enable,app.PhaseDim3Slider.Enable,app.PhaseDim4Label.Enable,app.PhaseDim4EditField.Enable,app.PhaseDim4Slider.Enable, ...
                app.PhaseDim5Label.Enable,app.PhaseDim5EditField.Enable,app.PhaseDim5Slider.Enable,app.PhaseDim6Label.Enable,app.PhaseDim6EditField.Enable, ...
                app.PhaseDim6Slider.Enable,app.FlowXLabel.Enable,app.FlowYLabel.Enable,app.FlowCalcButton.Enable,app.FlowLoadVencTableButton.Enable, ...
                app.FlowDirectionSpinnerLabel.Enable,app.FlowDirectionSpinner.Enable,app.FlowOrientationSpinner.Enable,app.FlowOrientationSpinnerLabel.Enable, ...
                app.FlowMinLabel.Enable,app.FlowMaxLabel.Enable,app.FlowMovieStartButton.Enable,app.FlowMovieStopButton.Enable,app.FlowDim3Label.Enable, ...
                app.FlowDim3EditField.Enable,app.FlowDim3Slider.Enable,app.FlowDim4Label.Enable,app.FlowDim4EditField.Enable,app.FlowDim4Slider.Enable,app.FlowDim5Label.Enable, ...
                app.FlowDim5EditField.Enable,app.FlowDim5Slider.Enable,app.FlowDim6Label.Enable,app.FlowDim6EditField.Enable,app.FlowDim6Slider.Enable, ...
                app.PhaseOrientationSpinnerLabel.Enable};

            app.MaskButton.Enable = 'off';
            app.ThresholdSlider.Enable = 'off';
            app.ThresholdEditField.Enable = 'off';
            app.ErodeButton.Enable = 'off';
            app.DilateButton.Enable = 'off';
            app.CloseButton.Enable = 'off';
            app.OpenButton.Enable = 'off';
            app.AutoIntensityCheckBox.Enable = 'off';
            app.SetButton.Enable = 'off';
            app.WindowSlider.Enable = 'off';
            app.LevelSlider.Enable = 'off';
            app.OrientationSpinner.Enable = 'off';
            app.Dim6Slider.Enable = 'off';
            app.Dim5Slider.Enable = 'off';
            app.Dim4Slider.Enable = 'off';
            app.Dim3Slider.Enable = 'off';
            app.Dim6EditField.Enable = 'off';
            app.Dim5EditField.Enable = 'off';
            app.Dim4EditField.Enable = 'off';
            app.Dim3EditField.Enable = 'off';
            app.XLabel.Enable = 'off';
            app.YLabel.Enable = 'off';
            app.MoviePlayButton.Enable = 'off';
            app.MovieStopButton.Enable = 'off';
            app.ThresholdEditFieldLabel.Enable = 'off';
            app.WindowEditField.Enable = 'off';
            app.LevelEditField.Enable = 'off';
            app.OrientationSpinnerLabel.Enable = 'off';
            app.Dim6Label.Enable = 'off';
            app.Dim5Label.Enable = 'off';
            app.Dim4Label.Enable = 'off';
            app.Dim3Label.Enable = 'off';
            app.AutoIntensityCheckBoxLabel.Enable = 'off';
            app.LLabel.Enable = 'off';
            app.WLabel.Enable = 'off';

            app.MIPXLabel.Enable = 'off';
            app.MIPYLabel.Enable = 'off';
            app.MIPWindowEditField.Enable = 'off';
            app.MIPLevelEditField.Enable = 'off';
            app.MIPSetButton.Enable = 'off';
            app.MIPWindowSlider.Enable = 'off';
            app.MIPLevelSlider.Enable = 'off';
            app.MIPWLabel.Enable = 'off';
            app.MIPLLabel.Enable = 'off';
            app.MIPOrientationSpinner.Enable = 'off';
            app.MIPOrientationSpinnerLabel.Enable = 'off';
            app.MIPResizeButton.Enable = 'off';
            app.MIPAngleResetButton.Enable = 'off';
            app.MIPxAngleSlider.Enable = 'off';
            app.MIPyAngleSlider.Enable = 'off';
            app.MIPxAngleLabel.Enable = 'off';
            app.MIPyAngleLabel.Enable = 'off';
            app.MIPxPlayButton.Enable = 'off';
            app.MIPyPlayButton.Enable = 'off';
            app.MIPRotateLabel.Enable = 'off';
           
            app.PhaseYLabel.Enable = 'off';
            app.PhaseXLabel.Enable = 'off';
            app.PhaseUnwrapButton.Enable = 'off';
            app.PhaseOrientationSpinner.Enable = 'off';
            app.PhaseCorrectCheckBoxLabel.Enable = 'off';
            app.PhaseMinLabel.Enable = 'off';
            app.PhaseMaxLabel.Enable = 'off';
            app.PhaseMovieStartButton.Enable = 'off';
            app.PhaseMovieStopButton.Enable = 'off';
            app.PhaseDim3Label.Enable = 'off';
            app.PhaseDim3EditField.Enable = 'off';
            app.PhaseDim3Slider.Enable = 'off';
            app.PhaseDim4Label.Enable = 'off';
            app.PhaseDim4EditField.Enable = 'off';
            app.PhaseDim4Slider.Enable = 'off';
            app.PhaseDim5Label.Enable = 'off';
            app.PhaseDim5EditField.Enable = 'off';
            app.PhaseDim5Slider.Enable = 'off';
            app.PhaseDim6Label.Enable = 'off';
            app.PhaseDim6EditField.Enable = 'off';
            app.PhaseDim6Slider.Enable = 'off';

            app.FlowXLabel.Enable = 'off';
            app.FlowYLabel.Enable = 'off';
            app.FlowCalcButton.Enable = 'off';
            app.FlowLoadVencTableButton.Enable = 'off';
            app.FlowDirectionSpinnerLabel.Enable = 'off';
            app.FlowDirectionSpinner.Enable = 'off';
            app.FlowOrientationSpinner.Enable = 'off';
            app.FlowOrientationSpinnerLabel.Enable = 'off';
            app.FlowMinLabel.Enable = 'off';
            app.FlowMaxLabel.Enable = 'off';
            app.FlowMovieStartButton.Enable = 'off';
            app.FlowMovieStopButton.Enable = 'off';
            app.FlowDim3Label.Enable = 'off';
            app.FlowDim3EditField.Enable = 'off';
            app.FlowDim3Slider.Enable = 'off';
            app.FlowDim4Label.Enable = 'off';
            app.FlowDim4EditField.Enable = 'off';
            app.FlowDim4Slider.Enable = 'off';
            app.FlowDim5Label.Enable = 'off';
            app.FlowDim5EditField.Enable = 'off';
            app.FlowDim5Slider.Enable = 'off';
            app.FlowDim6Label.Enable = 'off';
            app.FlowDim6EditField.Enable = 'off';
            app.FlowDim6Slider.Enable = 'off';

            app.PhaseOrientationSpinnerLabel.Enable = 'off';

            drawnow;
            
        end


        % Enable image buttons
        function EnableImageButtonsFcn(app)

            app.MaskButton.Enable = app.imageButtonStatus{1};
            app.ThresholdSlider.Enable = app.imageButtonStatus{2};
            app.ThresholdEditField.Enable = app.imageButtonStatus{3};
            app.ErodeButton.Enable = app.imageButtonStatus{4};
            app.DilateButton.Enable = app.imageButtonStatus{5};
            app.CloseButton.Enable = app.imageButtonStatus{6};
            app.OpenButton.Enable = app.imageButtonStatus{7};
            app.AutoIntensityCheckBox.Enable = app.imageButtonStatus{8};
            app.SetButton.Enable = app.imageButtonStatus{9};
            app.WindowSlider.Enable = app.imageButtonStatus{10};
            app.LevelSlider.Enable = app.imageButtonStatus{11};
            app.OrientationSpinner.Enable = app.imageButtonStatus{12};
            app.Dim6Slider.Enable = app.imageButtonStatus{13};
            app.Dim5Slider.Enable = app.imageButtonStatus{14};
            app.Dim4Slider.Enable = app.imageButtonStatus{15};
            app.Dim3Slider.Enable = app.imageButtonStatus{16};
            app.Dim6EditField.Enable = app.imageButtonStatus{17};
            app.Dim5EditField.Enable = app.imageButtonStatus{18};
            app.Dim4EditField.Enable = app.imageButtonStatus{19};
            app.Dim3EditField.Enable = app.imageButtonStatus{20};
            app.XLabel.Enable = app.imageButtonStatus{21};
            app.YLabel.Enable = app.imageButtonStatus{22};
            app.MoviePlayButton.Enable = app.imageButtonStatus{23};
            app.MovieStopButton.Enable = app.imageButtonStatus{24};
            app.ThresholdEditFieldLabel.Enable = app.imageButtonStatus{25};
            app.WindowEditField.Enable = app.imageButtonStatus{26};
            app.LevelEditField.Enable = app.imageButtonStatus{27};
            app.OrientationSpinnerLabel.Enable = app.imageButtonStatus{28};
            app.Dim6Label.Enable = app.imageButtonStatus{29};
            app.Dim5Label.Enable = app.imageButtonStatus{30};
            app.Dim4Label.Enable = app.imageButtonStatus{31};
            app.Dim3Label.Enable = app.imageButtonStatus{32};
            app.AutoIntensityCheckBoxLabel.Enable = app.imageButtonStatus{33};
            app.LLabel.Enable = app.imageButtonStatus{34};
            app.WLabel.Enable = app.imageButtonStatus{35};

            app.MIPXLabel.Enable = app.imageButtonStatus{36};
            app.MIPYLabel.Enable = app.imageButtonStatus{37};
            app.MIPWindowEditField.Enable = app.imageButtonStatus{38};
            app.MIPLevelEditField.Enable = app.imageButtonStatus{39};
            app.MIPSetButton.Enable = app.imageButtonStatus{40};
            app.MIPWindowSlider.Enable = app.imageButtonStatus{41};
            app.MIPLevelSlider.Enable = app.imageButtonStatus{42};
            app.MIPWLabel.Enable = app.imageButtonStatus{43};
            app.MIPLLabel.Enable = app.imageButtonStatus{44};
            app.MIPOrientationSpinner.Enable = app.imageButtonStatus{45};
            app.MIPOrientationSpinnerLabel.Enable = app.imageButtonStatus{46};
            app.MIPResizeButton.Enable = app.imageButtonStatus{47};
            app.MIPAngleResetButton.Enable = app.imageButtonStatus{48};
            app.MIPxAngleSlider.Enable = app.imageButtonStatus{49};
            app.MIPyAngleSlider.Enable = app.imageButtonStatus{50};
            app.MIPxAngleLabel.Enable = app.imageButtonStatus{51};
            app.MIPyAngleLabel.Enable = app.imageButtonStatus{52};
            app.MIPxPlayButton.Enable = app.imageButtonStatus{53};
            app.MIPyPlayButton.Enable = app.imageButtonStatus{54};
            app.MIPRotateLabel.Enable = app.imageButtonStatus{55};
           
            app.PhaseYLabel.Enable = app.imageButtonStatus{56};
            app.PhaseXLabel.Enable = app.imageButtonStatus{57};
            app.PhaseUnwrapButton.Enable = app.imageButtonStatus{58};
            app.PhaseOrientationSpinner.Enable = app.imageButtonStatus{59};
            app.PhaseCorrectCheckBoxLabel.Enable = app.imageButtonStatus{60};
            app.PhaseMinLabel.Enable = app.imageButtonStatus{61};
            app.PhaseMaxLabel.Enable = app.imageButtonStatus{62};
            app.PhaseMovieStartButton.Enable = app.imageButtonStatus{63};
            app.PhaseMovieStopButton.Enable = app.imageButtonStatus{64};
            app.PhaseDim3Label.Enable = app.imageButtonStatus{65};
            app.PhaseDim3EditField.Enable = app.imageButtonStatus{66};
            app.PhaseDim3Slider.Enable = app.imageButtonStatus{67};
            app.PhaseDim4Label.Enable = app.imageButtonStatus{68};
            app.PhaseDim4EditField.Enable = app.imageButtonStatus{69};
            app.PhaseDim4Slider.Enable = app.imageButtonStatus{70};
            app.PhaseDim5Label.Enable = app.imageButtonStatus{71};
            app.PhaseDim5EditField.Enable = app.imageButtonStatus{72};
            app.PhaseDim5Slider.Enable = app.imageButtonStatus{73};
            app.PhaseDim6Label.Enable = app.imageButtonStatus{74};
            app.PhaseDim6EditField.Enable = app.imageButtonStatus{75};
            app.PhaseDim6Slider.Enable = app.imageButtonStatus{76};

            app.FlowXLabel.Enable = app.imageButtonStatus{77};
            app.FlowYLabel.Enable = app.imageButtonStatus{78};
            app.FlowCalcButton.Enable = app.imageButtonStatus{79};
            app.FlowLoadVencTableButton.Enable = app.imageButtonStatus{80};
            app.FlowDirectionSpinnerLabel.Enable = app.imageButtonStatus{81};
            app.FlowDirectionSpinner.Enable = app.imageButtonStatus{82};
            app.FlowOrientationSpinner.Enable = app.imageButtonStatus{83};
            app.FlowOrientationSpinnerLabel.Enable = app.imageButtonStatus{84};
            app.FlowMinLabel.Enable = app.imageButtonStatus{85};
            app.FlowMaxLabel.Enable = app.imageButtonStatus{86};
            app.FlowMovieStartButton.Enable = app.imageButtonStatus{87};
            app.FlowMovieStopButton.Enable = app.imageButtonStatus{88};
            app.FlowDim3Label.Enable = app.imageButtonStatus{89};
            app.FlowDim3EditField.Enable = app.imageButtonStatus{90};
            app.FlowDim3Slider.Enable = app.imageButtonStatus{91};
            app.FlowDim4Label.Enable = app.imageButtonStatus{92};
            app.FlowDim4EditField.Enable = app.imageButtonStatus{93};
            app.FlowDim4Slider.Enable = app.imageButtonStatus{94};
            app.FlowDim5Label.Enable = app.imageButtonStatus{95};
            app.FlowDim5EditField.Enable = app.imageButtonStatus{96};
            app.FlowDim5Slider.Enable = app.imageButtonStatus{97};
            app.FlowDim6Label.Enable = app.imageButtonStatus{98};
            app.FlowDim6EditField.Enable = app.imageButtonStatus{99};
            app.FlowDim6Slider.Enable = app.imageButtonStatus{100};

            app.PhaseOrientationSpinnerLabel.Enable = app.imageButtonStatus{101};

            drawnow;

        end


        % Reset coil buttons
        function ResetCoilButtonsFcn(app)

            app.C1CheckBox.Value = 1;
            app.C1CheckBox.Enable = 'on';
            app.Label1.Enable = 'on'; 
            if app.pd.nrCoils > 1 app.C2CheckBox.Enable = 'on'; app.C2CheckBox.Value = 1; app.Label2.Enable = 'on'; else app.C2CheckBox.Enable = 'off'; app.C2CheckBox.Value = 0; app.Label2.Enable = 'off'; end
            if app.pd.nrCoils > 2 app.C3CheckBox.Enable = 'on'; app.C3CheckBox.Value = 1; app.Label3.Enable = 'on'; else app.C3CheckBox.Enable = 'off'; app.C3CheckBox.Value = 0; app.Label3.Enable = 'off'; end
            if app.pd.nrCoils > 3 app.C4CheckBox.Enable = 'on'; app.C4CheckBox.Value = 1; app.Label4.Enable = 'on'; else app.C4CheckBox.Enable = 'off'; app.C4CheckBox.Value = 0; app.Label4.Enable = 'off'; end
            if app.pd.nrCoils > 4 app.C5CheckBox.Enable = 'on'; app.C5CheckBox.Value = 1; app.Label5.Enable = 'on'; else app.C5CheckBox.Enable = 'off'; app.C5CheckBox.Value = 0; app.Label5.Enable = 'off'; end
            if app.pd.nrCoils > 5 app.C6CheckBox.Enable = 'on'; app.C6CheckBox.Value = 1; app.Label6.Enable = 'on'; else app.C6CheckBox.Enable = 'off'; app.C6CheckBox.Value = 0; app.Label6.Enable = 'off'; end
            if app.pd.nrCoils > 6 app.C7CheckBox.Enable = 'on'; app.C7CheckBox.Value = 1; app.Label7.Enable = 'on'; else app.C7CheckBox.Enable = 'off'; app.C7CheckBox.Value = 0; app.Label7.Enable = 'off'; end
            if app.pd.nrCoils > 7 app.C8CheckBox.Enable = 'on'; app.C8CheckBox.Value = 1; app.Label8.Enable = 'on'; else app.C8CheckBox.Enable = 'off'; app.C8CheckBox.Value = 0; app.Label8.Enable = 'off'; end

            app.S1EditField.Value = 1;
            app.S2EditField.Value = 1;
            app.S3EditField.Value = 1;
            app.S4EditField.Value = 1;
            app.S5EditField.Value = 1;
            app.S6EditField.Value = 1;
            app.S7EditField.Value = 1;
            app.S8EditField.Value = 1;

            app.pd.coilSensitivities = [app.S1EditField.Value app.S2EditField.Value app.S3EditField.Value app.S4EditField.Value ...
                app.S5EditField.Value app.S6EditField.Value app.S7EditField.Value app.S8EditField.Value];

            app.pd.coilActive_flag = [app.C1CheckBox.Value app.C2CheckBox.Value app.C3CheckBox.Value app.C4CheckBox.Value ...
                app.C5CheckBox.Value app.C6CheckBox.Value app.C7CheckBox.Value app.C8CheckBox.Value];

            app.S1EditField.Enable = 'on';
            if app.pd.nrCoils > 1 app.S2EditField.Enable = 'on'; else app.S2EditField.Enable = 'off'; end
            if app.pd.nrCoils > 2 app.S3EditField.Enable = 'on'; else app.S3EditField.Enable = 'off'; end
            if app.pd.nrCoils > 3 app.S4EditField.Enable = 'on'; else app.S4EditField.Enable = 'off'; end
            if app.pd.nrCoils > 4 app.S5EditField.Enable = 'on'; else app.S5EditField.Enable = 'off'; end
            if app.pd.nrCoils > 5 app.S6EditField.Enable = 'on'; else app.S6EditField.Enable = 'off'; end
            if app.pd.nrCoils > 6 app.S7EditField.Enable = 'on'; else app.S7EditField.Enable = 'off'; end
            if app.pd.nrCoils > 7 app.S8EditField.Enable = 'on'; else app.S8EditField.Enable = 'off'; end

            app.AutoSensitivityCheckBox.Value = 0;
            app.AutoSensitivityCheckBox.Enable = 'on';

            drawnow

        end


        % Enable coil buttons
        function EnableCoilButtonsFcn(app)

            app.C1CheckBox.Enable = 'on';
            app.Label1.Enable = 'on';
            if app.pd.nrCoils > 1 app.C2CheckBox.Enable = 'on'; app.Label2.Enable = 'on'; else app.C2CheckBox.Enable = 'off'; app.Label2.Enable = 'off'; end
            if app.pd.nrCoils > 2 app.C3CheckBox.Enable = 'on'; app.Label3.Enable = 'on'; else app.C3CheckBox.Enable = 'off'; app.Label3.Enable = 'off'; end
            if app.pd.nrCoils > 3 app.C4CheckBox.Enable = 'on'; app.Label4.Enable = 'on'; else app.C4CheckBox.Enable = 'off'; app.Label4.Enable = 'off'; end
            if app.pd.nrCoils > 4 app.C5CheckBox.Enable = 'on'; app.Label5.Enable = 'on'; else app.C5CheckBox.Enable = 'off'; app.Label5.Enable = 'off'; end
            if app.pd.nrCoils > 5 app.C6CheckBox.Enable = 'on'; app.Label6.Enable = 'on'; else app.C6CheckBox.Enable = 'off'; app.Label6.Enable = 'off'; end
            if app.pd.nrCoils > 6 app.C7CheckBox.Enable = 'on'; app.Label7.Enable = 'on'; else app.C7CheckBox.Enable = 'off'; app.Label7.Enable = 'off'; end
            if app.pd.nrCoils > 7 app.C8CheckBox.Enable = 'on'; app.Label8.Enable = 'on'; else app.C8CheckBox.Enable = 'off'; app.Label8.Enable = 'off'; end

            app.CoilLabel.Enable = 'on';
            app.RelativeSensitivityLabel.Enable = 'on';
            app.AutoSensitivityCheckBox.Enable = 'on';
            if app.AutoSensitivityCheckBox.Value == 0

                app.S1EditField.Enable = 'on';
                if app.pd.nrCoils > 1 app.S2EditField.Enable = 'on'; else app.S2EditField.Enable = 'off'; end
                if app.pd.nrCoils > 2 app.S3EditField.Enable = 'on'; else app.S3EditField.Enable = 'off'; end
                if app.pd.nrCoils > 3 app.S4EditField.Enable = 'on'; else app.S4EditField.Enable = 'off'; end
                if app.pd.nrCoils > 4 app.S5EditField.Enable = 'on'; else app.S5EditField.Enable = 'off'; end
                if app.pd.nrCoils > 5 app.S6EditField.Enable = 'on'; else app.S6EditField.Enable = 'off'; end
                if app.pd.nrCoils > 6 app.S7EditField.Enable = 'on'; else app.S7EditField.Enable = 'off'; end
                if app.pd.nrCoils > 7 app.S8EditField.Enable = 'on'; else app.S8EditField.Enable = 'off'; end

            else

                app.S1EditField.Enable = 'off';
                app.S2EditField.Enable = 'off';
                app.S3EditField.Enable = 'off';
                app.S4EditField.Enable = 'off';
                app.S5EditField.Enable = 'off';
                app.S6EditField.Enable = 'off';
                app.S7EditField.Enable = 'off';
                app.S8EditField.Enable = 'off';

            end

            drawnow

        end


        % Disable coil buttons
        function DisableCoilButtonsFcn(app)

            app.CoilLabel.Enable = 'off';
            app.RelativeSensitivityLabel.Enable = 'off';
            app.AutoSensitivityCheckBox.Enable = 'off';

            app.C1CheckBox.Enable = 'off';
            app.C2CheckBox.Enable = 'off';
            app.C3CheckBox.Enable = 'off';
            app.C4CheckBox.Enable = 'off';
            app.C5CheckBox.Enable = 'off';
            app.C6CheckBox.Enable = 'off';
            app.C7CheckBox.Enable = 'off';
            app.C8CheckBox.Enable = 'off';
            
            app.S1EditField.Enable = 'off';
            app.S2EditField.Enable = 'off';
            app.S3EditField.Enable = 'off';
            app.S4EditField.Enable = 'off';
            app.S5EditField.Enable = 'off';
            app.S6EditField.Enable = 'off';
            app.S7EditField.Enable = 'off';
            app.S8EditField.Enable = 'off';

            app.Label1.Enable = 'off';
            app.Label2.Enable = 'off';
            app.Label3.Enable = 'off';
            app.Label4.Enable = 'off';
            app.Label5.Enable = 'off';
            app.Label6.Enable = 'off';
            app.Label7.Enable = 'off';
            app.Label8.Enable = 'off';

            drawnow

        end


        % Enable radial buttons
        function EnableRadialButtonsFcn(app)
            
            switch app.pd.dataType

                case "2Dradial"

                    app.GxDelayEditField.Enable = 'on';
                    app.GxdelayEditFieldLabel.Enable = 'on';
                    app.GyDelayEditField.Enable = 'on';
                    app.GydelayEditFieldLabel.Enable = 'on';
                    app.GzDelayEditField.Enable = 'on';
                    app.GzdelayEditFieldLabel.Enable = 'on';

                    if app.bartDetectedFlag

                        if app.CSRecoCheckBox.Value
                            app.GradDelayCalibrationCheckBox.Enable = 'on';
                            app.GradDelayCalibrationCheckBoxLabel.Enable = 'on';
                            app.RingMethodCheckBox.Enable = 'on';
                            app.RingMethodCheckBoxLabel.Enable = 'on';
                            app.AbortGradCalButton.Enable = 'on';
                        else
                            app.GradDelayCalibrationCheckBox.Enable = 'off';
                            app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
                            app.RingMethodCheckBox.Enable = 'off';
                            app.RingMethodCheckBoxLabel.Enable = 'off';
                            app.AbortGradCalButton.Enable = 'off';
                        end
                        app.CenterEchoCheckBox.Enable = 'on';
                        app.CenterEchoCheckBoxLabel.Enable = 'on';
                        app.PhaseCorrectCheckBox.Enable = 'on';
                        app.PhaseCorrectCheckBoxLabel.Enable = 'on';
                        app.AmplitudeCorrectionCheckBox.Enable = 'on';
                        app.AmplitudeCorrectionCheckBoxLabel.Enable = 'on';
                        app.DensityCorrectCheckBox.Enable = 'on';
                        app.DensityCorrectCheckBoxLabel.Enable = 'on';
                        app.TukeyFilterCheckBox.Enable = 'on';
                        app.TukeyFilterCheckBoxLabel.Enable = 'on';
                        app.DataOffsetRadialEditField.Enable = 'off';
                        app.DataOffsetRadialEditFieldLabel.Enable = 'on';
                 
                    else

                        app.GradDelayCalibrationCheckBox.Enable = 'off';
                        app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
                        app.GradDelayCalibrationCheckBox.Value = 0;
                        app.RingMethodCheckBox.Enable = 'off';
                        app.RingMethodCheckBoxLabel.Enable = 'off';
                        app.RingMethodCheckBox.Value = 0;
                        app.AbortGradCalButton.Enable = 'on';
                        app.CenterEchoCheckBox.Enable = 'on';
                        app.CenterEchoCheckBoxLabel.Enable = 'off';
                        app.PhaseCorrectCheckBox.Enable = 'on';
                        app.PhaseCorrectCheckBoxLabel.Enable = 'off';
                        app.AmplitudeCorrectionCheckBox.Enable = 'on';
                        app.AmplitudeCorrectionCheckBoxLabel.Enable = 'off';
                        app.DensityCorrectCheckBox.Enable = 'off';
                        app.DensityCorrectCheckBoxLabel.Enable = 'off';
                        app.DensityCorrectCheckBox.Value = 0;
                        app.TukeyFilterCheckBox.Enable = 'on';
                        app.TukeyFilterCheckBoxLabel.Enable = 'off';
                        app.DataOffsetRadialEditField.Enable = 'off';
                        app.DataOffsetRadialEditFieldLabel.Enable = 'off';
                
                    end


                case "3Dute"

                    app.GxDelayEditField.Enable = 'on';
                    app.GxdelayEditFieldLabel.Enable = 'on';
                    app.GyDelayEditField.Enable = 'on';
                    app.GydelayEditFieldLabel.Enable = 'on';
                    app.GzDelayEditField.Enable = 'on';
                    app.GzdelayEditFieldLabel.Enable = 'on';

                    if app.bartDetectedFlag

                        if app.CSRecoCheckBox.Value
                            app.GradDelayCalibrationCheckBox.Enable = 'on';
                            app.GradDelayCalibrationCheckBoxLabel.Enable = 'on';
                            app.RingMethodCheckBox.Enable = 'off';
                            app.RingMethodCheckBoxLabel.Enable = 'off';
                            app.AbortGradCalButton.Enable = 'on';
                        else
                            app.GradDelayCalibrationCheckBox.Enable = 'off';
                            app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
                            app.RingMethodCheckBox.Enable = 'off';
                            app.RingMethodCheckBoxLabel.Enable = 'off';
                            app.AbortGradCalButton.Enable = 'off';
                        end
                        app.CenterEchoCheckBox.Enable = 'on';
                        app.CenterEchoCheckBoxLabel.Enable = 'on';
                        app.PhaseCorrectCheckBox.Enable = 'on';
                        app.PhaseCorrectCheckBoxLabel.Enable = 'on';
                        app.AmplitudeCorrectionCheckBox.Enable = 'on';
                        app.AmplitudeCorrectionCheckBoxLabel.Enable = 'on';
                        app.DensityCorrectCheckBox.Enable = 'on';
                        app.DensityCorrectCheckBoxLabel.Enable = 'on';
                        app.TukeyFilterCheckBox.Enable = 'on';
                        app.TukeyFilterCheckBoxLabel.Enable = 'on';
                        app.DataOffsetRadialEditField.Enable = 'on';
                        app.DataOffsetRadialEditFieldLabel.Enable = 'on';
                 
                    else

                        app.GradDelayCalibrationCheckBox.Enable = 'off';
                        app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
                        app.GradDelayCalibrationCheckBox.Value = 0;
                        app.RingMethodCheckBox.Enable = 'off';
                        app.RingMethodCheckBoxLabel.Enable = 'off';
                        app.RingMethodCheckBox.Value = 0;
                        app.AbortGradCalButton.Enable = 'off';
                        app.CenterEchoCheckBox.Enable = 'on';
                        app.CenterEchoCheckBoxLabel.Enable = 'on';
                        app.PhaseCorrectCheckBox.Enable = 'on';
                        app.PhaseCorrectCheckBoxLabel.Enable = 'on';
                        app.AmplitudeCorrectionCheckBox.Enable = 'on';
                        app.AmplitudeCorrectionCheckBoxLabel.Enable = 'on';
                        app.DensityCorrectCheckBox.Enable = 'off';
                        app.DensityCorrectCheckBoxLabel.Enable = 'off';
                        app.DensityCorrectCheckBox.Value = 0;
                        app.TukeyFilterCheckBox.Enable = 'on';
                        app.TukeyFilterCheckBoxLabel.Enable = 'on';
                        app.DataOffsetRadialEditField.Enable = 'on';
                        app.DataOffsetRadialEditFieldLabel.Enable = 'on';
                 
                    end

                otherwise

                    app.GxDelayEditField.Enable = 'off';
                    app.GxdelayEditFieldLabel.Enable = 'off';
                    app.GyDelayEditField.Enable = 'off';
                    app.GydelayEditFieldLabel.Enable = 'off';
                    app.GzDelayEditField.Enable = 'off';
                    app.GzdelayEditFieldLabel.Enable = 'off';
                    app.GradDelayCalibrationCheckBox.Enable = 'off';
                    app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
                    app.RingMethodCheckBox.Enable = 'off';
                    app.RingMethodCheckBoxLabel.Enable = 'off';
                    app.AbortGradCalButton.Enable = 'off';
                    app.CenterEchoCheckBox.Enable = 'off';
                    app.CenterEchoCheckBoxLabel.Enable = 'off';
                    app.PhaseCorrectCheckBox.Enable = 'off';
                    app.PhaseCorrectCheckBoxLabel.Enable = 'off';
                    app.AmplitudeCorrectionCheckBox.Enable = 'off';
                    app.AmplitudeCorrectionCheckBoxLabel.Enable = 'off';
                    app.DensityCorrectCheckBox.Enable = 'off';
                    app.DensityCorrectCheckBoxLabel.Enable = 'off';
                    app.TukeyFilterCheckBox.Enable = 'off';
                    app.TukeyFilterCheckBoxLabel.Enable = 'off';
                    app.DataOffsetRadialEditField.Enable = 'off';
                    app.DataOffsetRadialEditFieldLabel.Enable = 'off';

            end

            drawnow;

        end


        % Disable radial buttons
        function DisableRadialButtonsFcn(app)
                
            app.GxDelayEditField.Enable = 'off';
            app.GxdelayEditFieldLabel.Enable = 'off';
            app.GyDelayEditField.Enable = 'off';
            app.GydelayEditFieldLabel.Enable = 'off';
            app.GzDelayEditField.Enable = 'off';
            app.GzdelayEditFieldLabel.Enable = 'off';
            app.GradDelayCalibrationCheckBox.Enable = 'off';
            app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
            app.RingMethodCheckBox.Enable = 'off';
            app.RingMethodCheckBoxLabel.Enable = 'off';
            app.AbortGradCalButton.Enable = 'off';
            if contains(app.pd.dataType,["2Dradial","3Dute"])
                app.AbortGradCalButton.Enable = 'on';
            end
            app.CenterEchoCheckBox.Enable = 'off';
            app.CenterEchoCheckBoxLabel.Enable = 'off';
            app.PhaseCorrectCheckBox.Enable = 'off';
            app.PhaseCorrectCheckBoxLabel.Enable = 'off';
            app.AmplitudeCorrectionCheckBox.Enable = 'off';
            app.AmplitudeCorrectionCheckBoxLabel.Enable = 'off';
            app.DensityCorrectCheckBox.Enable = 'off';
            app.DensityCorrectCheckBoxLabel.Enable = 'off';
            app.TukeyFilterCheckBox.Enable = 'off';
            app.TukeyFilterCheckBoxLabel.Enable = 'off';
            app.DataOffsetRadialEditField.Enable = 'off';
            app.DataOffsetRadialEditFieldLabel.Enable = 'off';

            drawnow;

        end

        % Set images, figure properties, etc..
        function SetAppLayoutFcn(app)

            % Images
            app.TulpImage.ImageSource = 'AmsterdamUMC.png';
            app.MRSImage.ImageSource = 'MRSolutionsLogo.gif';
            app.MovieImage.ImageSource = 'Movie.gif';
            app.PhaseMovieImage.ImageSource = 'Movie.gif';
            app.FlowMovieImage.ImageSource = 'Movie.gif';
            app.blackImage = imread('black.png');

            % Buttons
            app.LoadMRDButton.Icon = 'disk.png';
            app.RecoimagesButton.Icon = 'reconstruct.gif';
            app.ExportDCMButton.Icon = 'disk.png';
            app.ExportNIFTIButton.Icon = 'disk.png';
            app.ExportMRDButton.Icon = 'disk.png';
            app.ExportGIFButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.MoviePlayButton.Icon = 'play.gif';
            app.MovieStopButton.Icon = 'stop.gif';
            app.PhaseMovieStartButton.Icon = 'play.gif';
            app.PhaseMovieStopButton.Icon = 'stop.gif';
            app.KStartButton.Icon = 'play.gif';
            app.KStopButton.Icon = 'stop.gif';
            app.MIPxPlayButton.Icon = 'play.gif';
            app.MIPyPlayButton.Icon = 'play.gif';
            app.FlowMovieStartButton.Icon = 'play.gif';
            app.FlowMovieStopButton.Icon = 'stop.gif';
            app.MaskButton.Icon = 'segmentation.png';
            app.PhaseUnwrapButton.Icon = 'unwrap.png';
            app.FlowLoadVencTableButton.Icon = 'disk.png';
            app.FlowCalcButton.Icon = 'flow.png';
           
            % Title
            app.TitleLabel.Text = ['P' char(178) 'ROUD'];

            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green';
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';

            app.guiBlack = [0.15,0.15,0.15];
            app.guiGrey = [0.94,0.94,0.94];
            app.guiMiddleGrey = [0.9,0.9,0.9];
            app.guiDarkGrey = [0.5,0.5,0.5];
            app.guiWhite = [1.0,1.0,1.0];
            app.guiBlackBlue = [0.02,0.13,0.3];

            app.MovieImage.BackgroundColor = app.guiGrey;
            app.PhaseMovieImage.BackgroundColor = app.guiGrey;
            app.FlowMovieImage.BackgroundColor = app.guiGrey;

            % Load sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');

            % Block figure interactivity
            app.KspaceFig.Toolbar.Visible = 'off';
            app.KspaceFig.Interactions = [];
            app.MIPFig.Toolbar.Visible = 'off';
            app.MIPFig.Interactions = [];
            app.PhaseRecoFig.Toolbar.Visible = 'off';
            app.PhaseRecoFig.Interactions = [];
            app.PhaseRecoFig.Interactions = [zoomInteraction regionZoomInteraction];
            app.FlowRecoFig.Toolbar.Visible = 'off';
            app.FlowRecoFig.Interactions = [];
            app.FlowRecoFig.Interactions = [zoomInteraction regionZoomInteraction];
            app.SenseFig1.Toolbar.Visible = 'off';
            app.SenseFig1.Interactions = [];
            app.SenseFig2.Toolbar.Visible = 'off';
            app.SenseFig2.Interactions = [];
            app.SenseFig3.Toolbar.Visible = 'off';
            app.SenseFig3.Interactions = [];
            app.SenseFig4.Toolbar.Visible = 'off';
            app.SenseFig4.Interactions = [];
            app.SenseFig5.Toolbar.Visible = 'off';
            app.SenseFig5.Interactions = [];
            app.SenseFig6.Toolbar.Visible = 'off';
            app.SenseFig6.Interactions = [];
            app.SenseFig7.Toolbar.Visible = 'off';
            app.SenseFig7.Interactions = [];
            app.SenseFig8.Toolbar.Visible = 'off';
            app.SenseFig8.Interactions = [];

            % Set k-space level and window limits
            app.KLevelEditField.Limits = [0 app.pd.maxKspace+1];
            app.KLevelSlider.Limits = [0 app.pd.maxKspace+1];
            app.KWindowEditField.Limits = [0 app.pd.maxKspace+1];
            app.KWindowSlider.Limits = [0 app.pd.maxKspace+1];

            % Set image level and window limits
            app.LevelEditField.Limits = [0 app.pd.maxImage+1];
            app.LevelSlider.Limits = [0 app.pd.maxImage+1];
            app.WindowEditField.Limits = [0 app.pd.maxImage+1];
            app.WindowSlider.Limits = [0 app.pd.maxImage+1];
            app.MIPLevelEditField.Limits = [0 app.pd.maxImage+1];
            app.MIPLevelSlider.Limits = [0 app.pd.maxImage+1];
            app.MIPWindowEditField.Limits = [0 app.pd.maxImage+1];
            app.MIPWindowSlider.Limits = [0 app.pd.maxImage+1];
            app.ThresholdEditField.Limits = [0 app.pd.maxImage+1];
            app.ThresholdSlider.Limits = [0 app.pd.maxImage+1];

            drawnow;

        end


        % App version check
        function CheckVersionFnc(app)

            try

                currentVersion = convertStringsToChars(app.appVersion);

                newestVersions = {"6.8","6.7","6.6","6.5","6.4","6.3","6.2","6.1","6.0","5.9","5.8","5.7","5.6","5.5","5.4","5.3","5.2","5.1","5.0"};

                webTxt = [];
                ver = 0;
                while isempty(webTxt) && ver<=length(newestVersions)
                    try
                        ver = ver + 1;
                        newVersionFile = strcat("https://github.com/Moby1971/P2ROUD-v",newestVersions{ver},"/blob/main/appversion.txt");
                        webTxt = webread(newVersionFile);
                    catch
                    end
                end

                pos = strfind(lower(webTxt),"p2roud_version=");
                newVersion = webTxt(pos+15:pos+15+length(newestVersions{ver})+3);

                app.TextMessage(strcat("Current version = ",currentVersion),1);
                app.TextMessage(strcat("Newest version = ",newVersion),1);

                newVersion = strrep(newVersion,".","");
                newVersionNr = str2num(newVersion);
                currentVersion = strrep(currentVersion,".","");
                currentVersionNr = str2num(currentVersion);

                if currentVersionNr < newVersionNr
                    app.TextMessage('A new version of P2ROUD is available ...',1);
                    app.TextMessage('Visit https://github.com/Moby1971/ to update ...',1);
                else
                    app.TextMessage('P2ROUD is up to date ...',1);
                end

            catch ME
                app.TextMessage(ME.message,1)
            end

        end % CheckVersionFnc


        % --------------------------------------------------------------
        %
        %                           PARAMETERS
        %
        % --------------------------------------------------------------

        % Trajectory types:
        %
        %                   app.pd.trajType
        %                   ----------------
        % pe1_order = 1:    standard
        %                   rtable                  (rtable.rtv) fast-spin-echo
        % pe1_order = 2:    zigzag
        % pe1_order = 3:    mul_kspace              (table in MRD file)
        % pe1_order = 4:    P2ROUD                  (txt file), number of entries = ky x kz
        % pe1_order = 5:    NRLUT                   (txt file), Non Regular LUT, k-space undersampling
        % 
        %
        % Data types:
        %
        %                   app.pd.dataType
        %                   ---------------
        %                   2D
        %                   2Dradial
        %                   2Depi
        %                   3D
        %                   3Dute
        %                   ZTE    
        %
        %
        %

        % Set data parameters
        function SetParametersFcn(app)

            app.TextMessage('Setting k-space parameters ...',1);
            app.TrajectoryViewField.Value = '-';

            % Determine the type of multi-dimensional dataset
            app.pd = app.pd.setDataParameters(app);

            % Read the SQL properties
            if app.pd.sqlFlag
                app.pd = app.pd.sqlParameters(app);
            end
          
            % Standard k-space filling
            app.pd.trajType = "standard";

            % Scanner k-space table (RARE sequence)
            app.rtableFile = '';
            if (app.pd.proudRecoScanFlag == false) && (app.pd.dataType ~= "2Dradial")
                tableFilename{1} = strcat(app.mrdImportPath,filesep,'rtable.rtv');
                tableFilename{2} = strcat(app.mrdImportPath,filesep,'origrtable.rtv.orig');
                tableFilename{3} = strcat(app.mrdImportPath,filesep,'Recon_001',filesep,'rtable.rtv');
                tableFilename{4} = strcat(app.mrdImportPath,filesep,'Recon_001',filesep,'origrtable.rtv.orig');
                for cnt = 1:length(tableFilename)
                    if isfile(tableFilename{cnt})
                        app.TextMessage('Scanner k-space table detected ...',1);
                        app.pd.trajType = "rtable";
                        app.TrajectoryViewField.Value = 'rtable.rtv';
                        app.rtableFile = tableFilename{cnt};
                        break;
                    end
                end
            end

            % User k-space trajectory & P2ROUD implementation
            if app.pd.proudRecoScanFlag == false

                % pe1_order == 2 : k-space zig-zag
                if (app.pd.pe1_order == 2) && (app.pd.dataType ~= "2Dradial")
                    app.pd.trajType = "zigzag";
                    app.TrajectoryViewField.Value = 'zigzag';
                end 
   
                % pe1_order == 3 : k-space trajectory list in preclinical
                if (app.pd.pe1_order == 3) && (app.pd.dataType ~= "2Dradial")
                    app.pd.trajType = "mul_kspace";
                    app.TrajectoryViewField.Value = 'mul_kspace_traj';
                end

                % pe1_order == 4 : 3D k-space trajectory from txt file
                if ((app.pd.pe1_order == 4) && (app.pd.dataType ~= "2Dradial")) || app.forceProudFlag

                    app.TextMessage('3D LUT ...',1);
                    
                    % Read k-space trajectory file
                    flist = dir(fullfile(app.mrdImportPath,'*.txt'));
                    if ~isempty(flist)
                        for cnt = length(flist):-1:1
                            if contains(flist(cnt).name,app.pd.ignoreFiles)
                                flist(cnt) = [];
                            end
                        end
                    end

                    if ~isempty(flist)
                        app.pd.gp_var_proud = load([flist(1).folder,filesep,flist(1).name]);
                        app.TrajectoryViewField.Value = flist(1).name;
                        app.pd.trajType = "P2ROUD";
                        app.TextMessage(strcat("3D LUT trajectory file = ",flist(1).name," ..."),1);
                    else
                        app.pd.trajType = "";
                        app.TextMessage('ERROR: Trajectory file not found ...',1);
                        app.SetStatus(2);
                    end

                end

                % pe1_order == 5 : Unrestricted 3D LUT mode
                if ((app.pd.pe1_order == 5) && (app.pd.dataType ~= "2Dradial"))
                    
                    app.TextMessage("3D Non Regular LUT ...",1);

                    % Read k-space trajectory file
                    flist = dir(fullfile(app.mrdImportPath,'*.txt'));
                    if ~isempty(flist)
                        for cnt = length(flist):-1:1
                            if contains(flist(cnt).name,app.pd.ignoreFiles)
                                flist(cnt) = [];
                            end
                        end
                    end

                    if ~isempty(flist)
                        app.pd.gp_var_lut = load([flist(1).folder,filesep,flist(1).name]);
                        app.TrajectoryViewField.Value = flist(1).name;
                        app.pd.trajType = "NRLUT";
                        app.TextMessage(strcat("Non Regular LUT trajectory file = ",flist(1).name," ..."),1);
                    else
                        app.pd.trajType = "";
                        app.TextMessage('ERROR: Trajectory file not found ...',1);
                        app.SetStatus(2);
                    end

                end

                % ZTE trajectory
                if app.pd.dataType == "ZTE"
                    app.TextMessage('ZTE k-space trajectory ...',1);

                    % Read k-space trajectory file
                    flist = dir(fullfile(app.mrdImportPath,'*.txt'));
                    if ~isempty(flist)
                        for cnt = length(flist):-1:1
                            if contains(flist(cnt).name,app.pd.ignoreFiles)
                                flist(cnt) = [];
                            end
                        end
                    end

                    if ~isempty(flist)
                        app.pd.gp_var_proud = load([flist(1).folder,filesep,flist(1).name]);
                        app.TrajectoryViewField.Value = flist(1).name;
                        app.pd.trajType = "ZTE";
                        app.TextMessage(strcat("ZTE trajectory file = ",flist(1).name," ..."),1);
                    else
                        app.pd.trajType = "";
                        app.TextMessage('ERROR: Trajectory file not found ...',1);
                        app.SetStatus(2);
                    end

                end

            end

            % Show some parameters in app
            app.SequenceViewField.Value = app.pd.PPL;
            app.TRViewField.Value = app.pd.TR;
            app.TEViewField.Value = app.pd.TE;
            app.NAViewField.Value = app.pd.NO_AVERAGES;
            app.NRViewField.Value = app.pd.EXPERIMENT_ARRAY;
            app.NEViewField.Value = app.pd.NO_ECHOES;
            app.FAViewField.Value = num2str(app.pd.flipAngleArray);
            app.NFAViewField.Value = length(app.pd.flipAngleArray);
            app.TextMessage(strcat("Phase orientation = ",num2str(app.pd.PHASE_ORIENTATION)," ..."),2);

            % Geometry parameters
            app.FieldOfViewViewField2.Value = app.pd.FOV;
            app.AspectRatioViewField.Value = app.pd.aspectratio;
            app.SliceThicknessViewField.Value = app.pd.SLICE_THICKNESS;
            app.SliceGapViewField.Value = app.pd.SQLsliceGap;
            app.AspectRatioViewField.Value = app.pd.aspectratio;
            app.PhaseOrientationViewField.Value = app.pd.PHASE_ORIENTATION;
            app.OffsetXViewField.Value = app.pd.SQLoffsetX;
            app.OffsetYViewField.Value = app.pd.SQLoffsetY;
            app.OffsetZViewField.Value = app.pd.SQLoffsetZ;
            app.AngleXViewField.Value = app.pd.SQLangleX;
            app.AngleYViewField.Value = app.pd.SQLangleY;
            app.AngleZViewField.Value = app.pd.SQLangleZ;

            % Flow
            if app.pd.venc > 0
                app.VencViewField.Value = num2str(app.pd.vencAmp);
            else
                app.VencViewField.Value = '-';
            end

            % Matrix sizes and parameters
            switch app.pd.dataType

                case "3Dute"

                    % 3D data
                    app.MZLabel.Text = 'Kz';
                    app.ZEditFieldLabel.Text = 'Z';

                    % Permute k-space to X(readout), Y(spokes)
                    app.pd = app.pd.permute3Dkspace;
                    app.pd.multiRepetitions_flag = false;

                    % New matrix dimensions
                    app.KMatrixViewField1.Value = app.pd.NO_SAMPLES;
                    app.KMatrixViewField2.Value = app.pd.NO_VIEWS;
                    app.KMatrixViewField3.Value = app.pd.NO_VIEWS_2;
                    app.NRViewField.Value = app.pd.nr_repetitions;
                  
                    % Field of view
                    app.FOVViewField1.Value = app.pd.FOV;
                    app.FOVViewField2.Value = app.pd.FOV*app.pd.aspectratio;
                    app.FOVViewField3.Value = app.pd.SLICE_THICKNESS;

                    % Reco size
                    app.XEditField.Value = app.KMatrixViewField1.Value;
                    app.YEditField.Value = app.KMatrixViewField1.Value;
                    app.ZEditField.Value = app.KMatrixViewField3.Value;
                    app.NREditField.Value = app.NRViewField.Value;

                    % Gradient optimization settings
                    app.GradDelayCalibrationCheckBox.Value = 0;
                    app.RingMethodCheckBox.Value = 0;
                    app.CenterEchoCheckBox.Value = 1;
                    app.PhaseCorrectCheckBox.Value = 1;
                    app.AmplitudeCorrectionCheckBox.Value = 0;
                    app.DensityCorrectCheckBox.Value = 1;
                    app.TukeyFilterCheckBox.Value = 1;
                    app.EnableRadialButtonsFcn;

                    % Some Bart standard values
                    app.WVxyzEditField.Value = 0.01;
                    app.TVxyzEditField.Value = 0;
                    app.LLRxyzEditField.Value = 0;
                    app.TVtimeEditField.Value = 0;
                    app.ZTEIterationsEditField.Value = 30;

                    % Morphological operation structuring element
                    [xx,yy,zz] = ndgrid(-1:1);
                    app.structElement = sqrt(xx.^2 + yy.^2 + zz.^2) <= 1.0;

                case "3D"

                    % 3D data
                    app.MZLabel.Text = 'Kz';
                    app.ZEditFieldLabel.Text = 'Z';

                    % Matrix dimensions
                    app.KMatrixViewField1.Value = app.pd.NO_SAMPLES;
                    app.KMatrixViewField2.Value = app.pd.NO_VIEWS;
                    app.KMatrixViewField3.Value = app.pd.NO_VIEWS_2;

                    % Permute k-space to (X, Y, Z, NR, NFA, NE)
                    app.pd = app.pd.permute3Dkspace;

                    % Field of view
                    app.FOVViewField1.Value = app.pd.FOV;
                    app.FOVViewField2.Value = app.pd.FOV*app.pd.aspectratio;
                    app.FOVViewField3.Value = app.pd.SLICE_THICKNESS*app.pd.NO_SLICES + app.pd.SQLsliceGap*(app.pd.NO_SLICES - 1);
                 
                    % Reco size
                    app.XEditField.Value = app.KMatrixViewField1.Value;
                    app.YEditField.Value = app.KMatrixViewField2.Value;
                    app.ZEditField.Value = app.KMatrixViewField3.Value;
                    app.NREditField.Value = app.NRViewField.Value;

                    % Morphological operation structuring element
                    [xx,yy,zz] = ndgrid(-1:1);
                    app.structElement = sqrt(xx.^2 + yy.^2 + zz.^2) <= 1.0;

                    % Some Bart standard values
                    app.WVxyzEditField.Value = 0.01;
                    app.TVxyzEditField.Value = 0;
                    app.LLRxyzEditField.Value = 0.005;
                    app.TVtimeEditField.Value = 0;
              
                case {"2D","2Depi"}

                    % 2D data or radial data
                    app.MZLabel.Text = 'Slices';
                    app.ZEditFieldLabel.Text = 'Slices';

                    % Matrix dimensions
                    app.KMatrixViewField1.Value = app.pd.NO_SAMPLES;
                    app.KMatrixViewField2.Value = app.pd.NO_VIEWS;
                    app.KMatrixViewField3.Value = app.pd.NO_SLICES;

                    % Permute k-space to (X, Y, Z, NR, NFA, NE)
                    app.pd = app.pd.permute2Dkspace;

                    % Field of view
                    app.FOVViewField1.Value = app.pd.FOV;
                    app.FOVViewField2.Value = app.pd.FOV*app.pd.aspectratio;
                    app.FOVViewField3.Value = app.pd.SLICE_THICKNESS;
     
                    % Reco size
                    app.XEditField.Value = app.KMatrixViewField1.Value;
                    app.YEditField.Value = app.KMatrixViewField2.Value;
                    app.ZEditField.Value = app.KMatrixViewField3.Value;
                    app.NREditField.Value = app.NRViewField.Value;

                    % Morphological operation structuring element
                    [xx,yy] = ndgrid(-1:1);
                    app.structElement = sqrt(xx.^2 + yy.^2) <= 1.0;

                    % Some Bart standard values
                    app.WVxyzEditField.Value = 0.001;
                    app.TVxyzEditField.Value = 0;
                    app.LLRxyzEditField.Value = 0.0005;
                    app.TVtimeEditField.Value = 0;

                case "2Dradial"

                    % 2D data or radial data
                    app.MZLabel.Text = 'Slices';
                    app.ZEditFieldLabel.Text = 'Slices';

                    % Matrix dimensions
                    app.KMatrixViewField1.Value = app.pd.NO_SAMPLES;
                    app.KMatrixViewField2.Value = app.pd.NO_VIEWS;
                    app.KMatrixViewField3.Value = app.pd.NO_SLICES;

                    % Permute k-space to (X, Y, Z, NR, NFA, NE)
                    app.pd = app.pd.permute2Dkspace;

                    % Field of view
                    app.FOVViewField1.Value = app.pd.FOV;
                    app.FOVViewField2.Value = app.pd.FOV*app.pd.aspectratio;
                    app.FOVViewField3.Value = app.pd.SLICE_THICKNESS;

                    % Reco size
                    app.XEditField.Value = app.KMatrixViewField1.Value;
                    app.YEditField.Value = app.KMatrixViewField2.Value;
                    app.ZEditField.Value = app.KMatrixViewField3.Value;
                    app.NREditField.Value = app.NRViewField.Value;

                    % Gradient optimization settings
                    app.GradDelayCalibrationCheckBox.Value = 1;
                    app.RingMethodCheckBox.Value = 1;
                    app.CenterEchoCheckBox.Value = 1;
                    app.PhaseCorrectCheckBox.Value = 1;
                    app.AmplitudeCorrectionCheckBox.Value = 0;
                    app.DensityCorrectCheckBox.Value = 1;
                    app.TukeyFilterCheckBox.Value = 1;
                    app.EnableRadialButtonsFcn;

                    % Some Bart standard values
                    app.WVxyzEditField.Value = 0.01;
                    app.TVxyzEditField.Value = 0;
                    app.LLRxyzEditField.Value = 0.005;
                    app.TVtimeEditField.Value = 0;
                    app.ZTEIterationsEditField.Value = 30;

                    % Morphological operation structuring element
                    [xx,yy] = ndgrid(-1:1);
                    app.structElement = sqrt(xx.^2 + yy.^2) <= 1.0;

                case "ZTE"

                    % 3D data
                    app.MZLabel.Text = 'Kz';
                    app.ZEditFieldLabel.Text = 'Z';

                    % Construct the k-space for ZTE
                    app.pd = app.pd.makeZTEkSpace(app);

                    % Matrix dimensions
                    app.KMatrixViewField1.Value = app.pd.NO_SAMPLES;
                    app.KMatrixViewField2.Value = app.pd.NO_VIEWS;
                    app.KMatrixViewField3.Value = app.pd.NO_VIEWS_2;

                    % Permute k-space to (X, Y, Z, NR)
                    app.pd = app.pd.permute3Dkspace;

                    % Field of view
                    app.FOVViewField1.Value = app.pd.FOV;
                    app.FOVViewField2.Value = app.pd.FOV*app.pd.aspectratio;
                    app.FOVViewField3.Value = app.pd.SLICE_THICKNESS*app.pd.NO_SLICES;
                 
                    % Reco size
                    app.XEditField.Value = app.KMatrixViewField1.Value;
                    app.YEditField.Value = app.KMatrixViewField1.Value;
                    app.ZEditField.Value = app.KMatrixViewField1.Value;
                    app.NREditField.Value = app.NRViewField.Value;

                    % Some Bart standard values
                    app.WVxyzEditField.Value = 0.01;
                    app.TVxyzEditField.Value = 0;
                    app.LLRxyzEditField.Value = 0;
                    app.TVtimeEditField.Value = 0;
                    app.ZTEIterationsEditField.Value = 50;

                    % Morphological operation structuring element
                    [xx,yy,zz] = ndgrid(-1:1);
                    app.structElement = sqrt(xx.^2 + yy.^2 + zz.^2) <= 1.0;

            end

            % Image orientations
            switch app.pd.dataType

                case {"3D","3Dute","ZTE"}

                    app.imageOrient = ...
                        [1 2 3 4 5 6 7; 2 3 1 4 5 6 7; 3 1 2 4 5 6 7; ...
                        4 1 2 3 5 6 7; 4 2 3 1 5 6 7; 4 3 1 2 5 6 7; ...
                        1 2 4 3 5 6 7; 2 3 4 1 5 6 7; 3 1 4 2 5 6 7; ...
                        1 2 3 6 4 5 7; 1 3 2 6 4 5 7; 2 3 1 6 4 5 7];

                    app.kSpaceOrientLabel = ["Kx","Ky","Kz","NR","FA","echo" ; "Ky","Kz","Kx","NR","FA","echo" ; "Kz","Kx","Ky","NR","FA","echo" ; ...
                        "NR","Kx","Ky","Kz","FA","echo" ; "NR","Ky","Kz","Kx","FA","echo" ; "NR","Kz","Kx","Ky","FA","echo" ; ...
                        "Kx","Ky","NR","Kz","FA","echo" ; "Ky","Kz","NR","Kx","FA","echo" ; "Kz","Kx","NR","Ky","FA","echo" ; ...
                        "Kx","Ky","Kz","echo","NR","FA" ; "Kx","Kz","Ky","echo","NR","FA" ; "Ky","Kz","Kx","echo","NR","FA" ];

                    app.imageOrientLabel = ["X","Y","Z","NR","FA","echo" ; "Y","Z","X","NR","FA","echo" ; "Z","X","Y","NR","FA","echo" ; ...
                        "NR","X","Y","Z","FA","echo" ; "NR","Y","Z","X","FA","echo" ; "NR","Z","X","Y","FA","echo" ; ...
                        "X","Y","NR","Z","FA","echo" ; "Y","Z","NR","X","FA","echo" ; "Z","X","NR","Y","FA","echo" ; ...
                        "X","Y","Z","echo","NR","FA" ; "X","Z","Y","echo","NR","FA" ; "Y","Z","X","echo","NR","FA" ];

                case {"2D","2Dradial","2Depi"}

                    app.imageOrient = ...
                        [1 2 3 4 5 6 ; 2 3 1 4 5 6 ; 3 1 2 4 5 6 ; ...
                        4 1 2 3 5 6 ; 4 2 3 1 5 6 ; 4 3 1 2 5 6 ; ...
                        1 2 4 3 5 6 ; 2 3 4 1 5 6 ; 3 1 4 2 5 6 ; ...
                        1 2 3 6 4 5 ; 1 3 2 6 4 5 ; 2 3 1 6 4 5 ];

                    app.kSpaceOrientLabel = ["Kx","Ky","slice","NR","FA","echo" ; "Ky","slice","Kx","NR","FA","echo" ; "slice","Kx","Ky","NR","FA","echo" ; ...
                        "NR","Kx","Ky","slice","FA","echo" ; "NR","Ky","slice","Kx","FA","echo" ; "NR","slice","Kx","Ky","FA","echo" ; ...
                        "Kx","Ky","NR","slice","FA","echo" ; "Ky","slice","NR","Kx","FA","echo" ; "slice","Kx","NR","Ky","FA","echo" ; ...
                        "Kx","Ky","slice","echo","NR","FA" ; "Kx","slice","Ky","echo","NR","FA" ; "Ky","slice","Kx","echo","NR","FA" ];

                    app.imageOrientLabel = ["X","Y","slice","NR","FA","echo" ; "Y","slice","X","NR","FA","echo" ; "slice","X","Y","NR","FA","echo" ; ...
                        "NR","X","Y","slice","FA","echo" ; "NR","Y","slice","X","FA","echo" ; "NR","slice","X","Y","FA","echo" ; ...
                        "X","Y","NR","slice","FA","echo" ; "Y","slice","NR","X","FA","echo" ; "slice","X","NR","Y","FA","echo" ; ...
                        "X","Y","slice","echo","NR","FA" ; "X","slice","Y","echo","NR","FA" ; "Y","slice","X","echo","NR","FA" ];

            end

            % Reset orientation spinners
            app.OrientationSpinner.Value = 1;
            app.MIPOrientationSpinner.Value = 1;
            app.KOrientationSpinner.Value = 1;
            app.PhaseOrientationSpinner.Value = 1;
            app.FlowOrientationSpinner.Value = 1;

            % Total scan time and scan time / dynamic
            app.CalcTotalAcquisitionTimeFcn;

        end % SetParametersFcn


        % Calculate the total acquisition time
        function CalcTotalAcquisitionTimeFcn(app)

            try

                if app.pd.rprFile_flag

                    pos = strfind(app.pd.rprFile,":IM_TEXT_DCM_AcquisitionDuration");
                    txt = app.pd.rprFile(pos:pos+40);
                    pos2 = strfind(txt,"""");
                    tt = extractBetween(txt,pos2(1)+1,pos2(2)-1);
                    app.pd.totalAcqTime = str2num(tt{1}); %#ok<*ST2NM>

                else

                    ME = MException('P2ROUD:acqnotfound','Acquisition duration not found in rpr file ...');
                    throw(ME);

                end

            catch ME

                app.TextMessage(ME.message,2);

                app.pd.totalAcqTime = app.pd.NO_VIEWS*app.pd.NO_SLICES*app.NEViewField.Value*app.NAViewField.Value*app.NFAViewField.Value*app.NRViewField.Value*app.pd.tr/1000;

                % FSE type sequences
                if contains(app.pd.PPL,'fse')
                    if app.pd.VIEWS_PER_SEGMENT > 1
                        app.pd.totalAcqTime = app.pd.totalAcqTime/app.pd.VIEWS_PER_SEGMENT;
                    else
                        app.pd.totalAcqTime = app.pd.totalAcqTime/app.NEViewField.Value;
                    end
                    app.pd.totalAcqTime = app.pd.totalAcqTime/app.pd.NO_SLICES;
                end

                % FLASH type sequences
                if contains(app.pd.PPL,'flash')
                    app.pd.totalAcqTime = app.pd.totalAcqTime/app.NEViewField.Value;
                    if app.pd.SLICE_INTERLEAVE == 2
                        app.pd.totalAcqTime = app.pd.totalAcqTime/app.pd.NO_SLICES;
                    end
                end

                app.pd.totalAcqTime = app.pd.totalAcqTime*app.pd.NO_VIEWS_2;

            end

            app.TimeDynViewField.Value = datestr(seconds(app.pd.totalAcqTime/app.NREditField.Value),'HH:MM:SS.FFF'); %#ok<*DATST> 
            app.ScanTimeViewField.Value = datestr(seconds(app.pd.totalAcqTime),'HH:MM:SS.FFF');
            drawnow;

        end % CalcTotalAcquisitionTimeFcn


        % Set the k-space imaging limits
        function SetKspaceLimitsFcn(app)

            % General buttons
            app.KxLabel.Enable = 'on';
            app.KyLabel.Enable = 'on';
            app.KspaceMovieLabel.Enable = 'on';
            app.KStartButton.Enable = 'on';
            app.FastSlowSwitch.Enable = 'on';
            app.KLevelEditField.Enable = 'on';
            app.KLLabel.Enable = 'on';
            app.KWindowEditField.Enable = 'on';
            app.KWLabel.Enable = 'on';
            app.KLevelSlider.Enable = 'on';
            app.KWindowSlider.Enable = 'on';
            app.fillingButton.Enable = 'on';
            app.averagesButton.Enable = 'on';
            app.kValuesButton.Enable = 'on';
            app.senseMapButton.Enable = 'on';
            app.FillingViewField.Enable = 'on';
            app.FillingPercLabel.Enable = 'on';
            app.KOrientationSpinner.Enable = 'on';
            app.KOrientationSpinnerLabel.Enable = 'on';
            app.CSRecoLabel.Enable = 'on';
            app.CSRecoCheckBox.Enable = 'on';
            app.PCAdeNoiseLabel.Enable = 'on';
            app.PCAdeNoiseWindowLabel.Enable = 'on';
            app.AFGPdeNoiseLabel.Enable = 'on';
            app.AFGPdeNoiseIterationsLabel.Enable = 'on';
            app.CDTVdeNoiseLabel.Enable = 'on';
            app.CDTVdeNoiseLamdaLabel.Enable = 'on';
            app.NLMFdeNoiseLabel.Enable = 'on';
            app.NLMFdeNoiseFilterStrengthLabel.Enable = 'on';
            app.PCAdeNoiseCheckBox.Enable = 'on';
            app.AFGPdeNoiseCheckBox.Enable = 'on';
            app.CDTVdeNoiseCheckBox.Enable = 'on';
            app.NLMFdeNoiseCheckBox.Enable = 'on';
            app.PCAdeNoiseWindowEditField.Enable = 'on';
            app.AFGPdeNoiseIterationsEditField.Enable = 'on';
            app.CDTVdeNoiseLambdaEditField.Enable = 'on';
            app.NLMFdeNoiseFilterStrengthEditField.Enable = 'on';
            app.DeRingLabel.Enable = 'on';
            app.KStopButton.Enable = 'off';
            app.DeRingCheckBox.Enable = 'on';
            app.FlowCalcButton.Enable = 'off';
            app.XEditField.Enable = 'on';
            app.YEditField.Enable = 'on';
            app.ZEditField.Enable = 'on';
            app.NREditField.Enable = 'on';

            % Initial settings
            app.stopMovieFlag = true;
            app.stopKspaceMovieFlag = true;
            app.newFigFlag = true;
            app.newPhaseFigFlag = true;
            app.newFlowFigFlag = true;
            % app.PCAdeNoiseCheckBox.Value = 0;
            % app.PCAdeNoiseWindowEditField.Value = 8;
            % app.AFGPdeNoiseIterationsEditField.Value = 25;
            % app.DeRingCheckBox.Value = 0;
            app.MIPcounter = 0;
            app.FlowVencLamp.Color = app.red;

            % Coils 
            if app.pd.nrCoils>1
                app.CoilSpinner.Value = 1;
                app.CoilSpinner.Limits = [1 app.pd.nrCoils];
                app.CoilSpinner.Enable = 'on';
                app.CoilSpinnerLabel.Enable = 'on';
                app.AutoSensitivityCheckBox.Value = 1;
            else
                app.CoilSpinner.Value = 1;
                app.CoilSpinner.Limits = [1 2];
                app.CoilSpinner.Enable = 'off';
                app.CoilSpinnerLabel.Enable = 'off';
                app.pd.coilActive_flag = [1 0 0 0 0 0 0 0];
                app.AutoSensitivityCheckBox.Value = 0;
            end
         
            % Dimension 1 selection
            im = permute(abs(app.pd.rawKspace{app.CoilSpinner.Value}),app.imageOrient(app.KOrientationSpinner.Value,:));
            dim1size = size(im,3);
            app.KDim3EditField.Value = 1;
            app.KDim3EditFieldS.Value = 1;
            app.KDim3Slider.Value = 1;
            app.KDim3SliderS.Value = 1;
            if dim1size > 1
                app.KDim3EditField.Limits = [1,dim1size];
                app.KDim3EditFieldS.Limits = [1,dim1size];
                app.KDim3Slider.Limits = [1,dim1size];
                app.KDim3SliderS.Limits = [1,dim1size];
                app.KDim3EditField.Value = round(dim1size/2);
                app.KDim3EditFieldS.Value = round(dim1size/2);
                app.KDim3Slider.Value = round(dim1size/2);
                app.KDim3SliderS.Value = round(dim1size/2);
                app.KDim3EditField.Enable = 'on';
                app.KDim3EditFieldS.Enable = 'on';
                app.KDim3Slider.Enable = 'on';
                app.KDim3SliderS.Enable = 'on';
                app.KDim3Label.Enable = 'on';
                app.KDim3LabelS.Enable = 'on';
            else
                app.KDim3EditField.Limits = [1,2];
                app.KDim3EditFieldS.Limits = [1,2];
                app.KDim3Slider.Limits = [1,2];
                app.KDim3SliderS.Limits = [1,2];
                app.KDim3EditField.Enable = 'off';
                app.KDim3EditFieldS.Enable = 'off';
                app.KDim3Slider.Enable = 'off';
                app.KDim3SliderS.Enable = 'off';
                app.KDim3Label.Enable = 'off';
                app.KDim3LabelS.Enable = 'off';
            end

            % Dimension 2 selection
            dim2size = size(im,4);
            app.KDim4EditField.Value = 1;
            app.KDim4Slider.Value = 1;
            if dim2size > 1
                app.KDim4EditField.Limits = [1,dim2size];
                app.KDim4Slider.Limits = [1,dim2size];
                app.KDim4EditField.Value = round(dim2size/2);
                app.KDim4Slider.Value = round(dim2size/2);
                app.KDim4EditField.Enable = 'on';
                app.KDim4Slider.Enable = 'on';
                app.KDim4Label.Enable = 'on';
            else
                app.KDim4EditField.Limits = [1,2];
                app.KDim4Slider.Limits = [1,2];
                app.KDim4EditField.Enable = 'off';
                app.KDim4Slider.Enable = 'off';
                app.KDim4Label.Enable = 'off';
            end

            % Echo selection
            app.KEchoEditField.Value = 1;
            app.KEchoSlider.Value = 1;
            if app.pd.multiEchoes_flag
                app.KEchoEditField.Limits = [1,app.NEViewField.Value];
                app.KEchoSlider.Limits = [1,app.NEViewField.Value];
                app.KEchoEditField.Enable = 'on';
                app.KEchoSlider.Enable = 'on';
                app.KEchoEditFieldLabel.Enable = 'on';
            else
                app.KEchoEditField.Limits = [1,2];
                app.KEchoSlider.Limits = [1,2];
                app.KEchoEditField.Enable = 'off';
                app.KEchoSlider.Enable = 'off';
                app.KEchoEditFieldLabel.Enable = 'off';
            end

            % Flip angle selection
            app.KFlipAngleEditField.Value = 1;
            app.KFlipAngleSlider.Value = 1;
            if app.pd.multiFlipAngles_flag
                app.KFlipAngleEditField.Limits = [1,app.NFAViewField.Value];
                app.KFlipAngleSlider.Limits = [1,app.NFAViewField.Value];
                app.KFlipAngleEditField.Enable = 'on';
                app.KFlipAngleSlider.Enable = 'on';
                app.KFlipAngleEditFieldLabel.Enable = 'on';
            else
                app.KFlipAngleEditField.Limits = [1,2];
                app.KFlipAngleSlider.Limits = [1,2];
                app.KFlipAngleEditField.Enable = 'off';
                app.KFlipAngleSlider.Enable = 'off';
                app.KFlipAngleEditFieldLabel.Enable = 'off';
            end

            % Label the orientations
            if app.senseMapButton.Value == 1
                app.KxLabel.Text = app.imageOrientLabel(app.KOrientationSpinner.Value,1);
                app.KyLabel.Text = app.imageOrientLabel(app.KOrientationSpinner.Value,2);
                app.KDim3Label.Text = app.imageOrientLabel(app.KOrientationSpinner.Value,3);
                app.KDim3LabelS.Text = app.imageOrientLabel(app.KOrientationSpinner.Value,3);
                app.KDim4Label.Text = app.imageOrientLabel(app.KOrientationSpinner.Value,4);
            else
                app.KxLabel.Text = app.kSpaceOrientLabel(app.KOrientationSpinner.Value,1);
                app.KyLabel.Text = app.kSpaceOrientLabel(app.KOrientationSpinner.Value,2);
                app.KDim3Label.Text = app.kSpaceOrientLabel(app.KOrientationSpinner.Value,3);
                app.KDim3LabelS.Text = app.kSpaceOrientLabel(app.KOrientationSpinner.Value,3);
                app.KDim4Label.Text = app.kSpaceOrientLabel(app.KOrientationSpinner.Value,4);
            end

            % Button status memory 
            app.kSpaceButtonStatus = {app.KDim3EditField.Enable,app.KDim3Slider.Enable,app.KDim4EditField.Enable,app.KDim4Slider.Enable, ...
                app.KFlipAngleEditField.Enable,app.KFlipAngleSlider.Enable,app.KEchoEditField.Enable,app.KEchoSlider.Enable, ...
                app.KOrientationSpinner.Enable,app.fillingButton.Enable,app.averagesButton.Enable,app.kValuesButton.Enable, ...
                app.senseMapButton.Enable,app.CoilSpinner.Enable,app.KspaceMovieLabel.Enable,app.KStartButton.Enable,app.KStopButton.Enable, ...
                app.FastSlowSwitch.Enable,app.KLevelEditField.Enable,app.KWindowEditField.Enable,app.KLevelSlider.Enable,app.KWindowSlider.Enable,app.CoilSpinnerLabel.Enable, ...
                app.FillingViewField.Enable,app.FillingPercLabel.Enable,app.KOrientationSpinnerLabel.Enable,app.KEchoEditFieldLabel.Enable,app.KFlipAngleEditFieldLabel.Enable, ...
                app.KDim4Label.Enable,app.KDim3Label.Enable,app.KLLabel.Enable,app.KWLabel.Enable,app.KxLabel.Enable,app.KyLabel.Enable,app.KDim3EditFieldS.Enable,app.KDim3SliderS.Enable,app.KDim3LabelS.Enable};

        end % SetKspaceLimitsFcn


        % Set the image limits
        function SetImageLimitsFcn(app)

            % Dimension 3 selection
            im = permute(abs(app.pd.images),app.imageOrient(app.OrientationSpinner.Value,:));
            dim3size = size(im,3);
            app.Dim3EditField.Value = 1;
            app.Dim3Slider.Value = 1;
            app.PhaseDim3EditField.Value = 1;
            app.PhaseDim3Slider.Value = 1;
            app.FlowDim3EditField.Value = 1;
            app.FlowDim3Slider.Value = 1;
            if dim3size > 1
                app.scrollEnableFlag = true;
                app.Dim3EditField.Limits = [1,dim3size];
                app.Dim3Slider.Limits = [1,dim3size];
                app.Dim3EditField.Value = round(dim3size/2);
                app.Dim3Slider.Value = round(dim3size/2);
                app.Dim3EditField.Enable = 'on';
                app.Dim3Slider.Enable = 'on';
                app.Dim3Label.Enable = 'on';
                app.PhaseDim3EditField.Limits = [1,dim3size];
                app.PhaseDim3Slider.Limits = [1,dim3size];
                app.PhaseDim3EditField.Value = round(dim3size/2);
                app.PhaseDim3Slider.Value = round(dim3size/2);
                app.PhaseDim3EditField.Enable = 'on';
                app.PhaseDim3Slider.Enable = 'on';
                app.PhaseDim3Label.Enable = 'on';
                app.FlowDim3EditField.Limits = [1,dim3size];
                app.FlowDim3Slider.Limits = [1,dim3size];
                app.FlowDim3EditField.Value = round(dim3size/2);
                app.FlowDim3Slider.Value = round(dim3size/2);
                app.FlowDim3EditField.Enable = 'on';
                app.FlowDim3Slider.Enable = 'on';
                app.FlowDim3Label.Enable = 'on';
            else
                app.scrollEnableFlag = false;
                app.Dim3EditField.Limits = [1,2];
                app.Dim3Slider.Limits = [1,2];
                app.Dim3EditField.Enable = 'off';
                app.Dim3Slider.Enable = 'off';
                app.Dim3Label.Enable = 'off';
                app.PhaseDim3EditField.Limits = [1,2];
                app.PhaseDim3Slider.Limits = [1,2];
                app.PhaseDim3EditField.Enable = 'off';
                app.PhaseDim3Slider.Enable = 'off';
                app.PhaseDim3Label.Enable = 'off';
                app.FlowDim3EditField.Limits = [1,2];
                app.FlowDim3Slider.Limits = [1,2];
                app.FlowDim3EditField.Enable = 'off';
                app.FlowDim3Slider.Enable = 'off';
                app.FlowDim3Label.Enable = 'off';
            end

            % Dimension 4 selection
            dim4size = size(im,4);
            app.Dim4EditField.Value = 1;
            app.Dim4Slider.Value = 1;
            app.PhaseDim4EditField.Value = 1;
            app.PhaseDim4Slider.Value = 1;
            app.FlowDim4EditField.Value = 1;
            app.FlowDim4Slider.Value = 1;
            if dim4size > 1
                app.Dim4EditField.Limits = [1,dim4size];
                app.Dim4Slider.Limits = [1,dim4size];
                app.Dim4EditField.Value = round(dim4size/2);
                app.Dim4Slider.Value = round(dim4size/2);
                app.Dim4EditField.Enable = 'on';
                app.Dim4Slider.Enable = 'on';
                app.Dim4Label.Enable = 'on';
                app.PhaseDim4EditField.Limits = [1,dim4size];
                app.PhaseDim4Slider.Limits = [1,dim4size];
                app.PhaseDim4EditField.Value = round(dim4size/2);
                app.PhaseDim4Slider.Value = round(dim4size/2);
                app.PhaseDim4EditField.Enable = 'on';
                app.PhaseDim4Slider.Enable = 'on';
                app.PhaseDim4Label.Enable = 'on';
                app.FlowDim4EditField.Limits = [1,dim4size];
                app.FlowDim4Slider.Limits = [1,dim4size];
                app.FlowDim4EditField.Value = round(dim4size/2);
                app.FlowDim4Slider.Value = round(dim4size/2);
                app.FlowDim4EditField.Enable = 'on';
                app.FlowDim4Slider.Enable = 'on';
                app.FlowDim4Label.Enable = 'on';
            else
                app.Dim4EditField.Limits = [1,2];
                app.Dim4Slider.Limits = [1,2];
                app.Dim4EditField.Enable = 'off';
                app.Dim4Slider.Enable = 'off';
                app.Dim4Label.Enable = 'off';
                app.PhaseDim4EditField.Limits = [1,2];
                app.PhaseDim4Slider.Limits = [1,2];
                app.PhaseDim4EditField.Enable = 'off';
                app.PhaseDim4Slider.Enable = 'off';
                app.PhaseDim4Label.Enable = 'off';
                app.FlowDim4EditField.Limits = [1,2];
                app.FlowDim4Slider.Limits = [1,2];
                app.FlowDim4EditField.Enable = 'off';
                app.FlowDim4Slider.Enable = 'off';
                app.FlowDim4Label.Enable = 'off';
            end

            % Dimension 5 selection
            dim5size = size(im,5);
            app.Dim5EditField.Value = 1;
            app.Dim5Slider.Value = 1;
            app.PhaseDim5EditField.Value = 1;
            app.PhaseDim5Slider.Value = 1;
            app.FlowDim5EditField.Value = 1;
            app.FlowDim5Slider.Value = 1;
            if dim5size > 1
                app.Dim5EditField.Limits = [1,dim5size];
                app.Dim5Slider.Limits = [1,dim5size];
                app.Dim5EditField.Enable = 'on';
                app.Dim5Slider.Enable = 'on';
                app.Dim5Label.Enable = 'on';
                app.PhaseDim5EditField.Limits = [1,dim5size];
                app.PhaseDim5Slider.Limits = [1,dim5size];
                app.PhaseDim5EditField.Enable = 'on';
                app.PhaseDim5Slider.Enable = 'on';
                app.PhaseDim5Label.Enable = 'on';
                app.FlowDim5EditField.Limits = [1,dim5size];
                app.FlowDim5Slider.Limits = [1,dim5size];
                app.FlowDim5EditField.Enable = 'on';
                app.FlowDim5Slider.Enable = 'on';
                app.FlowDim5Label.Enable = 'on';
            else
                app.Dim5EditField.Limits = [1,2];
                app.Dim5Slider.Limits = [1,2];
                app.Dim5EditField.Enable = 'off';
                app.Dim5Slider.Enable = 'off';
                app.Dim5Label.Enable = 'off';
                app.PhaseDim5EditField.Limits = [1,2];
                app.PhaseDim5Slider.Limits = [1,2];
                app.PhaseDim5EditField.Enable = 'off';
                app.PhaseDim5Slider.Enable = 'off';
                app.PhaseDim5Label.Enable = 'off';
                app.FlowDim5EditField.Limits = [1,2];
                app.FlowDim5Slider.Limits = [1,2];
                app.FlowDim5EditField.Enable = 'off';
                app.FlowDim5Slider.Enable = 'off';
                app.FlowDim5Label.Enable = 'off';
            end

            % Flip angle selection
            dim6size = size(im,6);
            app.Dim6EditField.Value = 1;
            app.Dim6Slider.Value = 1;
            app.PhaseDim6EditField.Value = 1;
            app.PhaseDim6Slider.Value = 1;
            app.FlowDim6EditField.Value = 1;
            app.FlowDim6Slider.Value = 1;
            if dim6size > 1
                app.Dim6EditField.Limits = [1,dim6size];
                app.Dim6Slider.Limits = [1,dim6size];
                app.Dim6EditField.Enable = 'on';
                app.Dim6Slider.Enable = 'on';
                app.Dim6Label.Enable = 'on';
                app.PhaseDim6EditField.Limits = [1,dim6size];
                app.PhaseDim6Slider.Limits = [1,dim6size];
                app.PhaseDim6EditField.Enable = 'on';
                app.PhaseDim6Slider.Enable = 'on';
                app.PhaseDim6Label.Enable = 'on';
                app.FlowDim6EditField.Limits = [1,dim6size];
                app.FlowDim6Slider.Limits = [1,dim6size];
                app.FlowDim6EditField.Enable = 'on';
                app.FlowDim6Slider.Enable = 'on';
                app.FlowDim6Label.Enable = 'on';

            else
                app.Dim6EditField.Limits = [1,2];
                app.Dim6Slider.Limits = [1,2];
                app.Dim6EditField.Enable = 'off';
                app.Dim6Slider.Enable = 'off';
                app.Dim6Label.Enable = 'off';
                app.PhaseDim6EditField.Limits = [1,2];
                app.PhaseDim6Slider.Limits = [1,2];
                app.PhaseDim6EditField.Enable = 'off';
                app.PhaseDim6Slider.Enable = 'off';
                app.PhaseDim6Label.Enable = 'off';
                app.FlowDim6EditField.Limits = [1,2];
                app.FlowDim6Slider.Limits = [1,2];
                app.FlowDim6EditField.Enable = 'off';
                app.FlowDim6Slider.Enable = 'off';
                app.FlowDim6Label.Enable = 'off';
            end

            % Label the orientations
            app.XLabel.Text = app.imageOrientLabel(app.OrientationSpinner.Value,1);
            app.YLabel.Text = app.imageOrientLabel(app.OrientationSpinner.Value,2);
            app.PhaseXLabel.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,1);
            app.PhaseYLabel.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,2);
            app.FlowXLabel.Text = app.imageOrientLabel(app.FlowOrientationSpinner.Value,1);
            app.FlowYLabel.Text = app.imageOrientLabel(app.FlowOrientationSpinner.Value,2);
            app.MIPXLabel.Text = app.XLabel.Text;
            app.MIPYLabel.Text = app.YLabel.Text;
            app.MIPxAngleLabel.Text = app.XLabel.Text;
            app.MIPyAngleLabel.Text = app.YLabel.Text;
            app.Dim3Label.Text = app.imageOrientLabel(app.OrientationSpinner.Value,3);
            app.Dim4Label.Text = app.imageOrientLabel(app.OrientationSpinner.Value,4);
            app.Dim5Label.Text = app.imageOrientLabel(app.OrientationSpinner.Value,5);
            app.Dim6Label.Text = app.imageOrientLabel(app.OrientationSpinner.Value,6);
            app.PhaseDim3Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,3);
            app.PhaseDim4Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,4);
            app.PhaseDim5Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,5);
            app.PhaseDim6Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,6);
            app.FlowDim3Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,3);
            app.FlowDim4Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,4);
            app.FlowDim5Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,5);
            app.FlowDim6Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,6);

            % Movie button
            if app.stopMovieFlag
                app.MoviePlayButton.Enable = 'on';
                app.MovieStopButton.Enable = 'off';
            else
                app.MoviePlayButton.Enable = 'off';
                app.MovieStopButton.Enable = 'on';
            end

            % Phase movie button
            if app.stopPhaseMovieFlag
                app.PhaseMovieStartButton.Enable = 'on';
                app.PhaseMovieStopButton.Enable = 'off';
            else
                app.PhaseMovieStartButton.Enable = 'off';
                app.PhaseMovieStopButton.Enable = 'on';
            end

            % Flow movie button
            if app.stopFlowMovieFlag
                app.FlowMovieStartButton.Enable = 'on';
                app.FlowMovieStopButton.Enable = 'off';
            else
                app.FlowMovieStartButton.Enable = 'off';
                app.FlowMovieStopButton.Enable = 'on';
            end

            % Other buttons
            app.XLabel.Enable = 'on';
            app.YLabel.Enable = 'on';
            app.MaskButton.Enable = 'on';
            app.ThresholdEditFieldLabel.Enable = 'on';
            app.ThresholdEditField.Enable = 'on';
            app.ThresholdSlider.Enable = 'on';
            app.ErodeButton.Enable = 'on';
            app.DilateButton.Enable = 'on';
            app.OpenButton.Enable = 'on';
            app.CloseButton.Enable = 'on';
            app.AutoIntensityCheckBox.Enable = 'on';
            app.AutoIntensityCheckBoxLabel.Enable = 'on';
            app.SetButton.Enable = 'on';
            app.WindowEditField.Enable = 'on';
            app.WindowSlider.Enable = 'on';
            app.WLabel.Enable = 'on';
            app.LevelEditField.Enable = 'on';
            app.LevelSlider.Enable = 'on';
            app.LLabel.Enable = 'on';
            app.OrientationSpinner.Enable = 'on';
            app.OrientationSpinnerLabel.Enable = 'on';

            app.MIPXLabel.Enable = 'on';
            app.MIPYLabel.Enable = 'on';
            app.MIPSetButton.Enable = 'on';
            app.MIPWindowEditField.Enable = 'on';
            app.MIPWindowSlider.Enable = 'on';
            app.MIPWLabel.Enable = 'on';
            app.MIPLevelEditField.Enable = 'on';
            app.MIPLevelSlider.Enable = 'on';
            app.MIPLLabel.Enable = 'on';
            app.MIPOrientationSpinner.Enable = 'on';
            app.MIPOrientationSpinnerLabel.Enable = 'on';
            app.MIPAngleResetButton.Enable = 'on';
            app.MIPResizeButton.Enable = 'on';
            app.MIPxAngleSlider.Enable = 'on';
            app.MIPyAngleSlider.Enable = 'on';
            app.MIPxAngleLabel.Enable = 'on';
            app.MIPyAngleLabel.Enable = 'on';
            app.MIPxPlayButton.Enable = 'on';
            app.MIPyPlayButton.Enable = 'on';
            app.MIPRotateLabel.Enable = 'on';

            app.PhaseXLabel.Enable = 'on';
            app.PhaseYLabel.Enable = 'on';
            app.PhaseUnwrapButton.Enable = 'on';
            app.PhaseOrientationSpinner.Enable = 'on';
            app.PhaseOrientationSpinnerLabel.Enable = 'on';
            app.PhaseMinLabel.Enable = 'on';
            app.PhaseMaxLabel.Enable = 'on';

            app.FlowXLabel.Enable = 'on';
            app.FlowYLabel.Enable = 'on';
            app.FlowDirectionSpinner.Enable = 'on';
            app.FlowDirectionSpinnerLabel.Enable = 'on';
            app.FlowOrientationSpinner.Enable = 'on';
            app.FlowOrientationSpinnerLabel.Enable = 'on';
            app.FlowMinLabel.Enable = 'on';
            app.FlowMaxLabel.Enable = 'on';

            % Button status memory
            app.imageButtonStatus = {app.MaskButton.Enable,app.ThresholdSlider.Enable,app.ThresholdEditField.Enable,app.ErodeButton.Enable, ...
                app.DilateButton.Enable,app.CloseButton.Enable,app.OpenButton.Enable,app.AutoIntensityCheckBox.Enable,app.SetButton.Enable, ...
                app.WindowSlider.Enable,app.LevelSlider.Enable,app.OrientationSpinner.Enable,app.Dim6Slider.Enable,app.Dim5Slider.Enable, ...
                app.Dim4Slider.Enable,app.Dim3Slider.Enable,app.Dim6EditField.Enable,app.Dim5EditField.Enable,app.Dim4EditField.Enable, ...
                app.Dim3EditField.Enable,app.XLabel.Enable,app.YLabel.Enable,app.MoviePlayButton.Enable,app.MovieStopButton.Enable, ...
                app.ThresholdEditFieldLabel.Enable,app.WindowEditField.Enable,app.LevelEditField.Enable,app.OrientationSpinnerLabel.Enable, ...
                app.Dim6Label.Enable,app.Dim5Label.Enable,app.Dim4Label.Enable,app.Dim3Label.Enable,app.AutoIntensityCheckBoxLabel.Enable, ...
                app.LLabel.Enable,app.WLabel.Enable,app.MIPXLabel.Enable,app.MIPYLabel.Enable,app.MIPWindowEditField.Enable,app.MIPLevelEditField.Enable, ...
                app.MIPSetButton.Enable,app.MIPWindowSlider.Enable,app.MIPLevelSlider.Enable,app.MIPWLabel.Enable,app.MIPLLabel.Enable,app.MIPOrientationSpinner.Enable, ...
                app.MIPOrientationSpinnerLabel.Enable,app.MIPResizeButton.Enable,app.MIPAngleResetButton.Enable,app.MIPxAngleSlider.Enable,app.MIPyAngleSlider.Enable, ...
                app.MIPxAngleLabel.Enable,app.MIPyAngleLabel.Enable,app.MIPxPlayButton.Enable,app.MIPyPlayButton.Enable,app.MIPRotateLabel.Enable, ...
                app.PhaseYLabel.Enable,app.PhaseXLabel.Enable,app.PhaseUnwrapButton.Enable,app.PhaseOrientationSpinner.Enable,app.PhaseCorrectCheckBoxLabel.Enable, ...
                app.PhaseMinLabel.Enable, app.PhaseMaxLabel.Enable,app.PhaseMovieStartButton.Enable,app.PhaseMovieStopButton.Enable,app.PhaseDim3Label.Enable, ...
                app.PhaseDim3EditField.Enable,app.PhaseDim3Slider.Enable,app.PhaseDim4Label.Enable,app.PhaseDim4EditField.Enable,app.PhaseDim4Slider.Enable, ...
                app.PhaseDim5Label.Enable,app.PhaseDim5EditField.Enable,app.PhaseDim5Slider.Enable,app.PhaseDim6Label.Enable,app.PhaseDim6EditField.Enable, ...
                app.PhaseDim6Slider.Enable,app.FlowXLabel.Enable,app.FlowYLabel.Enable,app.FlowCalcButton.Enable,app.FlowLoadVencTableButton.Enable, ...
                app.FlowDirectionSpinnerLabel.Enable,app.FlowDirectionSpinner.Enable,app.FlowOrientationSpinner.Enable,app.FlowOrientationSpinnerLabel.Enable, ...
                app.FlowMinLabel.Enable,app.FlowMaxLabel.Enable,app.FlowMovieStartButton.Enable,app.FlowMovieStopButton.Enable,app.FlowDim3Label.Enable, ...
                app.FlowDim3EditField.Enable,app.FlowDim3Slider.Enable,app.FlowDim4Label.Enable,app.FlowDim4EditField.Enable,app.FlowDim4Slider.Enable,app.FlowDim5Label.Enable, ...
                app.FlowDim5EditField.Enable,app.FlowDim5Slider.Enable,app.FlowDim6Label.Enable,app.FlowDim6EditField.Enable,app.FlowDim6Slider.Enable, ...
                app.PhaseOrientationSpinnerLabel.Enable};

        end % SetImageLimitsFcn


        % Sort and fill k-space
        function FillKspaceFcn(app)

            % Indicate busy
            app.TextMessage('Filling k-space ...',1);
            app.SetStatus(3);

            try

                if app.pd.validFile_flag

                    app.pd.nsaSpace = app.pd.NO_AVERAGES * ones(size(app.pd.rawKspace{1}));
                    app.pd.fillingSpace = ones(size(app.pd.rawKspace{1}));
                    for coil = 1:app.pd.maxCoils
                        app.pd.sensitivityMaps{coil} = zeros(size(app.pd.rawKspace{1}));
                    end
                    app.pd.validTrajectory_flag = false;

                    switch app.pd.trajType

                        case "zigzag" 

                            [app.pd,zigzagTraject] = app.pd.zigzagTrajectory(app.pd.NO_VIEWS);
                            app.pd.gp_var_mul = zigzagTraject - floor(app.pd.NO_VIEWS/2) - 1;
                      
                            % Sort
                            switch app.pd.dataType
                                case "2D"
                                    app.pd = app.pd.sortScanner2DKspaceMRD(app,zigzagTraject);
                                case "3D"
                                    app.pd = app.pd.sortScanner3DKspaceMRD(app,zigzagTraject);
                            end

                        case "rtable"

                            scannerKspaceTable = load(app.rtableFile)+1;
                     
                            % Sort
                            switch app.pd.dataType
                                case "2D"
                                    app.pd = app.pd.sortScanner2DKspaceMRD(app,scannerKspaceTable);
                                case "3D"
                                    app.pd = app.pd.sortScanner3DKspaceMRD(app,scannerKspaceTable);
                            end

                        case "mul_kspace"

                            minky = min(app.pd.gp_var_mul);
                            maxky = max(app.pd.gp_var_mul);
                            
                            if abs(minky)>abs(maxky)
                                app.pd.NO_VIEWS = 2*abs(minky);
                            else
                                app.pd.NO_VIEWS = 2*abs(maxky+1);
                            end

                            app.YEditField.Value = app.pd.NO_VIEWS;
                            app.KMatrixViewField2.Value = app.pd.NO_VIEWS;
                            app.pd.validTrajectory_flag = true;

                            % Sort
                            switch app.pd.dataType
                                case "2D"
                                    app.pd = app.pd.sort2DKspaceMRD(app);
                                case "3D"
                                    app.pd = app.pd.sort3DKspaceMRD(app);
                            end

                        case "P2ROUD"

                            app.pd.proudArray = reshape(app.pd.gp_var_proud,[2,length(app.pd.gp_var_proud)/2]);

                            minky = min(app.pd.proudArray(1,:));
                            maxky = max(app.pd.proudArray(1,:));

                            if abs(minky)>abs(maxky)
                                app.pd.NO_VIEWS = 2*abs(minky);
                            else
                                app.pd.NO_VIEWS = 2*abs(maxky+1);
                            end

                            minkz = min(app.pd.proudArray(2,:));
                            maxkz = max(app.pd.proudArray(2,:));

                            if abs(minkz)>abs(maxkz)
                                app.pd.NO_VIEWS_2 = 2*abs(minkz);
                            else
                                app.pd.NO_VIEWS_2 = 2*abs(maxkz+1);
                            end

                            app.YEditField.Value = app.pd.NO_VIEWS;
                            app.ZEditField.Value = app.pd.NO_VIEWS_2;
                            app.KMatrixViewField2.Value = app.pd.NO_VIEWS;
                            app.KMatrixViewField3.Value = app.pd.NO_VIEWS_2;
                            app.pd.validTrajectory_flag = true;

                            % Sort
                            app.pd = app.pd.sort3DProudKspaceMRD(app);

                        case "NRLUT"

                            app.pd.lutArray = app.pd.gp_var_lut(3:end);
                            app.pd.lutArray = reshape(app.pd.lutArray,[2,length(app.pd.lutArray)/2]);
                      
                            app.YEditField.Value = app.pd.NO_VIEWS;
                            app.KMatrixViewField2.Value = app.pd.NO_VIEWS;
                            app.pd.validTrajectory_flag = true;

                            % Sort
                            switch app.pd.dataType
                                case "2D"
                                    app.pd = app.pd.sort2DNonRegLUTKspaceMRD(app);
                                case "3D"
                                    app.ZEditField.Value = app.pd.NO_VIEWS_2;
                                    app.KMatrixViewField3.Value = app.pd.NO_VIEWS_2;
                                    app.pd = app.pd.sort3DNonRegLUTKspaceMRD(app);
                            end

                        case "3Dute"

                            app.pd.validTrajectory_flag = true;
                            
                            % XXXXX should be placed 
                            % Load the LUT
                            flist = dir(fullfile(app.mrdImportPath,'lut*.txt'));
                            try    
                                app.pd.seqTrajectory = load([flist(1).folder,filesep,flist(1).name]);
                                app.TextMessage(strcat("3D UTE trajectory: ",flist(1).name," ..."),1);
                                app.pd.seqTrajectory = reshape(app.pd.seqTrajectory,[3,length(app.pd.seqTrajectory)/3]);
                                app.pd.seqTrajectory = app.pd.seqTrajectory(:,1:size(app.pd.rawKspace{1},2));           
                            catch ME
                                app.TextMessage(ME.message);
                                app.TextMessage('ERROR: Trajectory LUT file not found or invalid file ...',1);
                                app.SetStatus(2);
                                app.pd.validTrajectory_flag = false;
                            end

                            % Load the gradient calibration file
                            try
                                app.pd.gradTrajectory = load('ktraj.txt');
                                app.TextMessage('Gradient calibration file: ktraj.txt ...',1);
                            catch ME
                                app.TextMessage(ME.message);
                                app.TextMessage('ERROR: ktraj.txt file not found ...',1);
                                app.SetStatus(2);
                                app.pd.validTrajectory_flag = false;
                            end

                            app.pd.NO_SAMPLES = length(app.pd.gradTrajectory);
                            app.pd.NO_VIEWS = app.pd.NO_SAMPLES;
                            app.XEditField.Value = app.pd.NO_SAMPLES;
                            app.YEditField.Value = app.pd.NO_SAMPLES;
                            app.ZEditField.Value = app.pd.NO_SAMPLES;

                        case "ZTE"

                            % do nothing

                    end

                    % Segmented acquisition
                    if app.pd.segmentedData_flag
                        switch app.pd.dataType
                            case "2D"
                                app.pd = app.pd.sort2DsegmKspaceMRD(app);
                            case "3D"
                                app.pd = app.pd.sort3DsegmKspaceMRD(app);
                        end
                    end

                    % Chop of the navigator if present
                    switch app.pd.trajType
                        case {"3Dute","2Dradial","2Dradialms"}
                            % do nothing
                        case "ZTE"
                            app.XEditField.Value = app.pd.NO_SAMPLES;
                            app.YEditField.Value = app.pd.NO_SAMPLES;
                            app.ZEditField.Value = app.pd.NO_SAMPLES;
                            app.KMatrixViewField1.Value = app.pd.NO_SAMPLES;
                        otherwise
                            app.pd = app.pd.chopNav(app);
                            app.XEditField.Value = app.pd.NO_SAMPLES;
                            app.KMatrixViewField1.Value = app.pd.NO_SAMPLES;
                    end

                end

                % K-space filling percentage, without cropping, dynamic interpolation, or zero-filling
                app.FillingViewField.Value = round(100*nnz(app.pd.fillingSpace)/numel(app.pd.fillingSpace));

                % Apply Tukey k-space filter
                app.pd = app.pd.applyTukey(app);

                % Scale k-space to convenient range
                app.pd = app.pd.scaleKspace;

                % Window and level, plot
                app.SetKspaceWLFcn;
                app.KWindowEditFieldValueChanged;
                app.KLevelEditFieldValueChanged;

                % Status
                app.SetStatus(0);

            catch ME

                app.TextMessage('ERROR: something went wrong filling k-space ...',1);
                app.TextMessage(ME.message,2);
                app.SetStatus(2);

            end

        end % FillKspaceFcn


        % --------------------------------------------------------------
        %
        %                           FIGURES
        %
        % --------------------------------------------------------------


        % Calculate the k-space image aspect ratio
        function result = CalcKAspectRatioFcn(app,dim1Label,dim2Label,sizeX,sizeY)

            switch app.pd.dataType

                case {"3Dute","ZTE"}

                    result = sizeY/sizeX;

                otherwise

                    L1 = 1;
                    L2 = 1;

                    if strcmp(dim1Label,'Kx') L1 = app.FOVViewField1.Value; end
                    if strcmp(dim1Label,'Ky') L1 = app.FOVViewField2.Value; end
                    if strcmp(dim1Label,'Kz') L1 = app.FOVViewField3.Value; end
                    if strcmp(dim1Label,'NR') L1 = min([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
                    if strcmp(dim1Label,'slice') L1 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

                    if strcmp(dim2Label,'Kx') L2 = app.FOVViewField1.Value; end
                    if strcmp(dim2Label,'Ky') L2 = app.FOVViewField2.Value; end
                    if strcmp(dim2Label,'Kz') L2 = app.FOVViewField3.Value; end
                    if strcmp(dim2Label,'NR') L2 = min([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
                    if strcmp(dim2Label,'slice') L2 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

                    result = (L2/L1);

            end

        end % CalcKAspectRatioFnc


        % Calculate the image aspect ratio
        function result = CalcImageAspectRatioFcn(app, dim1Label, dim2Label, sizeX, sizeY)

            L1 = 1;
            L2 = 1;

            if (strcmp(dim1Label,'X') || strcmp(dim1Label,'Kx')) L1 = app.FOVViewField1.Value; end
            if (strcmp(dim1Label,'Y') || strcmp(dim1Label,'Ky')) L1 = app.FOVViewField2.Value; end
            if (strcmp(dim1Label,'Z') || strcmp(dim1Label,'Kz')) L1 = app.FOVViewField3.Value; end
            if strcmp(dim1Label,'NR') L1 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim1Label,'slice') L1 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

            if (strcmp(dim2Label,'X') || strcmp(dim2Label,'Kx')) L2 = app.FOVViewField1.Value; end
            if (strcmp(dim2Label,'Y') || strcmp(dim2Label,'Ky')) L2 = app.FOVViewField2.Value; end
            if (strcmp(dim2Label,'Z') || strcmp(dim2Label,'Kz')) L2 = app.FOVViewField3.Value; end
            if strcmp(dim2Label,'NR') L2 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim2Label,'slice') L2 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end
            
            result = (L1/L2)*(sizeY/sizeX);

        end % CalcImageAspectRatioFnc


        % Calculate the MIP image aspect ratio
        function result = CalcMIPAspectRatioFcn(app,dim1Label,dim2Label,sizeX,sizeY,alphaX,alphaY)

            if strcmp(dim1Label,'X') L1 = app.FOVViewField1.Value; end
            if strcmp(dim1Label,'Y') L1 = app.FOVViewField2.Value; end
            if strcmp(dim1Label,'Z') L1 = app.FOVViewField3.Value; end
            if strcmp(dim1Label,'NR') L1 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim1Label,'slice') L1 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

            if strcmp(dim2Label,'X') L2 = app.FOVViewField1.Value; end
            if strcmp(dim2Label,'Y') L2 = app.FOVViewField2.Value; end
            if strcmp(dim2Label,'Z') L2 = app.FOVViewField3.Value; end
            if strcmp(dim2Label,'NR') L2 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim2Label,'slice') L2 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

            if strcmp(app.Dim3Label.Text,'X') L3 = app.FOVViewField1.Value; end
            if strcmp(app.Dim3Label.Text,'Y') L3 = app.FOVViewField2.Value; end
            if strcmp(app.Dim3Label.Text,'Z') L3 = app.FOVViewField3.Value; end
            if strcmp(app.Dim3Label.Text,'NR') L3 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(app.Dim3Label.Text,'slice') L3 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

            fovx = sqrt((L1 * cos(pi*alphaY/180))^2 + (L3 * sin(pi*alphaY/180))^2);
            fovy = sqrt((L2 * cos(pi*alphaX/180))^2 + (L3 * sin(pi*alphaX/180))^2);
            
            result = (fovy/fovx)*(sizeY/sizeX);

        end % CalcMIPAspectRatioFnc


        % Set the image window and level
        function SetImageWLFcn(app)

            if app.pd.validReco_flag

                % Select the data
                im = app.pd.images;

                im = permute(im,app.imageOrient(app.OrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.Dim3EditField.Value,app.Dim4EditField.Value,app.Dim5EditField.Value,app.Dim6EditField.Value));

                maxScale = double(round(max(im(:))));
                threshold = double(graythresh(mat2gray(im)))*maxScale;
                imt = im;
                imt(imt < threshold) = 0;
                scale = mean(nonzeros(imt(:)));
                scale(scale > maxScale) = maxScale;
                scale(isnan(scale)) = maxScale;
                wScale = 2*scale;
                wScale(wScale > maxScale) = maxScale;
   
                app.LevelSlider.Value = double(scale);
                app.LevelEditField.Value = double(round(scale));   
                app.WindowSlider.Value = double(wScale);
                app.WindowEditField.Value = double(round(wScale));

            end

        end % SetImageWL


        % Set the image window and level
        function SetMIPWLFcn(app)

            if app.pd.validReco_flag

                % Select the data
                im = app.pd.images;
                im = permute(im,app.imageOrient(app.OrientationSpinner.Value,:));
                im = squeeze(im(:,:,:,app.Dim4EditField.Value,app.Dim5EditField.Value,app.Dim6EditField.Value));
                im = max(im,[],3);

                scale = double(max(im(:)));

                app.MIPLevelSlider.Value = scale/3;
                app.MIPLevelEditField.Value = round(scale/3);
                app.MIPWindowSlider.Value = scale/1.5;
                app.MIPWindowEditField.Value = round(scale/1.5);

            end

        end % SetMIPWL


        % Set the k-space image window and level
        function SetKspaceWLFcn(app)

            if app.pd.validFile_flag

                % Select the data
                if app.senseMapButton.Value == 1
                    im = abs(app.pd.sensitivityMaps{app.CoilSpinner.Value});
                else
                    im = abs(app.pd.rawKspace{app.CoilSpinner.Value});
                end

                % Select which slice to show
                im = permute(im,app.imageOrient(app.KOrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.KDim3EditField.Value,app.KDim4EditField.Value,app.KFlipAngleEditField.Value,app.KEchoEditField.Value));

                maxim = max(im(:));
                if maxim == 0
                    window = app.pd.maxKspace;
                    level = 0.5*app.pd.maxKspace;
                elseif app.senseMapButton.Value == 1
                    window = double(round(1*app.pd.maxKspace*maxim));
                    level = double(round(0.75*app.pd.maxKspace*maxim));
                else
                    window = double(round(0.8*maxim));
                    level = double(round(0.4*window));
                end

                app.KWindowSlider.Value = window;
                app.KWindowEditField.Value = window;
                app.KLevelSlider.Value = level;
                app.KLevelEditField.Value = level;
              
            end

        end % SetKspaceWL


        % Plot k-space
        function PlotKspaceFigFcn(app)

            try

                if app.pd.validFile_flag

                    cla(app.KspaceFig);
                    delete(app.cbar);

                    % Select the data
                    if app.fillingButton.Value == 1

                        im = app.pd.fillingSpace;
                        cmap = [0 0 0 ; summer(1)];
                        minScale = 0;
                        maxScale = max(im(:));

                    end

                    if app.averagesButton.Value == 1

                        im = app.pd.nsaSpace;
                        cmap = [0 0 0 ; summer(max(im(:)))];
                        minScale = 0;
                        maxScale = max(im(:));
                        app.cbar = colorbar(app.KspaceFig,'location','eastoutside','TickLabelsMode','auto','FontSize',12);

                    end

                    if app.kValuesButton.Value == 1

                        % Plot the k-space
                        im = abs(app.pd.rawKspace{app.CoilSpinner.Value});
                        cmap = gray;

                        % Window and Level
                        lvl = app.KLevelSlider.Value;
                        wdw = app.KWindowSlider.Value;
                        im = (1/lvl)*(im - lvl + wdw/2);
                        im(im<0) = 0;
                        im(im>1) = 1;
                        minScale = 0;
                        maxScale = 1;

                    end

                    if app.senseMapButton.Value == 1
                 
                        % Plot the sense map
                        im = app.pd.maxKspace*abs(app.pd.sensitivityMaps{app.CoilSpinner.Value});
                        cmap = gray;

                        % Window and Level
                        lvl = app.KLevelSlider.Value;
                        wdw = app.KWindowSlider.Value;
                        im = (1/lvl)*(im - lvl + wdw/2);
                        im(im<0) = 0;
                        im(im>1) = 1;
                        minScale = 0;
                        maxScale = 1;

                    end

                    % Plot the image in the image panel
                    app.KspaceFig.Position = [34,87,325,325];

                    % Select which slice to show
                    im = permute(im,app.imageOrient(app.KOrientationSpinner.Value,:));
                    im = squeeze(im(:,:,app.KDim3EditField.Value,app.KDim4EditField.Value,app.KFlipAngleEditField.Value,app.KEchoEditField.Value));

                    if app.senseMapButton.Value == 1
                        % Rotate the image if phase-orientation equals 1
                        recoAspectRatio = app.CalcImageAspectRatioFcn(app.KxLabel.Text,app.KyLabel.Text,size(im,1),size(im,2));
                        if strcmp(app.KxLabel.Text,'X') && strcmp(app.KyLabel.Text,'Y') && app.pd.PHASE_ORIENTATION
                            im = rot90(im,-1);
                            recoAspectRatio = app.CalcImageAspectRatioFcn(app.KyLabel.Text,app.KxLabel.Text,size(im,1),size(im,2));
                        end
                    else
                        recoAspectRatio = app.CalcKAspectRatioFcn(app.KxLabel.Text,app.KyLabel.Text,size(im,1),size(im,2));
                    end

                    % Plot the image in the k-space images panel
                    imshow(rot90(im),[minScale maxScale],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.KspaceFig);

                    % Image size
                    daspect(app.KspaceFig,[1 recoAspectRatio 1]);
                    xlim(app.KspaceFig,[0, size(im,1) + 1]);
                    ylim(app.KspaceFig,[0, size(im,2) + 1]);

                    % Plot the 8 small sensitivity map figs
                    app.PlotSenseMapsFcn;

                    drawnow;

                end

            catch ME

                app.TextMessage(ME.message,2);
                app.TextMessage("Something has gone wrong during plotting in k-space figure panel ...",1);

            end

        end % PlotKspaceFig


        % Plot the 8 small sense maps in the coils panel
        function PlotSenseMapsFcn(app)

            try

                % Image colormap
                cmap = gray;

                % Make coil images
                im = zeros([app.pd.maxCoils,size(app.pd.sensitivityMaps{1})]);
                for coil = 1:app.pd.maxCoils
                    im(coil,:,:,:,:,:,:) = abs(app.pd.sensitivityMaps{coil});
                end

                im = permute(im,[1 1+app.imageOrient(app.KOrientationSpinner.Value,:)]);
                im = im(:,:,:,app.KDim3EditField.Value,app.KDim4EditField.Value,app.KFlipAngleEditField.Value,app.KEchoEditField.Value);

                % Rotate for phase_orientation equals 1
                for coil = 1:app.pd.maxCoils
                    if ((strcmp(app.KxLabel.Text,'X') && strcmp(app.KyLabel.Text,'Y')) || ...
                            (strcmp(app.KxLabel.Text,'Kx') && strcmp(app.KyLabel.Text,'Ky'))) ...
                            && app.pd.PHASE_ORIENTATION
                        im0 = permute(im,[2,3,1]);
                        ims{coil} = rot90(im0(:,:,coil),-1); %#ok<AGROW>
                    else
                        im0 = permute(im,[2,3,1]);
                        ims{coil} = im0(:,:,coil); %#ok<AGROW>
                    end
                end

                % AspectRatio
                recoAspectRatio = app.CalcImageAspectRatioFcn(app.KxLabel.Text,app.KyLabel.Text,size(ims{1},1),size(ims{1},2));
                if strcmp(app.KxLabel.Text,'X') && strcmp(app.KyLabel.Text,'Y') && app.pd.PHASE_ORIENTATION
                    recoAspectRatio = app.CalcImageAspectRatioFcn(app.KyLabel.Text,app.KxLabel.Text,size(ims{1},1),size(ims{1},2));
                end

                for coil = 1:app.pd.nrCoils
                    mx(coil) = max(ims{coil}(:)); %#ok<AGROW>
                end
                for coil = 1:app.pd.nrCoils
                    ims{coil} = ims{coil}/max(mx(:));
                end

                % X and Y sizes
                sizeXY = [0 size(ims{1},1)+1 0 size(ims{1},2)+1];

                % Put them in the figures
                cla(app.SenseFig1);
                imshow(rot90(ims{1}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig1);
                daspect(app.SenseFig1,[1 recoAspectRatio 1]);
                axis(app.SenseFig1,sizeXY);

                cla(app.SenseFig2);
                imshow(rot90(ims{2}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig2);
                daspect(app.SenseFig2,[1 recoAspectRatio 1]);
                axis(app.SenseFig2,sizeXY);

                cla(app.SenseFig3);
                imshow(rot90(ims{3}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig3);
                daspect(app.SenseFig3,[1 recoAspectRatio 1]);
                axis(app.SenseFig3,sizeXY);

                cla(app.SenseFig4);
                imshow(rot90(ims{4}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig4);
                daspect(app.SenseFig4,[1 recoAspectRatio 1]);
                axis(app.SenseFig4,sizeXY);

                cla(app.SenseFig5);
                imshow(rot90(ims{5}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig5);
                daspect(app.SenseFig5,[1 recoAspectRatio 1]);
                axis(app.SenseFig5,sizeXY);

                cla(app.SenseFig6);
                imshow(rot90(ims{6}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig6);
                daspect(app.SenseFig6,[1 recoAspectRatio 1]);
                axis(app.SenseFig6,sizeXY);

                cla(app.SenseFig7);
                imshow(rot90(ims{7}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig7);
                daspect(app.SenseFig7,[1 recoAspectRatio 1]);
                axis(app.SenseFig7,sizeXY);

                cla(app.SenseFig8);
                imshow(rot90(ims{8}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig8);
                daspect(app.SenseFig8,[1 recoAspectRatio 1]);
                axis(app.SenseFig8,sizeXY);

                drawnow;

            catch ME

                app.TextMessage(ME.message,2);
                app.TextMessage("Something has gone wrong during plotting of sensitivity maps ...",1);

            end
            
        end % PlotSenseMapsFnc


        % Plot the reconstructed image
        function PlotImageFigFcn(app)

            try

                if app.pd.validReco_flag

                    % Red color map
                    cmap = [linspace(0,1,256)', linspace(0,1,256)', linspace(0,1,256)'];
                    if app.pd.validMask_flag
                        cmap(1,:) = [0.7 0 0 ]; % reddish
                    end

                    % Select the data
                    try
                        im = app.pd.images.*app.pd.mask;
                    catch
                        im = app.pd.images;
                    end

                    % Select which slice to show
                    im = permute(im,app.imageOrient(app.OrientationSpinner.Value,:));
                    im = squeeze(im(:,:,app.Dim3EditField.Value,app.Dim4EditField.Value,app.Dim5EditField.Value,app.Dim6EditField.Value));

                    % Image scale
                    if app.AutoIntensityCheckBox.Value == 1
                        maxScale = double(round(max(im(:))));
                        threshold = double(graythresh(mat2gray(im)))*maxScale;
                        imt = im;
                        imt(imt < threshold) = 0;
                        scale = mean(nonzeros(imt(:)));
                        scale(scale > maxScale) = maxScale;
                        scale(isnan(scale)) = maxScale;
                        wScale = 2*scale;
                        wScale(wScale > maxScale) = maxScale;
                        app.LevelSlider.Value = double(scale);
                        app.LevelEditField.Value = double(round(scale));
                        app.WindowSlider.Value = double(wScale);
                        app.WindowEditField.Value = double(round(wScale));
                    end
                    minScale = app.LevelEditField.Value - app.WindowEditField.Value/2;
                    maxScale = app.LevelEditField.Value + app.WindowEditField.Value/2;
                    if minScale >= maxScale
                        maxScale = minScale + 1;
                    end

                    % Image aspect ratio & dimensions
                    recoAspectRatio = app.CalcImageAspectRatioFcn(app.XLabel.Text,app.YLabel.Text,size(im,1),size(im,2));

                    % If phase orientation = 1 rotate image (for viewing purposes only)
                    if strcmp(app.XLabel.Text,'X') && strcmp(app.YLabel.Text,'Y') && app.pd.PHASE_ORIENTATION
                        im = rot90(im,-1);
                        recoAspectRatio = app.CalcImageAspectRatioFcn(app.YLabel.Text,app.XLabel.Text,size(im,1),size(im,2));
                    end

                    % Plot the image in the images panel
                    if app.newFigFlag

                        % Plot the image in the image panel
                        cla(app.RecoFig);
                        app.RecoFig.Position = [43,87,325,325];

                        % New fig with changed properties
                        app.recoImage = imshow(rot90(im),[minScale maxScale],'Colormap',cmap,'Interpolation','bilinear','InitialMagnification','fit','Parent',app.RecoFig);

                        % Aspect ratio and dimensions
                        daspect(app.RecoFig,[1 recoAspectRatio 1]);
                        xlim(app.RecoFig,[0, size(im,1) + 1]);
                        ylim(app.RecoFig,[0, size(im,2) + 1]);

                        % Calculate the left, right, top, bottom, image orienation labels
                        app.pd = app.pd.imageOrientLabels(app);

                        % Plot the labels
                        text(app.RecoFig,0.960*size(im,1),0.520*size(im,2),app.pd.rightLabel,'Color',app.yellow,'FontSize',14);
                        text(app.RecoFig,0.015*size(im,1),0.520*size(im,2),app.pd.leftLabel,'Color',app.yellow,'FontSize',14);
                        text(app.RecoFig,0.490*size(im,1),0.970*size(im,2),app.pd.bottomLabel,'Color',app.yellow,'FontSize',14);
                        text(app.RecoFig,0.490*size(im,1),0.030*size(im,2),app.pd.topLabel,'Color',app.yellow,'FontSize',14);

                        % Set the new figure flag to false
                        app.newFigFlag = false;

                    else

                        % Only new data in the figure = fast
                        set(app.recoImage, 'CData', rot90(im));
                        set(app.recoImage, 'CDataMapping', 'scaled');

                        app.RecoFig.CLim = [minScale maxScale];
                        pause(0.002);

                    end

                    drawnow;

                end

            catch ME

                app.TextMessage(ME.message,2);
                app.TextMessage("Something has gone wrong during plotting of reconstructed images ...",1);

            end

        end % PlotImageFigFcn(app)


        % Plot the MIP
        function PlotMipFigFcn(app)

             if app.pd.validReco_flag

                % Clear the current figure
                cla(app.MIPFig);
                  
                % Select the data
                im = app.pd.images.*app.pd.mask;

                % Select which volume to show in MIP window
                im = permute(im,app.imageOrient(app.OrientationSpinner.Value,:));
                im = squeeze(im(:,:,:,app.Dim4EditField.Value,app.Dim5EditField.Value,app.Dim6EditField.Value));

                if ndims(im) == 3

                    % Downsample if selected
                    if app.MIPResizeButton.Value
                        im = imresize3(im,size(im)/2);
                    end

                    % Resize image for correct image dimensions
                    maxDim = 1.2*max(size(im));
                    imDim = app.pd.pixelSize(app.imageOrient(app.OrientationSpinner.Value,:));
                    imDim = imDim/min(imDim(1:3));
                    im = imresize3(im,[size(im,1)*imDim(1) size(im,2)*imDim(2) size(im,3)*imDim(3)]);

                    % Image aspect ratio & dimensions
                    recoAspectRatio = app.CalcMIPAspectRatioFcn(app.XLabel.Text,app.YLabel.Text,size(im,2),size(im,1),0,0);

                    % If phase orientation = 1 rotate image (for viewing purposes only)
                    if strcmp(app.XLabel.Text,'X') && strcmp(app.YLabel.Text,'Y') && app.pd.PHASE_ORIENTATION
                        recoAspectRatio = app.CalcMIPAspectRatioFcn(app.YLabel.Text,app.XLabel.Text,size(im,1),size(im,2),0,0);
                    end

                    % Phase orientation
                    if strcmp(app.XLabel.Text,'X') && strcmp(app.YLabel.Text,'Y') && app.pd.PHASE_ORIENTATION
                        im = rot90(im,-1);
                    end

                    % Rotate
                    im = imrotate3(im,app.MIPxAngleSlider.Value,[0 1 0]);
                    im = imrotate3(im,app.MIPyAngleSlider.Value,[1 0 0]);
                    
                    % MIP of 3rd dimension
                    im = max(im,[],3);

                    % Image scale
                    minScale = app.MIPLevelEditField.Value - app.MIPWindowEditField.Value/2;
                    maxScale = app.MIPLevelEditField.Value + app.MIPWindowEditField.Value/2;
                    if minScale >= maxScale
                        maxScale = minScale + 1;
                    end

                    % Plot the image in the image panel
                    app.MIPFig.Position = [43,87,325,325];
                    
                    % New fig with changed properties
                    imshow(app.blackImage,'XData',[-2*maxDim 2*maxDim],'YData',[-2*maxDim 2*maxDim],'Parent',app.MIPFig);
                    hold(app.MIPFig,'on');
                    imshow(rot90(im),[minScale maxScale],'Parent',app.MIPFig);
                    daspect(app.MIPFig,[1 recoAspectRatio 1]);
                    xlim(app.MIPFig,[(size(im,1) - maxDim)/2, (maxDim + size(im,1))/2]);
                    ylim(app.MIPFig,[(size(im,2) - maxDim)/2, (maxDim + size(im,2))/2]);
                    hold(app.MIPFig,'off');
                 
                else

                    % No image
                    emptyImage = ones(10,10);
                    imshow(emptyImage,[0 1.2],'Parent',app.MIPFig); 
                    xlim(app.MIPFig,[0, size(emptyImage,1) + 1]);
                    ylim(app.MIPFig,[0, size(emptyImage,2) + 1]);

                end

            end

        end % PlotMipFig


        % Plot the reconstructed phase image
        function PlotPhaseImageFigFcn(app)

            if app.pd.validReco_flag
        
                % Select the data
                im = app.pd.phaseImages.*app.pd.mask;

                % Select which slice to show
                im = permute(im,app.imageOrient(app.PhaseOrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.PhaseDim3EditField.Value,app.PhaseDim4EditField.Value,app.PhaseDim5EditField.Value,app.PhaseDim6EditField.Value));
             
                % Image aspect ratio & dimensions
                recoAspectRatio = app.CalcImageAspectRatioFcn(app.PhaseXLabel.Text,app.PhaseYLabel.Text,size(im,1),size(im,2));

                % Scale
                scale = floor(max(abs(im(:)))/pi)+1;
                
                % If phase orientation = 1 rotate image (for viewing purposes only)
                if strcmp(app.PhaseXLabel.Text,'X') && strcmp(app.PhaseYLabel.Text,'Y') && app.pd.PHASE_ORIENTATION
                    im = rot90(im,-1);
                    recoAspectRatio = app.CalcImageAspectRatioFcn(app.PhaseYLabel.Text,app.PhaseXLabel.Text,size(im,1),size(im,2));
                end

                % Plot the image in the images panel
                if app.newPhaseFigFlag

                    % Plot the image in the image panel
                    cla(app.PhaseRecoFig);
                    app.PhaseRecoFig.Position = [43,87,325,325];

                    % New fig with changed properties
                    app.phaseRecoImage = imshow(rot90(im),[-pi*scale-0.001 pi*scale],'Interpolation','bilinear','InitialMagnification','fit','Parent',app.PhaseRecoFig);

                    % Aspect ratio and dimensions
                    daspect(app.PhaseRecoFig,[1 recoAspectRatio 1]);
                    xlim(app.PhaseRecoFig,[0, size(im,1) + 1]);
                    ylim(app.PhaseRecoFig,[0, size(im,2) + 1]);
                    cbr = colorbar(app.PhaseRecoFig);
                    cbr.Position = [0.7 0.5 0.02 0.2];
                    cbr.TickDirection = 'out';
                    if scale == 1
                        app.PhaseMaxLabel.Text = ' π';
                        app.PhaseMinLabel.Text = '-π';
                    else
                        app.PhaseMaxLabel.Text = [' ',num2str(scale,2),'π'];
                        app.PhaseMinLabel.Text = ['-',num2str(scale,2),'π'];
                    end

                    app.newPhaseFigFlag = false;

                else

                    % Only new data in the figure = fast
                    set(app.phaseRecoImage, 'CData', rot90(im));
                    set(app.phaseRecoImage, 'CDataMapping', 'scaled');
                    pause(0.002);

                end

                drawnow;

            end

        end % PlotPhaseImageFigFcn(app)


        % Plot the reconstructed flow image
        function PlotFlowImageFigFcn(app)

            if app.pd.validFlow_flag
             
                % Select the data
                im = app.pd.flowImages(:,:,:,:,:,:,app.FlowDirectionSpinner.Value);

                % Scale
                maxsc = max(abs(im(:)));
                if app.FlowDirectionSpinner.Value == 5
                    minsc = 0;
                else
                    minsc = -maxsc;
                end
                
                % Select which slice to show
                im = permute(im,app.imageOrient(app.FlowOrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.FlowDim3EditField.Value,app.FlowDim4EditField.Value,app.FlowDim5EditField.Value,app.FlowDim6EditField.Value));
             
                % Image aspect ratio & dimensions
                recoAspectRatio = app.CalcImageAspectRatioFcn(app.FlowXLabel.Text,app.FlowYLabel.Text,size(im,1),size(im,2));

                % If phase orientation = 1 rotate image (for viewing purposes only)
                if strcmp(app.FlowXLabel.Text,'X') && strcmp(app.FlowYLabel.Text,'Y') && app.pd.PHASE_ORIENTATION
                    im = rot90(im,-1);
                    recoAspectRatio = app.CalcImageAspectRatioFcn(app.FlowYLabel.Text,app.FlowXLabel.Text,size(im,1),size(im,2));
                end

                % Plot the image in the images panel
                if app.newFlowFigFlag 

                    % Plot the image in the image panel
                    cla(app.FlowRecoFig);
                    app.FlowRecoFig.Position = [43,87,325,325];

                    % New fig with changed properties
                    app.flowRecoImage = imshow(rot90(im),[minsc-0.001 maxsc],'Interpolation','bilinear','InitialMagnification','fit','Parent',app.FlowRecoFig);

                    % Aspect ratio and dimensions
                    daspect(app.FlowRecoFig,[1 recoAspectRatio 1]);
                    xlim(app.FlowRecoFig,[0, size(im,1) + 1]);
                    ylim(app.FlowRecoFig,[0, size(im,2) + 1]);
                    cbr = colorbar(app.FlowRecoFig);
                    cbr.Position = [0.7 0.5 0.02 0.2];
                    cbr.TickDirection = 'out';
                    app.FlowMaxLabel.Text = num2str(maxsc,3);
                    app.FlowMinLabel.Text = num2str(minsc,3);
                 
                    app.newFlowFigFlag = false;

                else

                    % Only new data in the figure = fast
                    set(app.flowRecoImage, 'CData', rot90(im));
                    set(app.flowRecoImage, 'CDataMapping', 'scaled');
                  
                    pause(0.002);

                end

                drawnow;

            end

        end % PlotFlowImageFigFcn(app)


    end


    % --------------------------------------------------------------
    %
    %                       PUBLIC FUNCTIONS
    %
    % --------------------------------------------------------------

    methods (Access = public)


        % Text messages in the app
        function TextMessage(app, message, varargin)

            % Write all message to log
            try
                fprintf(app.logFile,"%s \n", message);
            catch
            end

            % Write message to message box
            try

                if ~app.blockMessagesFlag

                    % If no message level input, message level = 1
                    debugLevel = 1;
                    if nargin > 2
                        debugLevel = varargin{1};
                    end

                    % Add new text
                    newText = app.MessageBox.Value;
                    if debugLevel <= app.MessageLevelSpinner.Value
                        newText = [message ; app.MessageBox.Value];
                    end

                    % Limit the history of the messages in the message box to 100 lines
                    if size(newText,1)>100
                        newText = newText(1:100,1);
                    end

                    % Write the message in the app message window
                    app.MessageBox.Value = newText;
                    drawnow;

                end

            catch
            end

        end % TextMessage

        % Status icon
        function SetStatus(app,status)

            switch status

                case 0
                    % app.StatusImage.Position = [25,218,30,30];
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.StatusLabel.FontSize = 14;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontColor = app.green;
                    app.StatusLabel.Text = 'OK';

                case 1
                    % app.StatusImage.Position = [25,218,30,30];
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.StatusLabel.FontSize = 11;
                    app.StatusLabel.FontWeight = 'bold';
                    if app.AutoSoundsCheckBox.Value
                        sound(app.warningSound,48000);
                    end
                    app.StatusLabel.FontColor = app.orange;
                    app.StatusLabel.Text = 'WARNING';

                case 2
                    % app.StatusImage.Position = [25,218,30,30];
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.StatusLabel.FontSize = 12;
                    app.StatusLabel.FontWeight = 'bold';
                    if app.AutoSoundsCheckBox.Value
                        sound(app.errorSound,48000);
                    end
                    app.StatusLabel.FontColor = app.brightred;
                    app.StatusLabel.Text = 'ERROR';

                case 3
                    % app.StatusImage.Position = [25,218,30,30];
                    app.StatusImage.ImageSource = 'statusbusy.gif';
                    app.StatusLabel.FontSize = 12;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontColor = app.blue;
                    app.StatusLabel.Text = 'BUSY';

            end

            drawnow;

        end

    end


    % --------------------------------------------------------------
    %
    %                   USER INTERFACE CODE
    %
    % --------------------------------------------------------------


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Suppress unneccesary warnings
            warning off;
            clc;

            % Set app theme to light
            theme(app.P2ROUD_UIFigure,"light")
                       
            % Add subfolders to search path
            if (~isdeployed)
                app.oldDir = pwd;
                info = dir(which('P2ROUD.mlapp'));
                app.appDir = info.folder;
                cd(app.appDir);
                addpath(genpath(strcat(pwd,filesep,'functions')));
                addpath(genpath(strcat(pwd,filesep,'gui')));
            end

            % Open new log file
            try
                app.TextMessage("Creating log file ...",1);
                logFileName = makeLogFile(app.appVersion);
                app.logFile = fopen(logFileName,'w');
                app.TextMessage(strcat("log file = ",logFileName," ..."),3);
            catch ME
                app.TextMessage("Could not create log file ...",1);
                app.TextMessage(ME.message,2);
            end
       
            % Create empty objects of classes
            app.pd = proudData();

            % Set the app layout
            app.SetAppLayoutFcn;
            app.ColorsButtonGroupSelectionChanged;
            app.SetStatus(0);
            app.SwitchOffAllButtonsFcn;
            app.DisableLabelsFcn;
            app.CreditsLabel.Text = strcat("Gustav Strijkers, Bram Coolen, Amsterdam UMC, Version ",app.appVersion);

            % Empty file import and export paths
            app.mrdImportPath = '';
            app.mrdFlag = true;
            app.dicomExportPath = '';

            % Reset flags
            app.dicomFlag = true;
            app.stopMovieFlag = true;
            app.stopPhaseMovieFlag = true;
            app.stopFlowMovieFlag = true;
            app.stopMIPxMovieFlag = true;
            app.stopMIPyMovieFlag = true;
            app.newFigFlag = true;
            app.newPhaseFigFlag = true;
            app.newFlowFigFlag = true;
            app.forceProudFlag = false;
            app.scrollEnableFlag = false;
            app.bartDetectedFlag = false;

            % Add the bart path to the environment variable if available
            try
                if ismac()
                    [~,result] = system('uname -v');
                    is_m1_mac = any(strfind(result,'ARM64'));
                    if is_m1_mac
                        app.TextMessage('Apple arm64 version ...',1);
                    else
                        app.TextMessage('Apple Intel version ...',1);
                    end
                else
                    app.TextMessage('PC Intel version ...',1);
                end
            catch 
            end

            % Determine if BART is available by requesting its version number
            try
                app.bartVersion = 'not found';
                bart(app,'version');
                if contains(app.bartVersion,'v')
                    app.bartDetectedFlag = true;
                end
            catch ME
                app.bartDetectedFlag = false;
                app.TextMessage(ME.message,2);
            end

            % Report whether BART is available or not
            if app.bartDetectedFlag
                app.TextMessage('BART toolbox detected ...',1);
            else
                app.TextMessage('WARNING: BART toolbox not available ...',1);
                app.SetStatus(1);
            end

            % Check if Bart was compiled with GPU support
            app.GPUpresentFlag = false;
            if app.bartDetectedFlag
                try
                    app.blockMessagesFlag = true;
                    rec = bart(app,'pics -l2 -g ',[0 1 0 ; 1 1 1 ; 0 1 0],[1 1 1 ; 1 1 1 ; 1 1 1]); %#ok<NASGU>
                    app.blockMessagesFlag = false;
                    app.TextMessage('BART compiled with GPU support ...',1);
                    app.GPUpresentFlag = true;
                catch
                    app.blockMessagesFlag = false;
                    app.TextMessage('BART compiled without GPU support ...',1);
                end
            end

            % Check if AI modules are present
            if app.AI
                app.pd = app.pd.checkForAIModules(app);
            end

            % Load gradient delays file
            try
                delays = load('graddelays.txt');
                app.GxDelayEditField.Value = delays(1);
                app.GyDelayEditField.Value = delays(2);
                app.GzDelayEditField.Value = delays(3);
                app.DataOffsetRadialEditField.Value = delays(4);
                app.TextMessage('Gradient delays file found ...',1);
            catch ME
                app.TextMessage(ME.message,2);
                app.TextMessage('Gradient delays file not found or invalid gradient file ...',1);
            end

            % Determine the current directory in case of deployed app
            if isdeployed
                try
                    [~,result] = system('path');
                    app.currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                    app.TextMessage(strcat("Path: ",app.currentDir," ..."),2);
                catch
                    app.currentDir = '';
                end
            end

            % Check app version
            app.CheckVersionFnc;
          
             % Start the parallel pool if it is not running already
            try
                numCores = feature('numcores');
                p = gcp('nocreate');
                if isempty(p)
                    app.TextMessage("Starting parallel pool ...",1);
                    if numCores >= 8
                        parpool(8);
                    else
                        parpool(numCores);
                    end
                end
            catch ME
                app.TextMessage("Error starting the parallel pool ...",1);
                app.TextMessage(ME.message,2);
            end

            % Initialize buttons
            app.SetLightsFcn({'Red','Red','Red','Red','Red','Red'});
            app.SetButtonsFcn({'On','Off','Off','Off','Off','Off','On'});
            app.SetRecoFieldsFcn;
            app.DisableLabelsFcn;
            app.SetStatus(0);
            app.TextMessage("Ready ...");

        end

        % Selection changed function: ColorsButtonGroup
        function ColorsButtonGroupSelectionChanged(app, event)

            selectedButton = app.ColorsButtonGroup.SelectedObject;

            switch lower(selectedButton.Text)

                % COLORS:           backgroundColor, fontColor, boxColor, gaugeColor, gaugeBackgroundColor, figureColor, movieFigureColor, gridColor

                case "classic"

                    guiColors = {app.guiGrey, app.guiBlack, app.guiDarkGrey, app.blue, app.guiWhite, app.guiWhite, app.guiMiddleGrey, app.guiDarkGrey};
                 
                case "black/white"

                    guiColors = {app.guiBlack, app.guiWhite, app.guiDarkGrey, app.blue, app.guiDarkGrey, app.guiGrey, app.guiDarkGrey, app.guiDarkGrey};
                 
            end

            app.SetAppColorsFcn(guiColors);

        end

        % Button pushed function: LoadMRDButton
        function LoadMRDButtonPushed(app, event)

            % Set buttons
            app.SwitchOffAllButtonsFcn;
            app.DisableLabelsFcn;
            app.SetLightsFcn({'Yellow','Red','Red','Red','Red','Red'});
            app.SetStatus(0);

            % Reset object of class proudData
            app.pd = proudData();

            % Check standard smis directory first if platform is pc, only 1x per session
            if ispc

                try
                    folderName = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','MrdDirectory');
                    folderName = [folderName,filesep];
                catch ME
                    folderName = 'c:\smis\dev\MRD\';
                    app.TextMessage(ME.message,2);
                end

                if exist(folderName, 'dir') && app.mrdFlag
                    app.mrdImportPath = folderName;
                    app.mrdFlag = false;
                end

            end

            % Check mrdpath.txt file next, only 1x per session
            if isfile('mrdpath.txt') && app.mrdFlag

                try
                    folderName = fileread('mrdpath.txt');
                    if exist(folderName, 'dir')
                        app.mrdImportPath = folderName;
                        app.mrdFlag = false;
                    end
                catch ME
                    app.TextMessage(ME.message,2);
                    app.mrdImportPath = '';
                    app.mrdFlag = false;
                end

            end

            % Bart total generalized variation (G) or total variation (T)
            app.pd.totalVariation = 'T';
            if app.bartDetectedFlag
                try
                    picsHelp = bart(app,'pics -Rh');
                    if contains(picsHelp,"total generalized variation")
                        app.pd.totalVariation = 'G';
                    end
                catch
                end
            end

            % Select 1 MRD file
            f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
            [mrdFile,app.mrdImportPath] = uigetfile(strcat(app.mrdImportPath,filesep,'*.mrd'),'Select MR Solutions data file ...');
            delete(f);
            figure(app.P2ROUD_UIFigure);  % Put the app back in the foreground

            if ischar(mrdFile)

                % Indicate busy
                app.SetStatus(3);

                try

                        % MRD file list, check for multiple file names with the same name = multicoil
                        app.MRDfileViewField.Value = mrdFile;
                        k = strfind(mrdFile,'_');
                        if length(k)>1
                            mrdStart = extractBefore(mrdFile,k(end-1));
                        else
                            mrdStart = extractBefore(mrdFile,k(end));
                        end
                        mrdFile = [app.mrdImportPath,mrdFile];
                        fList = dir(fullfile(app.mrdImportPath,[mrdStart,'*.MRD']));

                        % Set the number of coils
                        app.pd = app.pd.setNumberOfCoils(app,fList);
                        app.ResetCoilButtonsFcn;

                        % Load the data
                        app.TextMessage('Reading MRD file(s) ...',1);
                        app.pd = app.pd.readProudData(app, mrdFile, fList);

                        % Filename tag
                        [~,app.tag,~] = fileparts([fList(1).folder, filesep, fList(1).name]);

                        % Read the full mrd-file footer
                        app.pd = app.pd.readMrdFooter(fullfile(fList(1).folder, fList(1).name));

                        % Read the rpr file
                        app.pd = app.pd.readRprFile(app, fList(1).folder);

                        % Read the SQL file if present
                        sqlFilename = [];
                        sqlFilenameList = dir(fullfile([fList(1).folder, filesep, '*.SQL']));
                        if ~isempty(sqlFilenameList)
                            sqlFilename = [sqlFilenameList(1).folder, filesep, sqlFilenameList(1).name];
                        end
                        app.pd = app.pd.readSQLfile(app, sqlFilename);

                catch ME

                    app.TextMessage('ERROR: during file loading ...',1);
                    app.TextMessage(ME.message,2);
                    app.SetStatus(2);

                end

            end

            % Report on valid/invalid file selection
            if app.pd.validFile_flag

                % Set other parameters
                try

                    app.SetParametersFcn;
                    app.SetKspaceLimitsFcn;

                    app.FillKspaceFcn;
                    app.PlotKspaceFigFcn;
                  
                    app.TextMessage('Data import ready ...',1);
                    app.TextMessage(strcat("Size of k-space = ",num2str(size(app.pd.rawKspace{1}))," ..."),1);

                    % Set buttons
                    app.SetLightsFcn({'Green','Red','Red','Red','Red','Red'});
                    app.SetButtonsFcn({'On','On','Null','Null','Null','Null','On'});
                    app.EnableLabelsFcn;
                    app.DisableImageButtonsFcn;
                    app.KspaceTabGroup.SelectedTab = app.KspaceTab;  
                    app.kValuesButton.Value = 1;
                    app.SetStatus(0);

                catch ME

                    app.TextMessage("Error setting initializing scan parameters ...",1);
                    app.TextMessage(ME.message,2);
                    app.pd.validFile_flag = false;
                    
                    % Set the buttons for invalid data file
                    app.SetLightsFcn({'Red','Red','Red','Red','Red','Red'});
                    app.SetButtonsFcn({'On','Off','Off','Off','Off','Off','On'});
                    app.EnableLabelsFcn;
                    app.DisableImageButtonsFcn;
                    app.KspaceTabGroup.SelectedTab = app.KspaceTab;
                    app.kValuesButton.Value = 1;
                    app.SetStatus(2);

                end

            else

                % Not a valid data file
                app.TextMessage('ERROR: Please select a valid data file ...',1);
                
                % Set the buttons for invalid data file
                app.SetLightsFcn({'Red','Red','Red','Red','Red','Red'});
                app.SetButtonsFcn({'On','Off','Off','Off','Off','Off','On'});
                app.EnableLabelsFcn;
                app.DisableImageButtonsFcn;
                app.KspaceTabGroup.SelectedTab = app.KspaceTab;
                app.kValuesButton.Value = 1;
                app.SetStatus(2);

            end

        end

        % Value changed function: KFlipAngleEditField
        function KFlipAngleEditFieldValueChanged(app, event)

            if app.stopKspaceMovieFlag
                app.KFlipAngleSlider.Value = app.KFlipAngleEditField.Value;
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: KFlipAngleSlider
        function KFlipAngleSliderValueChanged(app, event)

            if app.stopKspaceMovieFlag
                app.KFlipAngleEditField.Value = round(app.KFlipAngleSlider.Value);
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: KDim4EditField
        function KDim4EditFieldValueChanged(app, event)

            if app.stopKspaceMovieFlag
                app.KDim4Slider.Value = app.KDim4EditField.Value;
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: KDim4Slider
        function KDim4SliderValueChanged(app, event)

            if app.stopKspaceMovieFlag
                app.KDim4EditField.Value = round(app.KDim4Slider.Value);
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: KEchoEditField
        function KEchoEditFieldValueChanged(app, event)

            if app.stopKspaceMovieFlag
                app.KEchoSlider.Value = app.KEchoEditField.Value;
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: KEchoSlider
        function KEchoSliderValueChanged(app, event)

            if app.stopKspaceMovieFlag
                app.KEchoEditField.Value = round(app.KEchoSlider.Value);
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: KDim3EditField
        function KDim3EditFieldValueChanged(app, event)

            if app.stopKspaceMovieFlag
                app.KDim3EditFieldS.Value = app.KDim3EditField.Value;
                app.KDim3Slider.Value = app.KDim3EditField.Value;
                app.KDim3SliderS.Value = app.KDim3EditField.Value;
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: KDim3EditFieldS
        function KDim3EditFieldSValueChanged(app, event)
            
            if app.stopKspaceMovieFlag
                app.KDim3EditField.Value = app.KDim3EditFieldS.Value;
                app.KDim3Slider.Value = app.KDim3EditFieldS.Value;
                app.KDim3SliderS.Value = app.KDim3EditFieldS.Value;
                app.PlotKspaceFigFcn;
            end
            
        end

        % Value changed function: KDim3Slider
        function KDim3SliderValueChanged(app, event)

            if app.stopKspaceMovieFlag
                app.KDim3SliderS.Value = app.KDim3Slider.Value;
                app.KDim3EditField.Value = round(app.KDim3Slider.Value);
                app.KDim3EditFieldS.Value = round(app.KDim3Slider.Value);
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: KDim3SliderS
        function KDim3SliderSValueChanged(app, event)
               
            if app.stopKspaceMovieFlag
                app.KDim3Slider.Value = app.KDim3SliderS.Value;
                app.KDim3EditField.Value = round(app.KDim3SliderS.Value);
                app.KDim3EditFieldS.Value = round(app.KDim3SliderS.Value);
                app.PlotKspaceFigFcn;
            end
            
        end

        % Value changed function: KOrientationSpinner
        function KOrientationSpinnerValueChanged(app, event)

            if app.stopKspaceMovieFlag
                app.SetKspaceLimitsFcn;
                app.SetKspaceWLFcn;
                app.PlotKspaceFigFcn;
            end

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

            if app.stopKspaceMovieFlag
                app.SetKspaceLimitsFcn;
                app.SetKspaceWLFcn;
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: CoilSpinner
        function CoilSpinnerValueChanged(app, event)

            if app.stopKspaceMovieFlag
                app.SetKspaceWLFcn;
                app.PlotKspaceFigFcn;
            end

        end

        % Value changed function: C1CheckBox, C2CheckBox, C3CheckBox, 
        % ...and 5 other components
        function CoilCheckBoxValueChanged(app, event)

            app.pd.coilActive_flag = [app.C1CheckBox.Value app.C2CheckBox.Value app.C3CheckBox.Value app.C4CheckBox.Value ...
                app.C5CheckBox.Value app.C6CheckBox.Value app.C7CheckBox.Value app.C8CheckBox.Value];

            if nnz(app.pd.coilActive_flag(1:app.pd.nrCoils)) == 0
                app.C1CheckBox.Value = 1;
                app.pd.coilActive_flag(1) = 1;
            end

            if nnz(app.pd.coilActive_flag(1:app.pd.nrCoils)) == 1
                app.AutoSensitivityCheckBox.Value = 0;
                app.AutoSensitivityCheckBoxValueChanged;
            end

        end

        % Value changed function: S1EditField, S2EditField, S3EditField, 
        % ...and 5 other components
        function SensitivityEditFieldValueChanged(app, event)

            app.pd.coilSensitivities = [app.S1EditField.Value app.S2EditField.Value app.S3EditField.Value app.S4EditField.Value ...
                app.S5EditField.Value app.S6EditField.Value app.S7EditField.Value app.S8EditField.Value];
  
        end

        % Value changed function: AutoSensitivityCheckBox
        function AutoSensitivityCheckBoxValueChanged(app, event)

            if nnz(app.pd.coilActive_flag) == 1
                app.AutoSensitivityCheckBox.Value = 0;
            end

            app.EnableCoilButtonsFcn;

        end

        % Button pushed function: AbortGradCalButton
        function AbortGradCalButtonPushed(app, event)
            
            app.stopGradCalFlag = true;
            drawnow;

        end

        % Value changed function: ZTEDeadTimeEditField, 
        % ...and 2 other components
        function ZTEDeadTimeEditFieldValueChanged(app, event)
            
            app.stopMovieFlag = true;
            drawnow;

            % Set buttons
            app.SetLightsFcn({'Null','Red','Red','Red','Red','Red'});
            app.SetButtonsFcn({'On','On','Off','Off','Off','Off','On'});
            app.RecoProgressGauge.Value = 0;
            app.ExportProgressGauge.Value = 0;
            drawnow;

            app.pd.deadTimeZTE = app.ZTEDeadTimeEditField.Value;
            app.pd.shiftZTE = app.ZTEShiftEditField.Value;
            app.pd.fullEchoZTE = app.ZTEFullEchoCheckBox.Value;

            if app.pd.validFile_flag && app.pd.dataType == "ZTE"
                app.SetParametersFcn;
                app.SetKspaceLimitsFcn;
                app.FillKspaceFcn;
                app.PlotKspaceFigFcn;
            end

        end

        % Button pushed function: KStartButton
        function KStartButtonPushed(app, event)

            if app.pd.validTrajectory_flag

                % Indicate busy
                app.SetStatus(3);
                app.stopKspaceMovieFlag = false;
                app.DisableKbuttonsFcn;
                app.KStopButton.Enable = 'on';
                app.KStartButton.Enable = 'off';

                % Set the image dimensions
                im = abs(app.pd.rawKspace{app.CoilSpinner.Value});
                app.KspaceFig.Position = [34,87,325,325];
                im = permute(im,app.imageOrient(app.KOrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.KDim3EditField.Value,app.KDim4EditField.Value,app.KFlipAngleEditField.Value,app.KEchoEditField.Value));
                recoAspectRatio = app.CalcKAspectRatioFcn(app.KxLabel.Text,app.KyLabel.Text,size(im,1),size(im,2));
                daspect(app.KspaceFig,[1 recoAspectRatio 1]);
                sizex = size(im,1);
                sizey = size(im,2);

                hold(app.KspaceFig,'on');
                delete(app.kmovieFig);

                % Get plot size in pixel units
                originalUnits = app.KspaceFig.Units;
                axh.Units = 'Points';
                axSizePix = app.KspaceFig.Position(3:4);
                axh.Units = originalUnits; %#ok<STRNU> 
                pixPerUnit = axSizePix ./ [range(1:sizex),range(1:sizey)];  %pixels per unit

                % Initialize scatter plot
                app.kmovieFig = scatter(app.KspaceFig,1,1,ceil(median(pixPerUnit))^2,"yellow",'filled','s','MarkerFaceAlpha',0.5);
                xlim(app.KspaceFig,[0, sizex+1]);
                ylim(app.KspaceFig,[0, sizey+1]);
                hold(app.KspaceFig,'off');

                % K-space list that will be displayed
                pnts = app.pd.seqTrajectory(:,app.imageOrient(app.KOrientationSpinner.Value,:));
                pnts = pnts(pnts(:,3) == app.KDim3EditField.Value,:);
                pnts = pnts(pnts(:,4) == app.KDim4EditField.Value,:);

                % Accelerate viewing by stepping faster through the list
                stepSize = round(length(pnts)/256);
                stepSize(stepSize < 1) = 1;

                try

                    % Loop through the list and plot the k-space points
                    for cnt = 1:stepSize:length(pnts)

                        app.kmovieFig.XData = pnts(1:cnt,1);
                        app.kmovieFig.YData = sizey + 1 - pnts(1:cnt,2);

                        if app.FastSlowSwitch.Value == "slow"
                            drawnow;
                            pause(0.002);
                        else
                            drawnow limitrate;
                        end

                        if app.stopKspaceMovieFlag
                            break;
                        end

                    end

                    % Finish the plot by showing all points, with a max of 32768
                    stepSize = round(length(pnts)/65536);
                    stepSize(stepSize < 1) = 1;
                    app.kmovieFig.XData = pnts(1:stepSize:end,1);
                    app.kmovieFig.YData = sizey + 1 - pnts(1:stepSize:end,2);


                catch
                end

            else

                app.stopKspaceMovieFlag = true;
                app.TextMessage('K-space trajectory movie not possible for this type of data ...',1);

            end

            % Reset start/stop buttons
            app.stopKspaceMovieFlag = true;
            app.KStopButton.Enable = 'off';
            app.KStartButton.Enable = 'on';
            drawnow;

            % Set status ok
            app.EnableKbuttonsFcn;
            app.SetStatus(0);

        end

        % Button pushed function: KStopButton
        function KStopButtonPushed(app, event)

            app.stopKspaceMovieFlag = true;
            app.KStopButton.Enable = 'off';
            drawnow;

        end

        % Value changed function: FastSlowSwitch
        function FastSlowSwitchValueChanged(app, event)

            drawnow;

        end

        % Value changed function: KLevelEditField
        function KLevelEditFieldValueChanged(app, event)

            if app.pd.validFile_flag

                app.KLevelSlider.Value = app.KLevelEditField.Value;
                app.PlotKspaceFigFcn;

            end

        end

        % Value changed function: KWindowEditField
        function KWindowEditFieldValueChanged(app, event)

            if app.pd.validFile_flag

                app.KWindowSlider.Value = app.KWindowEditField.Value;
                app.PlotKspaceFigFcn;

            end

        end

        % Value changed function: KLevelSlider
        function KLevelSliderValueChanged(app, event)

            if app.pd.validFile_flag

                app.KLevelEditField.Value = round(app.KLevelSlider.Value);
                app.PlotKspaceFigFcn;

            end

        end

        % Value changed function: KWindowSlider
        function KWindowSliderValueChanged(app, event)

            if app.pd.validFile_flag

                app.KWindowEditField.Value = round(app.KWindowSlider.Value);
                app.PlotKspaceFigFcn;

            end

        end

        % Value changed function: NREditField
        function NREditFieldValueChanged(app, event)

            % Set buttons
            app.SetLightsFcn({'Yellow','Null','Null','Null','Null','Null'});
            app.SwitchOffAllButtonsFcn;
            app.DisableLabelsFcn;
            app.SetStatus(3);

            % Stop the movies, if running
            app.stopMovieFlag = true;
            app.stopKspaceMovieFlag = true;
            drawnow;

            % Reset navigator samples if present
            if app.pd.slice_nav == 1
                app.pd.NO_SAMPLES = app.pd.NO_SAMPLES + app.pd.no_samples_discard + app.pd.no_samples_nav;
                app.XEditField.Value = app.pd.NO_SAMPLES;
                app.KMatrixViewField1.Value = app.pd.NO_SAMPLES;
            end

            % Re-sort the data in the new desired number of frames
            switch app.pd.trajType

                case "P2ROUD"
                    app.pd = app.pd.sort3DProudKspaceMRD(app);
           
                case {"mul_kspace","zigzag"}

                    switch app.pd.dataType

                        case "2D"
                            app.pd = app.pd.sort2DKspaceMRD(app);

                        case "3D"
                            app.pd = app.pd.sort3DKspaceMRD(app);

                    end

                case "NRLUT"

                    app.pd = app.pd.sort3DNonRegLUTKspaceMRD(app);

            end

            % Chop of the navigator again if present
            app.pd.navChoppedFlag = false;
            app.pd = app.pd.chopNav(app);
        
            % K-space filling percentage, without cropping, dynamic interpolation, or zero-filling
            app.FillingViewField.Value = round(100*nnz(app.pd.fillingSpace)/numel(app.pd.fillingSpace));

            % Apply Tukey k-space filter
            app.pd = app.pd.applyTukey(app);

            % Total scan time and scan time / dynamic
            app.CalcTotalAcquisitionTimeFcn;

            % Set k-space dimensions and plot the k-space
            app.SetKspaceLimitsFcn;
            app.SetKspaceWLFcn;
            app.PlotKspaceFigFcn;

            % Set buttons
            app.SetLightsFcn({'Green','Red','Red','Red','Red','Red'});
            app.SetButtonsFcn({'On','On','Off','Off','Off','Off','On'});
            app.RecoProgressGauge.Value = 0;
            app.ExportProgressGauge.Value = 0;
            app.EnableLabelsFcn;
            app.SetStatus(0);
         
        end

        % Value changed function: ZEditField
        function RecoSizeFieldValueChanged(app, event)

            app.stopMovieFlag = true;
            drawnow;

            % Set buttons
            app.SetLightsFcn({'Null','Red','Red','Red','Red','Red'});
            app.SetButtonsFcn({'On','On','Off','Off','Off','Off','On'});
            app.RecoProgressGauge.Value = 0;
            app.ExportProgressGauge.Value = 0;
            drawnow;

        end

        % Value changed function: AFGPdeNoiseCheckBox, 
        % ...and 28 other components
        function RecoParametersChanged(app, event)

            app.stopMovieFlag = true;
            drawnow;

            % Set buttons
            app.SetLightsFcn({'Null','Red','Red','Red','Red','Red'});
            app.SetButtonsFcn({'On','On','Off','Off','Off','Off','On'});
            app.RecoProgressGauge.Value = 0;
            app.ExportProgressGauge.Value = 0;
            drawnow;

            app.SetRecoFieldsFcn;

        end

        % Button pushed function: RecoimagesButton
        function RecoImagesButtonPushed(app, event)

            if app.pd.validFile_flag

                % Set buttons
                app.SwitchOffAllButtonsFcn;
                app.SetLightsFcn({'Null','Yellow','Null','Null','Null','Null'});
                app.DisableLabelsFcn;
                app.FlowCalcButton.Enable = 'off';
                app.FlowVencLamp.Color = app.red;

                % Indicate busy
                app.SetStatus(3);

                % Reset some buttons, stop the movies
                app.stopRecoFlag = false;
                app.pd.validReco_flag = false;
                app.validNIFTIdataFlag = false;
                app.validMovieFlag = false;
                app.stopMovieFlag = true;
                app.stopKspaceMovieFlag = true;
                app.newFigFlag = true;
                app.newPhaseFigFlag = true;
                app.newFlowFigFlag = true;
                app.AbortRecoButton.Enable = 'on';
                app.RecoProgressGauge.Value = 0;
                app.pd.validMask_flag = false;
                drawnow;

                app.TextMessage('Reconstructing the data ...',1);

                try

                    % Images = (X, Y, Z, NR, NFA, NE)
                    app.pd.images = [];
                    app.pd.complexImages = [];
                    app.pd.phaseImages = [];
                    app.pd.phaseImagesOrig = [];
                    app.pd.mask = [];
                    app.pd.sensitivityMaps = {};

                    % Auto coil sensitivity estimation
                    if app.AutoSensitivityCheckBox.Value == 1
                        app.pd = app.pd.estimateCoilSensitivies(app);
                    end

                    switch app.pd.dataType

                        case {"2D","2Depi"}

                            if app.CSRecoCheckBox.Value == 1
                                % CS reconstruction
                                app.pd = app.pd.csReco2D(app);
                            else
                                % FFT reconstruction
                                app.pd = app.pd.fftReco2D(app);
                            end

                        case "3D"

                            if app.CSRecoCheckBox.Value == 1
                                % CS reconstruction
                                app.pd = app.pd.csReco3D(app);
                            else
                                % FFT reconstruction
                                app.pd = app.pd.fftReco3D(app);
                            end

                            % FOV shift for P2ROUD (exLUT) data
                            if strcmp(app.pd.trajType,"P2ROUD")
                                app.pd = app.pd.shiftImages3D(app);
                            end

                        case "2Dradial"

                            if app.CSRecoCheckBox.Value == 1 && app.bartDetectedFlag
                                % 2D Radial reconstruction with BART
                                app.pd = app.pd.Reco2DRadialCS(app);
                            else
                                % 2D Radial reconstruction with NUFFT in Matlab
                                app.pd = app.pd.Reco2DRadialNUFFT(app);
                            end

                            % 2D FOV shift
                            app.pd = app.pd.shiftImages2D(app);

                        case "3Dute"

                            if app.CSRecoCheckBox.Value == 1 && app.bartDetectedFlag
                                % CS BART reconstruction
                                app.pd = app.pd.Reco3DuteCS(app);
                            else
                                % NUFFT reconstruction in Matlab
                                app.pd = app.pd.Reco3DuteNUFFT(app);
                            end

                            % FOV shift for UTE
                            app.pd = app.pd.shiftImages3D(app);

                        case "ZTE"

                            % ZTE reco
                            app.pd = app.pd.Reco3DZTE(app);

                    end
            
                    % So far successful reco
                    app.pd.validReco_flag = true;

                    % Readout oversampling
                    if app.pd.oversample == 1
                        oversample = round(app.XEditField.Value/4);
                        app.pd.images = app.pd.images(oversample+1:end-oversample,:,:,:,:,:);
                        app.pd.phaseImages = app.pd.phaseImages(oversample+1:end-oversample,:,:,:,:,:);
                    end

                    % Deep learning denoising
                    if app.DLdenoiseCheckBox.Value
                        app.pd = app.pd.DLdenoise(app);
                    end

                    % Gibbs ringing suppression
                    if app.DeRingCheckBox.Value
                        app.pd = app.pd.unRing(app);
                    end

                    % PCA denoising
                    if app.PCAdeNoiseCheckBox.Value
                        app.pd = app.pd.PCAdenoise(app);
                    end

                    % AFGP denoising
                    if app.AFGPdeNoiseCheckBox.Value
                        app.pd = app.pd.AFGPdenoise(app);
                    end

                    % CDTV denoising
                    if app.CDTVdeNoiseCheckBox.Value
                        app.pd = app.pd.CDTVdenoise(app);
                    end

                    % NLMF denoising
                    if app.NLMFdeNoiseCheckBox.Value
                        app.pd = app.pd.NLMFdenoise(app);
                    end

                    % Scale images to convenient range
                    app.pd = app.pd.scaleImages;

                    % Finished
                    app.RecoProgressGauge.Value = 100;
                    drawnow;

                catch ME

                    app.TextMessage(ME.message,2);
                    if app.stopRecoFlag
                        app.TextMessage("Reco aborted by user ...",1);
                    end
                    app.pd.validReco_flag = false;
                    app.RecoProgressGauge.Value = 0;
                    drawnow;

                end

            end

            if app.pd.validReco_flag

                % Nifti check
                app.validNIFTIdataFlag = false;
                if size(app.pd.images,3)>1
                    app.validNIFTIdataFlag = true;
                end

                % Mask
                app.pd.mask = ones(size(app.pd.images));

                % Calculate pixel size 
                app.pd = app.pd.calcPixelSize(app);

                % Replot k-space too
                app.SetKspaceLimitsFcn;
                app.SetKspaceWLFcn;
                app.PlotKspaceFigFcn;

                % Show the images
                if size(app.pd.images,4) > 1
                    app.validMovieFlag = true;
                end
                app.SetImageLimitsFcn;
                app.SetImageWLFcn;
                app.SetMIPWLFcn;
                app.PlotImageFigFcn;
                app.PlotPhaseImageFigFcn;
                app.PlotMipFigFcn;
                app.TextMessage(strcat("Size of image = ",num2str(size(app.pd.images))," ..."),1);

                if app.stopRecoFlag
                    app.SetStatus(1);
                    app.SetLightsFcn({'Null','Green','Red','Red','Red','Red'});
                    app.TextMessage('WARNING: Reconstruction interrupted ...',1);
                else
                    app.SetStatus(0);
                    app.SetLightsFcn({'Null','Green','Red','Red','Red','Red'});
                end

                % Set buttons
                app.SetButtonsFcn({'On','On','On','On','On','On','On'});
                app.EnableLabelsFcn;
                app.AbortRecoButton.Enable = 'off';
                app.ExportProgressGauge.Value = 0;
                app.SetStatus(0);

                % Start the movie when CINE acquisition
                if (app.pd.frame_loop_on == 1)
                    app.OrientationSpinner.Value = 10;
                    app.OrientationSpinnerValueChanged;
                    app.MovieMoviePlayButtonPushed;
                    drawnow;
                end

            else

                % Set buttons
                app.SetButtonsFcn({'On','On','Off','Off','Off','Off','On'});
                app.SetLightsFcn({'Null','Red','Red','Red','Red','Red'});
                app.EnableLabelsFcn;
                app.AbortRecoButton.Enable = 'off';
                app.TextMessage('ERROR: Something went wrong ...',1);
                app.SetStatus(2);

            end

        end

        % Button pushed function: AbortRecoButton
        function AbortRecoButtonPushed(app, event)

            app.stopRecoFlag = true;
            app.AbortRecoButton.Enable = 'off';
            app.TextMessage('Reco will be aborted ...',1);
            app.TextMessage('Please wait ...',1);
            drawnow;

        end

        % Value changed function: Dim3EditField
        function Dim3EditFieldValueChanged(app, event)

            app.Dim3Slider.Value = app.Dim3EditField.Value;
            app.PlotImageFigFcn;
      
        end

        % Value changed function: Dim3Slider
        function Dim3SliderValueChanged(app, event)

            app.Dim3EditField.Value = round(app.Dim3Slider.Value);
            app.PlotImageFigFcn;

        end

        % Value changed function: Dim4EditField
        function Dim4EditFieldValueChanged(app, event)

            app.Dim4Slider.Value = app.Dim4EditField.Value;
            app.PlotImageFigFcn;
            app.SetMIPWLFcn;
            app.PlotMipFigFcn;

        end

        % Value changed function: Dim4Slider
        function Dim4SliderValueChanged(app, event)

            app.Dim4EditField.Value = round(app.Dim4Slider.Value);
            app.PlotImageFigFcn;
            app.SetMIPWLFcn;
            app.PlotMipFigFcn;

        end

        % Value changed function: Dim5EditField
        function Dim5EditFieldValueChanged(app, event)

            app.Dim5Slider.Value = app.Dim5EditField.Value;
            app.PlotImageFigFcn;
            app.SetMIPWLFcn;
            app.PlotMipFigFcn;

        end

        % Value changed function: Dim5Slider
        function Dim5SliderValueChanged(app, event)

            app.Dim5EditField.Value = round(app.Dim5Slider.Value);
            app.PlotImageFigFcn;
            app.SetMIPWLFcn;
            app.PlotMipFigFcn;

        end

        % Value changed function: Dim6EditField
        function Dim6EditFieldValueChanged(app, event)

            app.Dim6Slider.Value = app.Dim6EditField.Value;
            app.PlotImageFigFcn;
            app.SetMIPWLFcn;
            app.PlotMipFigFcn;

        end

        % Value changed function: Dim6Slider
        function Dim6SliderValueChanged(app, event)

            app.Dim6EditField.Value = round(app.Dim6Slider.Value);
            app.PlotImageFigFcn;
            app.SetMIPWLFcn;
            app.PlotMipFigFcn;

        end

        % Value changed function: OrientationSpinner
        function OrientationSpinnerValueChanged(app, event)

            app.MIPOrientationSpinner.Value = app.OrientationSpinner.Value;
            app.PhaseOrientationSpinner.Value = app.OrientationSpinner.Value;
            app.FlowOrientationSpinner.Value = app.OrientationSpinner.Value;
            app.SetImageLimitsFcn;
            app.newFigFlag = true;
            app.newPhaseFigFlag = true;
            app.newFlowFigFlag = true;
            app.PlotImageFigFcn;
            app.PlotMipFigFcn;
            app.PlotPhaseImageFigFcn;
            app.PlotFlowImageFigFcn;
            
        end

        % Value changed function: MIPOrientationSpinner
        function MIPOrientationSpinnerValueChanged(app, event)
            
            app.OrientationSpinner.Value = app.MIPOrientationSpinner.Value;
            app.PhaseOrientationSpinner.Value = app.MIPOrientationSpinner.Value;
            app.FlowOrientationSpinner.Value = app.MIPOrientationSpinner.Value;
            app.SetImageLimitsFcn;
            app.newFigFlag = true;
            app.newPhaseFigFlag = true;
            app.newFlowFigFlag = true;
            app.PlotImageFigFcn;
            app.PlotMipFigFcn;
            app.PlotPhaseImageFigFcn;
            app.PlotFlowImageFigFcn;
            
        end

        % Value changed function: LevelSlider
        function LevelSliderValueChanged(app, event)

            if app.pd.validReco_flag

                app.AutoIntensityCheckBox.Value = 0;
                app.LevelEditField.Value = round(app.LevelSlider.Value);
                app.PlotImageFigFcn;

            end

        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)

            if app.pd.validReco_flag

                app.AutoIntensityCheckBox.Value = 0;
                app.LevelSlider.Value = app.LevelEditField.Value;
                app.PlotImageFigFcn;

            end

        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)

            if app.pd.validReco_flag

                app.AutoIntensityCheckBox.Value = 0;
                app.WindowEditField.Value = round(app.WindowSlider.Value);
                app.PlotImageFigFcn;

            end

        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)

            if app.pd.validReco_flag

                app.AutoIntensityCheckBox.Value = 0;
                app.WindowSlider.Value = app.WindowEditField.Value;
                app.PlotImageFigFcn;

            end

        end

        % Value changed function: AutoIntensityCheckBox
        function AutoIntensityCheckBoxValueChanged(app, event)

            if app.AutoIntensityCheckBox.Value == 1
                app.PlotImageFigFcn;
            end

        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)

            if app.pd.validReco_flag

                app.AutoIntensityCheckBox.Value = 0;
                app.SetImageWLFcn;
                app.PlotImageFigFcn;

            end

        end

        % Button pushed function: MoviePlayButton
        function MovieMoviePlayButtonPushed(app, event)

            if app.pd.validReco_flag

                try

                    app.MoviePlayButton.Enable = 'off';
                    app.MovieStopButton.Enable = 'on';
                    % app.ExitButton.Enable = 'off';
                    app.stopMovieFlag = false;

                    app.TextMessage('Starting the movie ...',1);

                    movieCounter = 1;
                    while ~app.stopMovieFlag

                        app.Dim4EditField.Value = movieCounter;
                        app.Dim4Slider.Value = movieCounter;
                        app.PlotImageFigFcn;
                        movieCounter = app.Dim4EditField.Value;

                        movieCounter = movieCounter + 1;
                        movieIm = permute(app.pd.images,app.imageOrient(app.OrientationSpinner.Value,:));
                        if movieCounter>size(movieIm,4)
                            movieCounter = 1;
                        end

                    end

                    app.MoviePlayButton.Enable = 'on';
                    app.MovieStopButton.Enable = 'off';

                catch
                end


            end

        end

        % Button pushed function: MovieStopButton
        function MovieMovieStopButtonPushed(app, event)

            app.TextMessage('Stopping the movie ...',1);

            app.MoviePlayButton.Enable = 'on';
            app.MovieStopButton.Enable = 'off';
            app.stopMovieFlag = true;
            app.ExitButton.Enable = 'on';
            drawnow;

        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            
            if app.pd.validReco_flag
                
                app.TextMessage('Automatic image thresholding ...',1);
             
                try
                    
                    % Reset the mask
                    app.pd.mask = ones(size(app.pd.images));

                    % Determine automatic threshold using the Otsu method
                    app.pd.threshold = double(graythresh(mat2gray(app.pd.images(:)))) * max(app.pd.images(:));

                    % Mask based on threshold
                    app.pd.mask(app.pd.images < app.pd.threshold) = 0;

                    % Display the threshold value for the current slice
                    app.ThresholdEditField.Value = round(app.pd.threshold);
                    app.ThresholdSlider.Value = round(app.pd.threshold);

                    % Mask is valid
                    app.pd.validMask_flag = true;
                    app.newFigFlag = true;

                    % Display the mask in the image
                    app.PlotImageFigFcn;
       
                    % Plot MIP and phase image
                    app.PlotMipFigFcn;
                    app.PlotPhaseImageFigFcn;

                catch ME

                    % Failure
                    app.pd.validMask_flag = false;
                    app.newFigFlag = true;
                    app.TextMessage('ERROR: during image thresholding ...',1);
                    app.TextMessage(ME.message,2);
               
                end
                
            end
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            
            app.ThresholdSlider.Value = app.ThresholdEditField.Value;

            if app.pd.validReco_flag
             
                try
                    
                    % Reset the mask
                    app.pd.mask = ones(size(app.pd.images));

                    % Threshold
                    app.pd.threshold = app.ThresholdEditField.Value;

                    % Mask based on threshold
                    app.pd.mask(app.pd.images < app.pd.threshold) = 0;

                    % Mask is valid
                    app.pd.validMask_flag = true;
                    app.newFigFlag = true;

                    % Display the mask in the image
                    app.PlotImageFigFcn;

                    % Plot MIP and phase image
                    app.PlotMipFigFcn;
                    app.PlotPhaseImageFigFcn;

                catch ME

                    % Failure
                    app.pd.validMask_flag = false;
                    app.newFigFlag = true;
                    app.TextMessage('ERROR: during image thresholding ...',1);
                    app.TextMessage(ME.message,2);
               
                end
                
            end
            
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            
            app.ThresholdEditField.Value = round(app.ThresholdSlider.Value);

            app.ThresholdEditFieldValueChanged;
            
        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)

            if app.pd.validReco_flag && app.pd.validMask_flag

                try

                    % Morphological erosion
                    app.pd.mask = 1-imerode(1-app.pd.mask,app.structElement);

                    % Display the result;
                    app.newFigFlag = true;
                    app.PlotImageFigFcn;
                    app.PlotMipFigFcn;
                    app.PlotPhaseImageFigFcn;

                catch
                end

            end

        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
            
            if app.pd.validReco_flag && app.pd.validMask_flag

                try

                    % Morphological erosion
                    app.pd.mask = 1-imdilate(1-app.pd.mask,app.structElement);

                    % Display the result;
                    app.newFigFlag = true;
                    app.PlotImageFigFcn;
                    app.PlotMipFigFcn;
                    app.PlotPhaseImageFigFcn;

                catch
                end

            end

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            
            if app.pd.validReco_flag && app.pd.validMask_flag

                try

                    % Morphological erosion
                    app.pd.mask = 1-imclose(1-app.pd.mask,app.structElement);

                    % Display the result;
                    app.newFigFlag = true;
                    app.PlotImageFigFcn;
                    app.PlotMipFigFcn;
                    app.PlotPhaseImageFigFcn;

                catch
                end

            end

        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            if app.pd.validReco_flag && app.pd.validMask_flag

                try

                    % Morphological erosion
                    app.pd.mask = 1-imopen(1-app.pd.mask,app.structElement);

                    % Display the result;
                    app.newFigFlag = true;
                    app.PlotImageFigFcn;
                    app.PlotMipFigFcn;
                    app.PlotPhaseImageFigFcn;

                catch
                end

            end

        end

        % Value changed function: MIPxAngleSlider, MIPyAngleSlider
        function MIPAngleSliderValueChanged(app, event)
            
            app.MIPcounter = 0;
            app.PlotMipFigFcn;

        end

        % Button pushed function: MIPxPlayButton
        function MIPxPlayButtonPushed(app, event)

            if app.pd.validReco_flag

                app.stopMIPyMovieFlag = true;
                app.MIPyPlayButton.Icon = 'play.gif';

                if app.stopMIPxMovieFlag == true
                    app.MIPcounter = 0;
                    app.stopMIPxMovieFlag = false;
                    app.MIPxPlayButton.Icon = 'stop.gif';
                else
                    app.stopMIPxMovieFlag = true;
                    app.MIPxPlayButton.Icon = 'play.gif';
                end

                drawnow;
           
                while ~app.stopMIPxMovieFlag

                    newAngleX = app.MIPxAngleSlider.Value + app.MIPstep;
                    app.MIPcounter = app.MIPcounter + app.MIPstep;
                    newAngleX(newAngleX > 179) = -180;
                    app.MIPxAngleSlider.Value = newAngleX;
                    drawnow;

                    app.PlotMipFigFcn;

                end

            end

        end

        % Button pushed function: MIPyPlayButton
        function MIPyPlayButtonPushed(app, event)

            if app.pd.validReco_flag

                app.stopMIPxMovieFlag = true;
                app.MIPxPlayButton.Icon = 'play.gif';

                if app.stopMIPyMovieFlag == true
                    app.MIPcounter = 0;
                    app.stopMIPyMovieFlag = false;
                    app.MIPyPlayButton.Icon = 'stop.gif';
                else
                    app.stopMIPyMovieFlag = true;
                    app.MIPyPlayButton.Icon = 'play.gif';
                end

                drawnow;

                while ~app.stopMIPyMovieFlag

                    app.MIPcounter = app.MIPcounter + app.MIPstep;
                    newAngleY = app.MIPyAngleSlider.Value + app.MIPstep;
                    newAngleY(newAngleY > 179) = -180;
                    app.MIPyAngleSlider.Value = newAngleY;
                    drawnow;

                    app.PlotMipFigFcn;

                end

            end

        end

        % Button pushed function: MIPAngleResetButton
        function MIPAngleSliderResetButtonPushed(app, event)
            
            if app.pd.validReco_flag

                app.stopMIPxMovieFlag = true;
                app.stopMIPyMovieFlag = true;

                app.MIPxAngleSlider.Value = 0;
                app.MIPyAngleSlider.Value = 0;

                % Reset the play icon buttons
                app.MIPxPlayButton.Icon = 'play.gif';
                app.MIPyPlayButton.Icon = 'play.gif';

                app.MIPcounter = 0;

                app.PlotMipFigFcn;

            end

        end

        % Value changed function: MIPResizeButton
        function MIPResizeButtonValueChanged(app, event)
         
            if app.pd.validReco_flag
                
                app.MIPcounter = 0;

                if app.stopMIPxMovieFlag & app.stopMIPyMovieFlag %#ok<AND2> 
    
                    app.PlotMipFigFcn;

                end
              
            end
            
        end

        % Button pushed function: MIPSetButton
        function MIPSetButtonPushed(app, event)
            
            app.SetMIPWLFcn;
            app.PlotMipFigFcn;

        end

        % Value changed function: MIPLevelSlider
        function MIPLevelSliderValueChanged(app, event)

            if app.pd.validReco_flag

                app.MIPLevelEditField.Value = round(app.MIPLevelSlider.Value);
                app.PlotMipFigFcn;

            end

        end

        % Value changed function: MIPWindowSlider
        function MIPWindowSliderValueChanged(app, event)
            
            if app.pd.validReco_flag

                app.MIPWindowEditField.Value = round(app.MIPWindowSlider.Value);
                app.PlotMipFigFcn;

            end
            
        end

        % Value changed function: MIPLevelEditField
        function MIPLevelEditFieldValueChanged(app, event)
           
            if app.pd.validReco_flag

                app.MIPLevelSlider.Value = app.MIPLevelEditField.Value;
                app.PlotMipFigFcn;

            end
            
        end

        % Value changed function: MIPWindowEditField
        function MIPWindowEditFieldValueChanged(app, event)

            if app.pd.validReco_flag

                app.MIPWindowSlider.Value = app.MIPWindowEditField.Value;
                app.PlotMipFigFcn;

            end
            
        end

        % Value changed function: PhaseDim3EditField
        function PhaseDim3EditFieldValueChanged(app, event)
             
            app.PhaseDim3Slider.Value = app.PhaseDim3EditField.Value;
            app.PlotPhaseImageFigFcn;
            
        end

        % Value changed function: PhaseDim3Slider
        function PhaseDim3SliderValueChanged(app, event)
            
            app.PhaseDim3EditField.Value = round(app.PhaseDim3Slider.Value);
            app.PlotPhaseImageFigFcn;
            
        end

        % Value changed function: PhaseDim4EditField
        function PhaseDim4EditFieldValueChanged(app, event)
             
            app.PhaseDim4Slider.Value = app.PhaseDim4EditField.Value;
            app.PlotPhaseImageFigFcn;
            
        end

        % Value changed function: PhaseDim4Slider
        function PhaseDim4SliderValueChanged(app, event)
            
            app.PhaseDim4EditField.Value = round(app.PhaseDim4Slider.Value);
            app.PlotPhaseImageFigFcn
            
        end

        % Value changed function: PhaseDim5EditField
        function PhaseDim5EditFieldValueChanged(app, event)
             
            app.PhaseDim5Slider.Value = app.PhaseDim5EditField.Value;
            app.PlotPhaseImageFigFcn;
            
        end

        % Value changed function: PhaseDim5Slider
        function PhaseDim5SliderValueChanged(app, event)
            
            app.PhaseDim5EditField.Value = round(app.PhaseDim5Slider.Value);
            app.PlotPhaseImageFigFcn
            
        end

        % Value changed function: PhaseDim6EditField
        function PhaseDim6EditFieldValueChanged(app, event)
             
            app.PhaseDim6Slider.Value = app.PhaseDim6EditField.Value;
            app.PlotPhaseImageFigFcn;
            
        end

        % Value changed function: PhaseDim6Slider
        function PhaseDim6SliderValueChanged(app, event)
            
            app.PhaseDim6EditField.Value = round(app.PhaseDim6Slider.Value);
            app.PlotPhaseImageFigFcn
            
        end

        % Value changed function: PhaseOrientationSpinner
        function PhaseOrientationSpinnerValueChanged(app, event)

            app.OrientationSpinner.Value = app.PhaseOrientationSpinner.Value;
            app.MIPOrientationSpinner.Value = app.PhaseOrientationSpinner.Value;
            app.FlowOrientationSpinner.Value = app.PhaseOrientationSpinner.Value;
            app.SetImageLimitsFcn;
            app.newFigFlag = true;
            app.newPhaseFigFlag = true;
            app.newFlowFigFlag = true;
            app.PlotImageFigFcn;
            app.PlotMipFigFcn;
            app.PlotPhaseImageFigFcn;
            app.PlotFlowImageFigFcn;
            
        end

        % Button pushed function: PhaseMovieStartButton
        function PhaseMovieStartButtonPushed(app, event)
            
            if app.pd.validReco_flag

                app.PhaseMovieStartButton.Enable = 'off';
                app.PhaseMovieStopButton.Enable = 'on';
                app.stopPhaseMovieFlag = false;

                app.TextMessage('Starting the phase movie ...',1);

                try

                    phaseCounter = 1;
                    while ~app.stopPhaseMovieFlag

                        app.PhaseDim4EditField.Value = phaseCounter;
                        app.PhaseDim4Slider.Value = phaseCounter;
                        app.PlotPhaseImageFigFcn;
                        phaseCounter = app.PhaseDim4EditField.Value;
                        phaseCounter = phaseCounter + 1;
                        phaseIm = permute(app.pd.phaseImages,app.imageOrient(app.PhaseOrientationSpinner.Value,:));
                        if phaseCounter>size(phaseIm,4)
                            phaseCounter = 1;
                        end

                    end

                catch
                end

                app.PhaseMovieStartButton.Enable = 'on';
                app.PhaseMovieStopButton.Enable = 'off';

            end

        end

        % Button pushed function: PhaseMovieStopButton
        function PhaseMovieStopButtonPushed(app, event)
            
            app.TextMessage('Stopping the phase movie ...',1);

            app.PhaseMovieStartButton.Enable = 'on';
            app.PhaseMovieStopButton.Enable = 'off';
            app.stopPhaseMovieFlag = true;
            app.ExitButton.Enable = 'on';
            drawnow;

        end

        % Button pushed function: PhaseUnwrapButton
        function PhaseUnwrapButtonPushed(app, event)

            if app.pd.validReco_flag

                app.SetStatus(3);
                app.PhaseUnwrapButton.Enable = 'off';
                drawnow;

                % Phase unwrapping
                try
                    app.pd = app.pd.unwrap3D;
                    app.newPhaseFigFlag = true;
                    app.PlotPhaseImageFigFcn;
                catch ME
                    app.TextMessage("Error during phase unwrapping ...",1);
                    app.TextMessage(ME.message,2);
                end

                app.SetStatus(0);
                app.PhaseUnwrapButton.Enable = 'on';
                drawnow;

            end

        end

        % Button pushed function: FlowLoadVencTableButton
        function FlowLoadVencTableButtonPushed(app, event)
            
            % Read the venc file
            try
                app.P2ROUD_UIFigure.Visible = 'off';
                [vencFile,vencPath] = uigetfile('*.txt');
                app.P2ROUD_UIFigure.Visible = 'on';
                app.pd.vencTable = dlmread([vencPath,vencFile]); %#ok<DLMRD> 
                drawnow;
                crit1 = true;
            catch ME
                app.TextMessage(ME.message,2);
                app.TextMessage('ERROR reading venc file ...',1);
                crit1 = false;
            end
            
            % Check the length of the Venc table
            try
                EncDir = app.pd.vencTable(:,2:4);
                Venc = app.pd.vencTable(:,1);
                if size(app.pd.vencTable,2) < 5
                    app.pd.vencTable(:,5) = 1;
                end
                EncUse = app.pd.vencTable(:,5);

                if length(Venc) == app.NREditField.Value
                    app.TextMessage('Length of Venc table matches data ...',1);
                    crit2 = true;
                else
                    app.TextMessage('Length of Venc table does not match the data ...',1);
                    crit2 = false;
                end
            catch ME
                app.TextMessage(ME.message);
                crit2 = false;
            end

            % Check if number of directions is at least 2
            try
                if nnz(EncUse) > 1
                    app.TextMessage('Number of flow-encoding directions > 1 ...',1);
                    crit3 = true;
                else
                    app.TextMessage('Insufficient number of flow-encoding directions ...',1);
                    crit3 = false;
                end
            catch ME
                app.TextMessage("Error while checking Venc ...",1);
                app.TextMessage(ME.message,2);
                crit3 = false;
            end
            
            % Venc table is okay
            if crit1 && crit2 && crit3
                app.TextMessage('Venc table seems compatible with data ...',1);
                EncMatrix = [EncDir Venc EncUse];
                for i = size(EncMatrix,1):-1:1
                    app.TextMessage(strcat(num2str(EncMatrix(i,:),3)));
                end
                app.TextMessage('z         y         x       Venc    include ...',1);
                app.pd.validVenc_flag = true;
                app.FlowVencLamp.Color = app.green;
                app.FlowCalcButton.Enable = 'on';
                app.SetStatus(0);
            else
                app.TextMessage('Venc table seems not compatible with data ...',1);
                app.pd.validVenc_flag = false;
                app.FlowVencLamp.Color = app.red;
                app.FlowCalcButton.Enable = 'off';
                app.SetStatus(2);
            end
      
        end

        % Button pushed function: FlowCalcButton
        function FlowCalcButtonPushed(app, event)

            if app.pd.validMask_flag && app.pd.validVenc_flag
                
                app.SetStatus(3);
                app.FlowCalcButton.Enable = 'off';
                drawnow;

                try
                    % Calculate flow
                    app.TextMessage('Calculating 4D-flow data ...',1);
                    app.pd = app.pd.calcFlow(app);

                    app.pd.validFlow_flag = true;
                    app.newFlowFigFlag = true;
                    app.PlotFlowImageFigFcn;

                catch ME
                    app.TextMessage("Error during flow calculations ...",1);
                    app.TextMessage(ME.message,2);
                end

                app.FlowCalcButton.Enable = 'on';
                app.SetStatus(0);
                drawnow;

            else

                app.pd.validFlow_flag = false;
                app.SetStatus(1);
                app.TextMessage('WARNING: Please segment the data first ...',1);

            end

        end

        % Value changed function: FlowDim3EditField
        function FlowDim3EditFieldValueChanged(app, event)
            
            app.FlowDim3Slider.Value = app.FlowDim3EditField.Value;
            app.PlotFlowImageFigFcn;
            
        end

        % Value changed function: FlowDim3Slider
        function FlowDim3SliderValueChanged(app, event)
            
            app.FlowDim3EditField.Value = round(app.FlowDim3Slider.Value);
            app.PlotFlowImageFigFcn;
            
        end

        % Window scroll wheel function: P2ROUD_UIFigure
        function P2ROUD_UIFigureWindowScrollWheel(app, event)

            verticalScrollCount = event.VerticalScrollCount;

            if app.pd.validReco_flag && app.scrollEnableFlag

                if verticalScrollCount > 0

                    newslice = app.Dim3Slider.Value + 1;
                    newslice(newslice>app.Dim3Slider.Limits(2)) = 1;

                else

                    newslice = app.Dim3Slider.Value - 1;
                    newslice(newslice<1) = app.Dim3Slider.Limits(2);

                end

                app.Dim3Slider.Value = newslice;
                app.Dim3SliderValueChanged;

            end

        end

        % Value changed function: FlowDim4EditField
        function FlowDim4EditFieldValueChanged(app, event)
            
            app.FlowDim4Slider.Value = app.FlowDim4EditField.Value;
            app.PlotFlowImageFigFcn;
            
        end

        % Value changed function: FlowDim4Slider
        function FlowDim4SliderValueChanged(app, event)
            
            app.FlowDim4EditField.Value = round(app.FlowDim4Slider.Value);
            app.PlotFlowImageFigFcn;
            
        end

        % Value changed function: FlowDim5EditField
        function FlowDim5EditFieldValueChanged(app, event)
            
            app.FlowDim5Slider.Value = app.FlowDim5EditField.Value;
            app.PlotFlowImageFigFcn;
            
        end

        % Value changed function: FlowDim5Slider
        function FlowDim5SliderValueChanged(app, event)
            
            app.FlowDim5EditField.Value = round(app.FlowDim5Slider.Value);
            app.PlotFlowImageFigFcn;
            
        end

        % Value changed function: FlowDim6EditField
        function FlowDim6EditFieldValueChanged(app, event)
            
            app.FlowDim6Slider.Value = app.FlowDim6EditField.Value;
            app.PlotFlowImageFigFcn;
            
        end

        % Value changed function: FlowDim6Slider
        function FlowDim6SliderValueChanged(app, event)
            
            app.FlowDim6EditField.Value = round(app.FlowDim6Slider.Value);
            app.PlotFlowImageFigFcn;
            
        end

        % Value changed function: FlowOrientationSpinner
        function FlowOrientationSpinnerValueChanged(app, event)
            
            app.OrientationSpinner.Value = app.FlowOrientationSpinner.Value;
            app.MIPOrientationSpinner.Value = app.FlowOrientationSpinner.Value;
            app.PhaseOrientationSpinner.Value = app.FlowOrientationSpinner.Value;
            app.SetImageLimitsFcn;
            app.newFigFlag = true;
            app.newPhaseFigFlag = true;
            app.newFlowFigFlag = true;
            app.PlotImageFigFcn;
            app.PlotMipFigFcn;
            app.PlotPhaseImageFigFcn;
            app.PlotFlowImageFigFcn;
            
        end

        % Value changed function: FlowDirectionSpinner
        function FlowDirectionSpinnerValueChanged(app, event)
            
            switch app.FlowDirectionSpinner.Value

                case 1
                    app.FlowDirectionSpinnerLabel.Text = 'Vz (cm/s)';
                case 2
                    app.FlowDirectionSpinnerLabel.Text = 'Vy (cm/s)';
                case 3
                    app.FlowDirectionSpinnerLabel.Text = 'Vx (cm/s)';
                case 4
                    app.FlowDirectionSpinnerLabel.Text = 'Background';
                case 5
                    app.FlowDirectionSpinnerLabel.Text = '| V | (cm/s)';

            end

            app.newFlowFigFlag = true;
            app.PlotFlowImageFigFcn;

        end

        % Button pushed function: FlowMovieStartButton
        function FlowMovieStartButtonPushed(app, event)
            
            if app.pd.validFlow_flag

                try

                    app.FlowMovieStartButton.Enable = 'off';
                    app.FlowMovieStopButton.Enable = 'on';
                    % app.ExitButton.Enable = 'off';
                    app.stopFlowMovieFlag = false;

                    app.TextMessage('Starting the flow movie ...',1);

                    flowCounter = 1;
                    while ~app.stopFlowMovieFlag

                        app.FlowDim4EditField.Value = flowCounter;
                        app.FlowDim4Slider.Value = flowCounter;
                        app.PlotFlowImageFigFcn;
                        flowCounter = app.FlowDim4EditField.Value;

                        flowCounter = flowCounter + 1;
                        flowIm = permute(app.pd.flowImages(:,:,:,:,:,:,app.FlowDirectionSpinner.Value),app.imageOrient(app.FlowOrientationSpinner.Value,:));
                        if flowCounter>size(flowIm,4)
                            flowCounter = 1;
                        end

                    end

                    app.FlowMovieStartButton.Enable = 'on';
                    app.FlowMovieStopButton.Enable = 'off';

                catch
                end

            end

        end

        % Button pushed function: FlowMovieStopButton
        function FlowMovieStopButtonPushed(app, event)
            
            app.TextMessage('Stopping the flow movie ...',1);

            app.FlowMovieStartButton.Enable = 'on';
            app.FlowMovieStopButton.Enable = 'off';
            app.stopFlowMovieFlag = true;
            app.ExitButton.Enable = 'on';
            drawnow;

        end

        % Button pushed function: ExportDCMButton
        function ExportDCMButtonPushed(app, event)
     
            if app.pd.validReco_flag

                try

                    % Set buttons
                    app.SwitchOffAllButtonsFcn;
                    app.SetLightsFcn({'Null','Null','Yellow','Null','Null','Null'});
                    app.DisableLabelsFcn;
                    app.SetStatus(3);

                    % Some parameters
                    switch app.pd.dataType

                        case {"2D","2Dradial","2Depi"}
                            app.pd.SLICE_SEPARATION = app.pd.SLICE_SEPARATION/app.pd.SLICE_INTERLEAVE;
                            app.pd.SLICE_THICKNESS = app.FOVViewField3.Value;

                        case {"3D","3Dute","ZTE"}
                            app.pd.SLICE_SEPARATION = app.FOVViewField3.Value/size(app.pd.images,3);
                            app.pd.SLICE_THICKNESS = app.FOVViewField3.Value/size(app.pd.images,3);

                    end
                    app.pd.SLICE_INTERLEAVE = 1;
                    app.pd.FOV = app.FOVViewField1.Value;
                    app.pd.NO_SLICES_DCM = size(app.pd.images,3);
                    % app.pd.NO_VIEWS = size(app.pd.images,2);
                    % app.pd.NO_VIEWS_2 = size(app.pd.images,3);
                    app.pd.field_strength = 7;
                    app.pd.filename = 111;
                    app.CalcTotalAcquisitionTimeFcn;
                    app.pd.acqdur = app.pd.totalAcqTime;
                    app.pd.timeperframe = app.pd.totalAcqTime/app.NREditField.Value;
                    app.pd.nr_frames = app.NREditField.Value;

                    % Search for dicom file
                    app.TextMessage('DICOM export ...',1);

                    % Assuming that MRD and Data directory share the same base-directory
                    % Example: base / MRD / 343 / 8455 /

                    % Base / data / 343 / DICOM / 8455 /
                    dcmSpath{1} = replace(app.mrdImportPath,"MRD","Data");
                    try
                        k = strfind(dcmSpath{1},"Data");
                        fs = strfind(dcmSpath{1}(k:end),filesep);
                        dcmSpath{1}= dcmSpath{1}(1:k+fs(3)-1);
                        dcmSpath{1} = insertAfter(dcmSpath{1},k+fs(2)-1,strcat("DICOM",filesep));
                        dcmIndx{1} = 2;
                    catch
                        dcmSpath{1} = app.mrdImportPath;
                    end

                    % Base / data / 343 / DICOM / 8455 / 1 /
                    dcmSpath{2} = strcat(dcmSpath{1},'1',filesep);
                    dcmIndx{2} = 3;

                    % Base / data / 343 / DICOM / 8455 / 2 /
                    dcmSpath{3} = strcat(dcmSpath{1},'2',filesep);
                    dcmIndx{3} = 3;

                    % Base / data / 343 / DICOM / 8455 / 3 /
                    dcmSpath{4} = strcat(dcmSpath{1},'3',filesep);
                    dcmIndx{4} = 3;

                    % Check if one of the directories contains dicom files
                    dcmExist = false;
                    for i=1:length(dcmSpath)
                        if ~isempty(dir(strcat(dcmSpath{i},"*.dcm")))
                            dcmPath = dcmSpath{i};
                            app.TextMessage(strcat("Found DICOM information in ",dcmPath, " ..."),1);
                            filesepIdx = strfind(dcmPath, filesep);
                            app.dicomExportPath = dcmPath(1:filesepIdx(end-dcmIndx{i}));
                            dcmExist = true;
                            break;
                        end
                    end

                    if dcmExist

                        % Export, reading dicom information from dcmPath
                        try
                            % Check if directory is actually writable
                            mkdir(strcat(app.dicomExportPath,filesep,"testdir"));
                            rmdir(strcat(app.dicomExportPath,filesep,"testdir"));
                        catch
                            app.P2ROUD_UIFigure.Visible = 'off';
                            app.dicomExportPath = uigetdir(app.dicomExportPath,'DICOM export path');
                            app.P2ROUD_UIFigure.Visible = 'on';
                            drawnow;
                            if ~ischar(app.dicomExportPath)
                                ME = MException('P2ROUD:userCancel','User cancel ...');
                                throw(ME);
                            end
                        end

                        % Export with image orientation information
                        app.pd.exportPath = exportDicomDCM(app, dcmPath);
                        app.TextMessage(strcat("DICOM export path = ",app.pd.exportPath," ..."),1);
                        app.pd.ExportRecoParametersFcn(app);

                    else

                        % Ask for a dicom export directory
                        app.P2ROUD_UIFigure.Visible = 'off';
                        app.dicomExportPath = uigetdir(app.dicomExportPath,'DICOM export path');
                        app.P2ROUD_UIFigure.Visible = 'on';
                        drawnow;
                        if ~ischar(app.dicomExportPath)
                            ME = MException('P2ROUD:userCancel','User cancel ...');
                            throw(ME);
                        end
                        
                        % Export without image orientation information
                        app.pd.exportPath = exportDicomMRD(app, app.tag);
                        app.TextMessage(strcat("DICOM export path = ",app.pd.exportPath," ..."),1);
                        app.pd.ExportRecoParametersFcn(app);

                    end

                    % Try additional nifti export (beta)
                    try
                        app.pd.exportPath = exportNiftiFcn(app);
                        app.pd.ExportRecoParametersFcn(app);
                    catch ME
                        app.TextMessage(ME.message,2);
                        app.TextMessage("ERROR: NIfti export failed ...",1);
                    end

                    % Success
                    app.TextMessage('Ready ...');
                    app.SetStatus(0);
                    app.SetLightsFcn({'Null','Null','Green','Null','Null','Null'});
                    SetButtonsFcn(app,{'On','On','On','On','On','On','On'});
                    app.EnableLabelsFcn;

                catch ME

                    % Failure
                    app.TextMessage(ME.message,2);
                    app.TextMessage('ERROR: Something went wrong during DICOM export ...',1);
                    app.dicomExportPath = '';
                    app.SetStatus(2);
                    app.SetLightsFcn({'Null','Null','Red','Null','Null','Null'});
                    SetButtonsFcn(app,{'On','On','On','On','On','On','On'});
                    app.EnableLabelsFcn;
                end

            end

        end

        % Button pushed function: ExportNIFTIButton
        function ExportNIFTIButtonPushed(app, event)

            if app.pd.validReco_flag && app.validNIFTIdataFlag

                try

                    % Set buttons
                    app.SwitchOffAllButtonsFcn;
                    app.SetLightsFcn({'Null','Null','Null','Yellow','Null','Null'});
                    app.DisableLabelsFcn;
                    app.SetStatus(3);

                    app.TextMessage('Exporting Nifti data ...',1);

                    % Make base / data / study / Nifti
                    dataDirExists = false;
                    try
                        if contains(app.mrdImportPath, "MRD")
                            app.niftiExportPath = replace(app.mrdImportPath, "MRD", "Data");
                            k = strfind(app.niftiExportPath,"Data");
                            fs = strfind(app.niftiExportPath(k:end),filesep);
                            app.niftiExportPath = app.niftiExportPath(1:k+fs(2)-1);
                            app.niftiExportPath = strcat(app.niftiExportPath,filesep,"Nifti",filesep);
                            app.niftiExportPath = strrep(app.niftiExportPath, strcat(filesep,filesep), filesep);
                            dataDirExists = true;
                        end
                    catch
                    end

                    % Ask for directory if Nifti directory does not exists
                    if dataDirExists
                        try
                            % Check if directory is actually writable
                            mkdir(strcat(app.niftiExportPath,filesep,"testdir"));
                            rmdir(strcat(app.niftiExportPath,filesep,"testdir"));
                        catch
                            app.P2ROUD_UIFigure.Visible = 'off';
                            app.niftiExportPath = uigetdir(app.niftiExportPath,'NIFTI export folder');
                            app.P2ROUD_UIFigure.Visible = 'on';
                            drawnow;
                            if ~ischar(app.niftiExportPath)
                                ME = MException('PROUD:userCancel','User cancel ...');
                                throw(ME);
                            end
                            app.niftiExportPath = strcat(app.niftiExportPath,filesep,"Nifti",filesep);
                        end
                    else

                        app.P2ROUD_UIFigure.Visible = 'off';
                        app.niftiExportPath = uigetdir(app.niftiExportPath,'NIFTI export path');
                        app.P2ROUD_UIFigure.Visible = 'on';
                        drawnow;
                        if ~ischar(app.niftiExportPath)
                            ME = MException('PROUD:userCancel','User cancel ...');
                            throw(ME);
                        end
                        app.niftiExportPath = strcat(app.niftiExportPath,filesep,"Nifti",filesep);
                    end

                    %---- Anatomical NIFTI file ----

                    % Normalize images and determine dimensions
                    image4D = squeeze(app.pd.images);
                    image4D = squeeze(image4D(:,:,:,:,1,1));
                    im = round(app.pd.maxImage * image4D / max(image4D(:)));
                    im = cast(im,'single');

                    % Phase orientation  --- only in the last step, I think
                    if app.pd.PHASE_ORIENTATION == 1
                        app.TextMessage('INFO: phase orientation = 1 ...',1);
                        im = permute(rot90(permute(im,[2 1 3 4]),1),[2 1 3 4]);
                        FOVx = app.FOVViewField2.Value;
                        FOVy = app.FOVViewField1.Value;
                    else
                        FOVx = app.FOVViewField1.Value;
                        FOVy = app.FOVViewField2.Value;
                    end

                    % Of course orientations are never correct and something needs to be flipped
                    im = flip(im,1);
                    [dimx,dimy,dimz,nrFrames] = size(im);

                    % Set some parameters
                    app.CalcTotalAcquisitionTimeFcn;
                    app.pd.timeperframe = 0;
                    if nrFrames>1
                        app.pd.timeperframe = app.pd.totalAcqTime/app.NREditField.Value;
                    end
                    load('niftiinfo.mat'); %#ok<LOAD>
                    info.Filemoddate = datestr(datetime);
                    info.Datatype = 'single';
                    info.BitsPerPixel = 32;
                    info.Filesize = dimx*dimy*dimz*nrFrames*32;

                    switch app.pd.dataType
                        case {"2D","2Dradial","2Depi"}
                            zdim = app.FOVViewField3.Value;
                        case {"3D","3Dute","ZTE"}
                            zdim = app.FOVViewField3.Value/dimz;
                    end

                    % Time per dynamic frame
                    tdim = app.pd.timeperframe;
                    if size(app.pd.images,6) > 1 % movie, multi-echo
                        tdim = app.TRViewField.Value;
                    end

                    if nrFrames>1
                        info.ImageSize = [dimx dimy dimz nrFrames];
                        info.PixelDimensions = [FOVx/dimx FOVy/dimy zdim tdim];
                    else
                        info.ImageSize = [dimx dimy dimz];
                        info.PixelDimensions = [FOVx/dimx FOVy/dimy zdim];
                    end

                    info.raw.dim = [3 dimx dimy dimz nrFrames 1 1 1 1];
                    info.raw.pixdim = [FOVx/dimx FOVy/dimy zdim tdim 0 0 0 0];
                    info.vox_offset = 0;

                    %---- Flow data NIFTI file ----

                    if app.pd.validFlow_flag

                        % Normalize images and determine dimensions
                        imf = app.pd.flowImages(:,:,:,1,1,:,:);
                        imf = permute(imf,[1 2 3 6 7 4 5]);
                        imf = cast(imf,'single');

                        % Phase orientation
                        if app.pd.PHASE_ORIENTATION == 1
                            imf = permute(rot90(permute(imf,[2 1 3 4 5]),1),[2 1 3 4 5]);
                        end

                        % Of course orientations are never correct and something needs to be flipped
                        imf = flip(imf,1);
                        [dimx,dimy,dimz,nrFramesflow,~] = size(imf);

                        % Set some parameters
                        app.pd.timeperframe = 0;
                        if nrFramesflow>1
                            app.pd.timeperframe = app.pd.tr;
                        end
                        load('niftiinfo1.mat'); %#ok<LOAD>
                        info1.Filemoddate = datestr(datetime);
                        info1.Datatype = 'single';
                        info1.BitsPerPixel = 32;
                        info1.Filesize = dimx*dimy*dimz*nrFramesflow*32;

                        switch app.pd.dataType
                            case "2D"
                                zdim = app.FOVViewField3.Value;
                            case {"3D","3Dute","ZTE"}
                                zdim = app.FOVViewField3.Value/dimz;
                        end

                        if nrFramesflow>1
                            info1.ImageSize = [dimx dimy dimz nrFramesflow];
                            info1.PixelDimensions = [FOVx/dimx FOVy/dimy zdim app.pd.tr];
                        else
                            info1.ImageSize = [dimx dimy dimz];
                            info1.PixelDimensions = [FOVx/dimx FOVy/dimy zdim];
                        end

                        info1.raw.dim = [3 dimx dimy dimz nrFramesflow 1 1 1 1];
                        info1.raw.pixdim = [FOVx/dimx FOVy/dimy zdim app.pd.tr 0 0 0 0];
                        info1.vox_offset = 0;

                    end

                    % Create new directory
                    ready = false;
                    cnt = 1;
                    while ~ready
                        app.pd.exportPath = strcat(app.niftiExportPath,filesep,app.tag,"P",filesep,num2str(cnt),filesep);
                        if ~exist(app.pd.exportPath, 'dir')
                            mkdir(app.pd.exportPath);
                            ready = true;
                        end
                        cnt = cnt + 1;
                    end
                    app.TextMessage(strcat("NIFTI export folder = ",app.pd.exportPath," ..."),1);

                    info.Filename = strcat(app.tag,'.nii');
                    niftiwrite(im,strcat(app.pd.exportPath,filesep,app.tag,'.nii'),info);
                    if app.pd.validFlow_flag
                        info1.Filename = strcat(app.tag,'Vz.nii');
                        niftiwrite(squeeze(imf(:,:,:,:,1)),strcat(app.pd.exportPath,filesep,app.tag,"P",'Vz.nii'),info1);
                        info1.Filename = strcat(app.tag,'Vy.nii');
                        niftiwrite(squeeze(imf(:,:,:,:,2)),strcat(app.pd.exportPath,filesep,app.tag,"P",'Vy.nii'),info1);
                        info1.Filename = strcat(app.tag,'Vx.nii');
                        niftiwrite(squeeze(imf(:,:,:,:,3)),strcat(app.pd.exportPath,filesep,app.tag,"P",'Vx.nii'),info1);
                        info1.Filename = strcat(app.tag,'Vm.nii');
                        niftiwrite(squeeze(imf(:,:,:,:,5)),strcat(app.pd.exportPath,filesep,app.tag,"P",'Vm.nii'),info1);
                    end
                    app.pd.ExportRecoParametersFcn(app);

                    % Success
                    app.TextMessage('NIFTI export finished ...',1);
                    app.SetStatus(0);
                    app.SetLightsFcn({'Null','Null','Null','Green','Null','Null'});
                    SetButtonsFcn(app,{'On','On','On','On','On','On','On'});
                    app.EnableLabelsFcn;

                catch ME

                    % Failure
                    app.TextMessage(ME.message,2);
                    app.TextMessage('ERROR: Something went wrong during NIFTI export ...',1);
                    app.niftiExportPath = '';
                    app.SetStatus(2);
                    app.SetLightsFcn({'Null','Null','Null','Red','Null','Null'});
                    SetButtonsFcn(app,{'On','On','On','On','On','On','On'});
                    app.EnableLabelsFcn;

                end

            else

                app.TextMessage("No suitable 3D data for Nifti export available ...",1);

            end

        end

        % Button pushed function: ExportMRDButton
        function ExportMRDButtonPushed(app, event)

            try

                % Set buttons
                app.SwitchOffAllButtonsFcn;
                app.SetLightsFcn({'Null','Null','Null','Null','Yellow','Null'});
                app.DisableLabelsFcn;
                app.SetStatus(3);

                % Indicate busy
                app.TextMessage('MRD file export ...',1);

                % Make a k-space from the movie
                % Dimensions = (samples, views, views2, slices, echoes (frames), experiments, flip-angles)
                app.pd = app.pd.backToKspace;
               
                % Parameters for mrd footer
                par = app.pd.replaceMRDparameters(app);

                % Construct a new footer for the MRD file
                app.pd = app.pd.makeMrdFooter(par);

                % Construct a new rpr file if input rpr file was available
                if app.pd.rprFile_flag
                    app.pd = app.pd.makeRprFile(par);
                end

                % Auto export the new MRD and rpr files
                app.mrdExportPath = app.mrdImportPath;

                % Check if directory is actually writable, else ask for different folder
                try
                    mkdir(strcat(app.mrdExportPath,filesep,"testdir"));
                    rmdir(strcat(app.mrdExportPath,filesep,"testdir"));
                catch
                    app.P2ROUD_UIFigure.Visible = 'off';
                    app.mrdExportPath = uigetdir(app.mrdExportPath,'MRD export folder');
                    app.P2ROUD_UIFigure.Visible = 'on';
                    if ~ischar(app.mrdExportPath)
                        ME = MException('P2ROUD:userCancel','User cancel ...');
                        throw(ME);
                    end
                end
    
                % Make a unique file name
                if contains(app.tag,'p2roud')
                    k = strfind(app.tag,'_');
                    app.tag = extractAfter(app.tag,k(2));
                end
                cnt = 1;
                while true
                    mrdfilename = ['p2roud_',num2str(cnt),'_',app.tag,'.MRD'];
                    rprfilename = ['p2roud_',num2str(cnt),'_',app.tag,'.rpr'];
                    mrdexportfilename = [app.mrdExportPath,mrdfilename];
                    rprexportfilename = [app.mrdExportPath,rprfilename];
                    if ~isfile(mrdexportfilename)
                        break;
                    end
                    cnt = cnt + 1;
                end

                % Try the export in the MRD input directory
                app.pd.writeDataToMrd(mrdexportfilename, par);
                app.pd.exportPath = app.mrdExportPath;
                app.pd.ExportRecoParametersFcn(app);
                if app.pd.rprFile_flag
                    app.pd.writeToRprFile(rprexportfilename);
                end

                % Backup rtable file
                if isfile(app.rtableFile)
                    [rtablePath,rtableName,rtableExt] = fileparts(app.rtableFile);
                    if rtableName == "rtable"
                        movefile(strcat(rtablePath,filesep,rtableName,rtableExt),strcat(rtablePath,filesep,'origrtable.rtv.orig'));
                        delete(strcat(rtablePath,filesep,rtableName,rtableExt));
                    end
                end

                % Try to autoreconstruct and export of SUR files, only try when platform is pc
                if ispc

                    % Determine SUR directory
                    surPathFound = false;
                    try

                        scanPos = strfind(app.mrdImportPath,filesep);
                        scanTag = strcat(app.mrdImportPath(scanPos(end-2)+1:scanPos(end-1)-1),filesep,'Image',filesep,app.mrdImportPath(scanPos(end-1)+1:scanPos(end)-1));
                        surPath = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','BaseStorageDirectory');
                        surPath1 = strcat(surPath,filesep,scanTag,filesep);
                        surPath2 = strcat(surPath,filesep,scanTag,filesep,'1',filesep);
                        % Check if directory is actually writable
                        mkdir(strcat(surPath2,filesep,"testdir"));
                        rmdir(strcat(surPath2,filesep,"testdir"));
                        app.TextMessage('Image directory found in Windows registry ...',1);
                        surPathFound = true;

                    catch
                    end

                    % If not found automatically, try different way
                    if ~surPathFound

                        try

                            surPath = replace(app.mrdImportPath,"MRD","Data");
                            k = strfind(surPath,"Data");
                            fs = strfind(surPath(k:end),filesep);
                            surPath = surPath(1:k+fs(3)-1);
                            imageSlash = strcat("Image",filesep);
                            surPath1 = insertAfter(surPath,k+fs(2)-1,imageSlash);
                            surPath1 = strrep(surPath1,dblSlash,filesep);
                            surPath2 = strcat(surPath1,filesep,'1',filesep);
                            surPath2 = strrep(surPath2,dblSlash,filesep);
                            % Check if directory is actually writable
                            mkdir(strcat(surPath2,filesep,"testdir"));
                            rmdir(strcat(surPath2,filesep,"testdir"));
                            surPathFound = true;

                        catch
                        end

                    end

                    if surPathFound
                        
                        try
                        
                            surPath = surPath1;
                            if (exist(surPath2, 'dir'))
                                surPath = surPath2;
                            end

                            app.TextMessage(strcat("SUR directory = ",surPath," ..."),1);

                            % SUR file names
                            sursuffix = app.tag;

                            % Make and save the new SUR files
                            app.pd.recoSurFiles(surPath,sursuffix,mrdexportfilename,rprexportfilename);

                        catch ME1

                            app.TextMessage(ME1.message,2);
                            app.TextMessage('WARNING: Automatic reconstruction of SUR files failed ...',1);
                            app.SetStatus(1);

                        end

                        if app.pd.trajType == "NRLUT"

                            try

                                % Create new DCM files from SUR files
                                app.pd.surToDcm(app, surPath);

                            catch ME2

                                app.TextMessage(ME2.message,2);
                                app.TextMessage('WARNING: Automatic reconstruction of DCM files failed ...',1);
                                app.SetStatus(1);

                            end

                        end

                    end

                end

                % Success
                app.TextMessage('MRD export successfully completed ...',1);
                app.SetLightsFcn({'Null','Null','Null','Null','Green','Null'});
                SetButtonsFcn(app,{'On','On','On','On','On','On','On'});
                app.EnableLabelsFcn;
                app.SetStatus(0);

            catch ME

                % Failures
                app.TextMessage(ME.message,2);
                app.TextMessage('ERROR: Something went wrong during MRD export ...',1);
                app.mrdExportPath = '';
                app.SetLightsFcn({'Null','Null','Null','Null','Red','Null'});
                SetButtonsFcn(app,{'On','On','On','On','On','On','On'});
                app.EnableLabelsFcn;
                app.SetStatus(2);

            end

        end

        % Button pushed function: ExportGIFButton
        function ExportGIFButtonPushed(app, event)

            if app.pd.validReco_flag

                try

                    % Set buttons
                    app.SwitchOffAllButtonsFcn;
                    app.SetLightsFcn({'Null','Null','Null','Null','Null','Yellow'});
                    app.DisableLabelsFcn;
                    app.SetStatus(3);

                    % Indicate busy
                    app.SetStatus(3);
                    app.TextMessage('Exporting to GIF ...',1);

                    % Make base / data / study / GIF / directory
                    try
                        gifExportDir = replace(app.mrdImportPath, "MRD", "Data");
                        k = strfind(gifExportDir,"Data");
                        fs = strfind(gifExportDir(k:end),filesep);
                        gifExportDir = gifExportDir(1:k+fs(2)-1);
                        % Check if directory is actually writable
                        mkdir(strcat(gifExportDir,filesep,"testdir"));
                        rmdir(strcat(gifExportDir,filesep,"testdir"));
                    catch
                        app.P2ROUD_UIFigure.Visible = 'off';
                        gifExportDir = uigetdir(app.gifExportPath,'GIF export path');
                        app.P2ROUD_UIFigure.Visible = 'on';
                        drawnow;
                        if ~ischar(gifExportDir)
                            ME = MException('PROUD:userCancel','User cancel ...');
                            throw(ME);
                        end
                    end
                    app.gifExportPath = gifExportDir;

                    gifExportDir= exportGifProud(app);
                    app.pd.exportPath = gifExportDir;
                    app.pd.ExportRecoParametersFcn(app);

                    app.TextMessage('GIF export finished ...',1);
                    app.SetLightsFcn({'Null','Null','Null','Null','Null','Green'});
                    SetButtonsFcn(app,{'On','On','On','On','On','On','On'});
                    app.EnableLabelsFcn;
                    app.SetStatus(0);

                catch ME

                    app.TextMessage('ERROR: Something went wrong during GIF export ...',1);
                    app.TextMessage(ME.message,2);
                    app.gifExportPath = '';
                    app.SetLightsFcn({'Null','Null','Null','Null','Null','Red'});
                    SetButtonsFcn(app,{'On','On','On','On','On','On','On'});
                    app.EnableLabelsFcn;
                    app.SetStatus(2);

                end

            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
         
            app.DisableLabelsFcn;

            % Remove subfolders from search path
            if (~isdeployed)
                info = dir(which('P2ROUD.mlapp'));
                app.appDir = info.folder;
                rmpath(genpath(app.appDir));
                cd(app.oldDir);
            end

            % Delete python cache files
            try
                delete(strcat(app.appDir,filesep,'DLdenoising',filesep,'utils',filesep,'__pycache__',filesep,'*.pyc'));
            catch
            end

            try
                delete(strcat(app.appDir,filesep,'DLdenoising',filesep,'models',filesep,'__pycache__',filesep,'*.pyc'));
            catch
            end

            try
                delete(strcat(app.appDir,filesep,'DLdenoising',filesep,'models',filesep,'op',filesep,'__pycache__',filesep,'*.pyc'));
            catch
            end

            % Exit
            app.delete;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.P2ROUD_UIFigure = uifigure('Visible', 'off');
            app.P2ROUD_UIFigure.Icon = fullfile(pathToMLAPP, 'Picon.png');
            app.P2ROUD_UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @P2ROUD_UIFigureWindowScrollWheel, true);
            app.TitleLabel = uilabel(app.P2ROUD_UIFigure);
            app.TitleLabel.Text = 'P2ROUD';
            app.TulpImage = uiimage(app.P2ROUD_UIFigure);
            app.CreditsLabel = uilabel(app.P2ROUD_UIFigure);
            app.CreditsLabel.Tooltip = {'g.j.strijkers@amsterdamumc.nl'};
            app.CreditsLabel.Text = 'Gustav Strijkers, Bram Coolen, Amsterdam UMC, Version 6.0.0';
            app.MRSImage = uiimage(app.P2ROUD_UIFigure);
            app.P2ROUDLabel = uilabel(app.P2ROUD_UIFigure);
            app.P2ROUDLabel.Text = 'Preclinical PROspective Undersampling in multiple Dimensions';
            app.DashboardTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.DashboardTab = uitab(app.DashboardTabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadMRDButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDButtonPushed, true);
            app.LoadMRDButton.Enable = 'off';
            app.LoadMRDButton.Tooltip = {'Load MRD data'};
            app.LoadMRDButton.Text = 'Load MRD';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.ReconstructionDimensionsPanel = uipanel(app.DashboardTab);
            app.ReconstructionDimensionsPanel.Tooltip = {'Dimensions'};
            app.ReconstructionDimensionsPanel.Title = 'Dimensions';
            app.XEditField = uieditfield(app.ReconstructionDimensionsPanel, 'numeric');
            app.XEditField.Limits = [16 1024];
            app.XEditField.RoundFractionalValues = 'on';
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.Tooltip = {'Readout dimension'};
            app.XEditField.Value = 128;
            app.YEditField = uieditfield(app.ReconstructionDimensionsPanel, 'numeric');
            app.YEditField.Limits = [16 1024];
            app.YEditField.RoundFractionalValues = 'on';
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.Tooltip = {'Phase encoding dimension'};
            app.YEditField.Value = 128;
            app.ZEditField = uieditfield(app.ReconstructionDimensionsPanel, 'numeric');
            app.ZEditField.Limits = [1 1024];
            app.ZEditField.RoundFractionalValues = 'on';
            app.ZEditField.ValueDisplayFormat = '%.0f';
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @RecoSizeFieldValueChanged, true);
            app.ZEditField.Tooltip = {'Slice dimension / 2nd phase-encoding dimension'};
            app.ZEditField.Value = 128;
            app.NREditField = uieditfield(app.ReconstructionDimensionsPanel, 'numeric');
            app.NREditField.Limits = [1 1024];
            app.NREditField.RoundFractionalValues = 'on';
            app.NREditField.ValueDisplayFormat = '%.0f';
            app.NREditField.ValueChangedFcn = createCallbackFcn(app, @NREditFieldValueChanged, true);
            app.NREditField.Tooltip = {'Number of dynamics/repetitions'};
            app.NREditField.Value = 1;
            app.XEditFieldLabel = uilabel(app.ReconstructionDimensionsPanel);
            app.XEditFieldLabel.Text = 'X';
            app.YEditFieldLabel = uilabel(app.ReconstructionDimensionsPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.ZEditFieldLabel = uilabel(app.ReconstructionDimensionsPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.NREditFieldLabel = uilabel(app.ReconstructionDimensionsPanel);
            app.NREditFieldLabel.Text = 'NR';
            app.ReconstructionParametersPanel = uipanel(app.DashboardTab);
            app.ReconstructionParametersPanel.Tooltip = {'Reconstruction'};
            app.ReconstructionParametersPanel.Title = 'Reconstruction';
            app.RecoimagesButton = uibutton(app.ReconstructionParametersPanel, 'push');
            app.RecoimagesButton.ButtonPushedFcn = createCallbackFcn(app, @RecoImagesButtonPushed, true);
            app.RecoimagesButton.Enable = 'off';
            app.RecoimagesButton.Tooltip = {'Reconstruct the images'};
            app.RecoimagesButton.Text = 'Reco images';
            app.Lamp2 = uilamp(app.ReconstructionParametersPanel);
            app.RecoProgressGauge = uigauge(app.ReconstructionParametersPanel, 'linear');
            app.RecoProgressGauge.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95.1];
            app.AbortRecoButton = uibutton(app.ReconstructionParametersPanel, 'push');
            app.AbortRecoButton.ButtonPushedFcn = createCallbackFcn(app, @AbortRecoButtonPushed, true);
            app.AbortRecoButton.Enable = 'off';
            app.AbortRecoButton.Tooltip = {'Abort the reconstruction'};
            app.AbortRecoButton.Text = 'abort';
            app.ExportPanel = uipanel(app.DashboardTab);
            app.ExportPanel.Tooltip = {'Export images'};
            app.ExportPanel.Title = 'Export';
            app.ExportDCMButton = uibutton(app.ExportPanel, 'push');
            app.ExportDCMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDCMButtonPushed, true);
            app.ExportDCMButton.Enable = 'off';
            app.ExportDCMButton.Tooltip = {'Export to DICOM'};
            app.ExportDCMButton.Text = 'Export DCM';
            app.ExportProgressGauge = uigauge(app.ExportPanel, 'linear');
            app.ExportProgressGauge.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.ExportNIFTIButton = uibutton(app.ExportPanel, 'push');
            app.ExportNIFTIButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNIFTIButtonPushed, true);
            app.ExportNIFTIButton.Enable = 'off';
            app.ExportNIFTIButton.Tooltip = {'Export to NIFTI'};
            app.ExportNIFTIButton.Text = 'Export NIFTI';
            app.ExportMRDButton = uibutton(app.ExportPanel, 'push');
            app.ExportMRDButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMRDButtonPushed, true);
            app.ExportMRDButton.Enable = 'off';
            app.ExportMRDButton.Tooltip = {'Export to MRD file'};
            app.ExportMRDButton.Text = 'Export MRD';
            app.ExportGIFButton = uibutton(app.ExportPanel, 'push');
            app.ExportGIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIFButton.Enable = 'off';
            app.ExportGIFButton.Tooltip = {'Export to GIF'};
            app.ExportGIFButton.Text = 'Export GIF';
            app.Lamp3 = uilamp(app.ExportPanel);
            app.Lamp4 = uilamp(app.ExportPanel);
            app.Lamp5 = uilamp(app.ExportPanel);
            app.Lamp6 = uilamp(app.ExportPanel);
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Tooltip = {'Exit the app'};
            app.ExitButton.Text = '    Exit';
            app.CSRecoCheckBox = uicheckbox(app.DashboardTab);
            app.CSRecoCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.CSRecoCheckBox.Tooltip = {'Check for compressed sensing reconstruction'};
            app.CSRecoCheckBox.Text = '';
            app.CSRecoLabel = uilabel(app.DashboardTab);
            app.CSRecoLabel.Tooltip = {'Compressed-sensing reconstruction'};
            app.CSRecoLabel.Text = 'CS reco';
            app.KspaceTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.KspaceTab = uitab(app.KspaceTabGroup);
            app.KspaceTab.Title = 'K-space';
            app.KspaceFig = uiaxes(app.KspaceTab);
            app.KspaceFig.XTick = [];
            app.KspaceFig.YTick = [];
            app.KDim3EditField = uieditfield(app.KspaceTab, 'numeric');
            app.KDim3EditField.Limits = [1 Inf];
            app.KDim3EditField.ValueDisplayFormat = '%.0f';
            app.KDim3EditField.ValueChangedFcn = createCallbackFcn(app, @KDim3EditFieldValueChanged, true);
            app.KDim3EditField.Value = 1;
            app.KDim3Slider = uislider(app.KspaceTab);
            app.KDim3Slider.Limits = [1 200];
            app.KDim3Slider.MajorTicks = [];
            app.KDim3Slider.ValueChangedFcn = createCallbackFcn(app, @KDim3SliderValueChanged, true);
            app.KDim3Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.KDim3Slider.Value = 1;
            app.KDim4Slider = uislider(app.KspaceTab);
            app.KDim4Slider.Limits = [1 200];
            app.KDim4Slider.MajorTicks = [];
            app.KDim4Slider.ValueChangedFcn = createCallbackFcn(app, @KDim4SliderValueChanged, true);
            app.KDim4Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.KDim4Slider.Value = 1;
            app.KDim4EditField = uieditfield(app.KspaceTab, 'numeric');
            app.KDim4EditField.Limits = [1 Inf];
            app.KDim4EditField.ValueDisplayFormat = '%.0f';
            app.KDim4EditField.ValueChangedFcn = createCallbackFcn(app, @KDim4EditFieldValueChanged, true);
            app.KDim4EditField.Value = 1;
            app.KxLabel = uilabel(app.KspaceTab);
            app.KxLabel.Text = '     Kx     ';
            app.KyLabel = uilabel(app.KspaceTab);
            app.KyLabel.Text = '     Ky     ';
            app.ButtonGroup = uibuttongroup(app.KspaceTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.kValuesButton = uiradiobutton(app.ButtonGroup);
            app.kValuesButton.Tooltip = {'Check to display K-space'};
            app.kValuesButton.Text = '| k-values |';
            app.kValuesButton.Interpreter = 'html';
            app.kValuesButton.Value = true;
            app.averagesButton = uiradiobutton(app.ButtonGroup);
            app.averagesButton.Tooltip = {'Check to display K-space averages'};
            app.averagesButton.Text = 'averages';
            app.averagesButton.Interpreter = 'html';
            app.fillingButton = uiradiobutton(app.ButtonGroup);
            app.fillingButton.Tooltip = {'Check to display K-space filling'};
            app.fillingButton.Text = 'filling';
            app.fillingButton.Interpreter = 'html';
            app.senseMapButton = uiradiobutton(app.ButtonGroup);
            app.senseMapButton.Tooltip = {'Check to display K-space'};
            app.senseMapButton.Text = 'sense map';
            app.senseMapButton.Interpreter = 'html';
            app.KDim3Label = uilabel(app.KspaceTab);
            app.KDim3Label.Text = '            Kz';
            app.KEchoSlider = uislider(app.KspaceTab);
            app.KEchoSlider.Limits = [1 200];
            app.KEchoSlider.MajorTicks = [];
            app.KEchoSlider.ValueChangedFcn = createCallbackFcn(app, @KEchoSliderValueChanged, true);
            app.KEchoSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.KEchoSlider.Value = 1;
            app.KDim4Label = uilabel(app.KspaceTab);
            app.KDim4Label.Text = '             NR';
            app.KFlipAngleSlider = uislider(app.KspaceTab);
            app.KFlipAngleSlider.Limits = [1 200];
            app.KFlipAngleSlider.MajorTicks = [];
            app.KFlipAngleSlider.ValueChangedFcn = createCallbackFcn(app, @KFlipAngleSliderValueChanged, true);
            app.KFlipAngleSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.KFlipAngleSlider.Value = 1;
            app.FillingViewField = uieditfield(app.KspaceTab, 'numeric');
            app.FillingViewField.Editable = 'off';
            app.FillingViewField.Tooltip = {'K-space filling percentage'};
            app.FillingPercLabel = uilabel(app.KspaceTab);
            app.FillingPercLabel.Text = '%';
            app.KStartButton = uibutton(app.KspaceTab, 'push');
            app.KStartButton.ButtonPushedFcn = createCallbackFcn(app, @KStartButtonPushed, true);
            app.KStartButton.Tooltip = {'Start the K-space trajectory movie'};
            app.KStartButton.Text = 'Start';
            app.KStopButton = uibutton(app.KspaceTab, 'push');
            app.KStopButton.ButtonPushedFcn = createCallbackFcn(app, @KStopButtonPushed, true);
            app.KStopButton.Tooltip = {'Stop the K-space trajectory movie'};
            app.KStopButton.Text = 'Stop';
            app.KspaceMovieLabel = uilabel(app.KspaceTab);
            app.KspaceMovieLabel.Text = {'K-space'; 'movie'};
            app.FastSlowSwitch = uiswitch(app.KspaceTab, 'rocker');
            app.FastSlowSwitch.Items = {'fast', 'slow'};
            app.FastSlowSwitch.ValueChangedFcn = createCallbackFcn(app, @FastSlowSwitchValueChanged, true);
            app.FastSlowSwitch.Tooltip = {'Slow or fast K-space movie'};
            app.FastSlowSwitch.Value = 'fast';
            app.KLevelSlider = uislider(app.KspaceTab);
            app.KLevelSlider.Limits = [0 65536];
            app.KLevelSlider.MajorTicks = [];
            app.KLevelSlider.ValueChangedFcn = createCallbackFcn(app, @KLevelSliderValueChanged, true);
            app.KLevelSlider.MinorTicks = [0 8192 16384 24576 32768 40960 49152 57344 65536];
            app.KLevelSlider.Tooltip = {'Level slider'};
            app.KWLabel = uilabel(app.KspaceTab);
            app.KWLabel.Text = 'W';
            app.KLLabel = uilabel(app.KspaceTab);
            app.KLLabel.Text = 'L';
            app.KWindowSlider = uislider(app.KspaceTab);
            app.KWindowSlider.Limits = [0 65536];
            app.KWindowSlider.MajorTicks = [];
            app.KWindowSlider.ValueChangedFcn = createCallbackFcn(app, @KWindowSliderValueChanged, true);
            app.KWindowSlider.MinorTicks = [0 8192 16384 24576 32768 40960 49152 57344 65536];
            app.KWindowSlider.Tooltip = {'Window slider'};
            app.KLevelEditField = uieditfield(app.KspaceTab, 'numeric');
            app.KLevelEditField.Limits = [0 Inf];
            app.KLevelEditField.ValueDisplayFormat = '%.0f';
            app.KLevelEditField.ValueChangedFcn = createCallbackFcn(app, @KLevelEditFieldValueChanged, true);
            app.KLevelEditField.Tooltip = {'Level'};
            app.KWindowEditField = uieditfield(app.KspaceTab, 'numeric');
            app.KWindowEditField.Limits = [0 Inf];
            app.KWindowEditField.ValueDisplayFormat = '%.0f';
            app.KWindowEditField.ValueChangedFcn = createCallbackFcn(app, @KWindowEditFieldValueChanged, true);
            app.KWindowEditField.Tooltip = {'Window'};
            app.KEchoEditFieldLabel = uilabel(app.KspaceTab);
            app.KEchoEditFieldLabel.Text = 'echo';
            app.KEchoEditField = uieditfield(app.KspaceTab, 'numeric');
            app.KEchoEditField.Limits = [1 Inf];
            app.KEchoEditField.ValueDisplayFormat = '%.0f';
            app.KEchoEditField.ValueChangedFcn = createCallbackFcn(app, @KEchoEditFieldValueChanged, true);
            app.KEchoEditField.Value = 1;
            app.KOrientationSpinnerLabel = uilabel(app.KspaceTab);
            app.KOrientationSpinnerLabel.Text = 'orientation';
            app.KOrientationSpinner = uispinner(app.KspaceTab);
            app.KOrientationSpinner.Limits = [1 6];
            app.KOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @KOrientationSpinnerValueChanged, true);
            app.KOrientationSpinner.Tooltip = {'Image orientation'};
            app.KOrientationSpinner.Value = 1;
            app.KFlipAngleEditFieldLabel = uilabel(app.KspaceTab);
            app.KFlipAngleEditFieldLabel.Text = 'FA';
            app.KFlipAngleEditField = uieditfield(app.KspaceTab, 'numeric');
            app.KFlipAngleEditField.Limits = [1 Inf];
            app.KFlipAngleEditField.ValueDisplayFormat = '%.0f';
            app.KFlipAngleEditField.ValueChangedFcn = createCallbackFcn(app, @KFlipAngleEditFieldValueChanged, true);
            app.KFlipAngleEditField.Value = 1;
            app.CoilSpinnerLabel = uilabel(app.KspaceTab);
            app.CoilSpinnerLabel.Text = 'Coil';
            app.CoilSpinner = uispinner(app.KspaceTab);
            app.CoilSpinner.Limits = [1 16];
            app.CoilSpinner.ValueDisplayFormat = '%.0f';
            app.CoilSpinner.ValueChangedFcn = createCallbackFcn(app, @CoilSpinnerValueChanged, true);
            app.CoilSpinner.Tooltip = {'Receiver coil'};
            app.CoilSpinner.Value = 1;
            app.CoilsTab = uitab(app.KspaceTabGroup);
            app.CoilsTab.Title = 'Coils';
            app.CoilSelectionPanel = uipanel(app.CoilsTab);
            app.CoilSelectionPanel.Tooltip = {'Receiver coil selection and relative sensitivities'};
            app.CoilSelectionPanel.Title = 'Coil selection';
            app.C1CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C1CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C1CheckBox.Text = ' 1';
            app.C1CheckBox.Value = true;
            app.C2CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C2CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C2CheckBox.Text = ' 2';
            app.C3CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C3CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C3CheckBox.Text = ' 3';
            app.C4CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C4CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C4CheckBox.Text = ' 4';
            app.C5CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C5CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C5CheckBox.Text = ' 5';
            app.C6CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C6CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C6CheckBox.Text = ' 6';
            app.C7CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C7CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C7CheckBox.Text = ' 7';
            app.C8CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C8CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C8CheckBox.Text = ' 8';
            app.S1EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S1EditField.Limits = [0.01 1];
            app.S1EditField.ValueDisplayFormat = '%.3f';
            app.S1EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S1EditField.Value = 1;
            app.S2EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S2EditField.Limits = [0.01 1];
            app.S2EditField.ValueDisplayFormat = '%.3f';
            app.S2EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S2EditField.Value = 1;
            app.S3EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S3EditField.Limits = [0.01 1];
            app.S3EditField.ValueDisplayFormat = '%.3f';
            app.S3EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S3EditField.Value = 1;
            app.S4EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S4EditField.Limits = [0.01 1];
            app.S4EditField.ValueDisplayFormat = '%.3f';
            app.S4EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S4EditField.Value = 1;
            app.S5EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S5EditField.Limits = [0.01 1];
            app.S5EditField.ValueDisplayFormat = '%.3f';
            app.S5EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S5EditField.Value = 1;
            app.S6EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S6EditField.Limits = [0.01 1];
            app.S6EditField.ValueDisplayFormat = '%.3f';
            app.S6EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S6EditField.Value = 1;
            app.S7EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S7EditField.Limits = [0.01 1];
            app.S7EditField.ValueDisplayFormat = '%.3f';
            app.S7EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S7EditField.Value = 1;
            app.S8EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S8EditField.Limits = [0.01 1];
            app.S8EditField.ValueDisplayFormat = '%.3f';
            app.S8EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S8EditField.Value = 1;
            app.CoilLabel = uilabel(app.CoilSelectionPanel);
            app.CoilLabel.Text = 'Coil';
            app.RelativeSensitivityLabel = uilabel(app.CoilSelectionPanel);
            app.RelativeSensitivityLabel.Text = 'Relative sensitivity';
            app.AutoSensitivityCheckBox = uicheckbox(app.CoilSelectionPanel);
            app.AutoSensitivityCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoSensitivityCheckBoxValueChanged, true);
            app.AutoSensitivityCheckBox.Tooltip = {'Automatically determine coil sensitivities (requires Bart toolbox)'};
            app.AutoSensitivityCheckBox.Text = ' auto sensitivity';
            app.AutoSensitivityCheckBox.Value = true;
            app.SensitivityMapsPanel = uipanel(app.CoilsTab);
            app.SensitivityMapsPanel.Tooltip = {'Receiver coil selection and relative sensitivities'};
            app.SensitivityMapsPanel.Title = 'Sensitivity maps';
            app.SenseFig1 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig1.XTick = [];
            app.SenseFig1.YTick = [];
            app.SenseFig2 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig2.XTick = [];
            app.SenseFig2.YTick = [];
            app.SenseFig3 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig3.XTick = [];
            app.SenseFig3.YTick = [];
            app.SenseFig4 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig4.XTick = [];
            app.SenseFig4.YTick = [];
            app.SenseFig5 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig5.XTick = [];
            app.SenseFig5.YTick = [];
            app.SenseFig6 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig6.XTick = [];
            app.SenseFig6.YTick = [];
            app.SenseFig7 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig7.XTick = [];
            app.SenseFig7.YTick = [];
            app.SenseFig8 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig8.XTick = [];
            app.SenseFig8.YTick = [];
            app.Label2 = uilabel(app.SensitivityMapsPanel);
            app.Label2.Text = '2';
            app.Label3 = uilabel(app.SensitivityMapsPanel);
            app.Label3.Text = '3';
            app.Label4 = uilabel(app.SensitivityMapsPanel);
            app.Label4.Text = '4';
            app.Label6 = uilabel(app.SensitivityMapsPanel);
            app.Label6.Text = '6';
            app.Label7 = uilabel(app.SensitivityMapsPanel);
            app.Label7.Text = '7';
            app.Label8 = uilabel(app.SensitivityMapsPanel);
            app.Label8.Text = '8';
            app.Label1 = uilabel(app.SensitivityMapsPanel);
            app.Label1.Text = '1';
            app.Label5 = uilabel(app.SensitivityMapsPanel);
            app.Label5.Text = '5';
            app.KDim3SliderS = uislider(app.SensitivityMapsPanel);
            app.KDim3SliderS.Limits = [1 200];
            app.KDim3SliderS.MajorTicks = [];
            app.KDim3SliderS.ValueChangedFcn = createCallbackFcn(app, @KDim3SliderSValueChanged, true);
            app.KDim3SliderS.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.KDim3SliderS.Value = 1;
            app.KDim3LabelS = uilabel(app.CoilsTab);
            app.KDim3LabelS.Text = '            Kz';
            app.KDim3EditFieldS = uieditfield(app.CoilsTab, 'numeric');
            app.KDim3EditFieldS.Limits = [1 Inf];
            app.KDim3EditFieldS.ValueDisplayFormat = '%.0f';
            app.KDim3EditFieldS.ValueChangedFcn = createCallbackFcn(app, @KDim3EditFieldSValueChanged, true);
            app.KDim3EditFieldS.Value = 1;
            app.ScanParameterTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.ScanParametersTab = uitab(app.ScanParameterTabGroup);
            app.ScanParametersTab.Title = 'Scan parameters';
            app.KMatrixViewField2 = uieditfield(app.ScanParametersTab, 'numeric');
            app.KMatrixViewField2.Limits = [1 Inf];
            app.KMatrixViewField2.ValueDisplayFormat = '%.0f';
            app.KMatrixViewField2.Editable = 'off';
            app.KMatrixViewField2.Tooltip = {'Phase-encoding matrix size'};
            app.KMatrixViewField2.Value = 1;
            app.KMatrixViewField3 = uieditfield(app.ScanParametersTab, 'numeric');
            app.KMatrixViewField3.Limits = [1 Inf];
            app.KMatrixViewField3.ValueDisplayFormat = '%.0f';
            app.KMatrixViewField3.Editable = 'off';
            app.KMatrixViewField3.Tooltip = {'Number of slices / 2nd phase-encoding matrix size'};
            app.KMatrixViewField3.Value = 1;
            app.FOVViewField2 = uieditfield(app.ScanParametersTab, 'numeric');
            app.FOVViewField2.Limits = [0 Inf];
            app.FOVViewField2.ValueDisplayFormat = '%.1f';
            app.FOVViewField2.Editable = 'off';
            app.FOVViewField2.Tooltip = {'FOV in phas-encoding direction'};
            app.FOVViewField3 = uieditfield(app.ScanParametersTab, 'numeric');
            app.FOVViewField3.Limits = [0 Inf];
            app.FOVViewField3.ValueDisplayFormat = '%.1f';
            app.FOVViewField3.Editable = 'off';
            app.FOVViewField3.Tooltip = {'Slice thickness / FOV in 2nd phase-encoding direction'};
            app.MXLabel = uilabel(app.ScanParametersTab);
            app.MXLabel.Text = 'Kx';
            app.MYLabel = uilabel(app.ScanParametersTab);
            app.MYLabel.Text = 'Ky';
            app.MZLabel = uilabel(app.ScanParametersTab);
            app.MZLabel.Text = 'Kz';
            app.NRViewFieldLabel = uilabel(app.ScanParametersTab);
            app.NRViewFieldLabel.Text = 'NR';
            app.NRViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.NRViewField.Limits = [0 Inf];
            app.NRViewField.ValueDisplayFormat = '%.0f';
            app.NRViewField.Editable = 'off';
            app.NRViewField.Tooltip = {'Number of dynamics/repetitions'};
            app.SequenceViewFieldLabel = uilabel(app.ScanParametersTab);
            app.SequenceViewFieldLabel.Text = 'sequence';
            app.SequenceViewField = uieditfield(app.ScanParametersTab, 'text');
            app.SequenceViewField.Editable = 'off';
            app.SequenceViewField.Tooltip = {'MRI sequence name'};
            app.KMatrixViewField1Label = uilabel(app.ScanParametersTab);
            app.KMatrixViewField1Label.Text = 'matrix';
            app.KMatrixViewField1 = uieditfield(app.ScanParametersTab, 'numeric');
            app.KMatrixViewField1.Limits = [1 Inf];
            app.KMatrixViewField1.ValueDisplayFormat = '%.0f';
            app.KMatrixViewField1.Editable = 'off';
            app.KMatrixViewField1.Tooltip = {'Readout matrix size'};
            app.KMatrixViewField1.Value = 1;
            app.FOVViewField1Label = uilabel(app.ScanParametersTab);
            app.FOVViewField1Label.Text = 'FOV (mm)';
            app.FOVViewField1 = uieditfield(app.ScanParametersTab, 'numeric');
            app.FOVViewField1.ValueDisplayFormat = '%.1f';
            app.FOVViewField1.Editable = 'off';
            app.FOVViewField1.Tooltip = {'FOV in readout direction'};
            app.TRViewFieldLabel = uilabel(app.ScanParametersTab);
            app.TRViewFieldLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.TRViewField.ValueDisplayFormat = '%.2f';
            app.TRViewField.Editable = 'off';
            app.TRViewField.Tooltip = {'Repetition time'};
            app.TEViewFieldLabel = uilabel(app.ScanParametersTab);
            app.TEViewFieldLabel.Text = 'TE (ms)';
            app.TEViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.TEViewField.ValueDisplayFormat = '%.2f';
            app.TEViewField.Editable = 'off';
            app.TEViewField.Tooltip = {'Echo time'};
            app.NAViewFieldLabel = uilabel(app.ScanParametersTab);
            app.NAViewFieldLabel.Text = 'NA';
            app.NAViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.NAViewField.Editable = 'off';
            app.NAViewField.Tooltip = {'Number of averages'};
            app.NFAViewFieldLabel = uilabel(app.ScanParametersTab);
            app.NFAViewFieldLabel.Text = 'NFA';
            app.NFAViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.NFAViewField.Editable = 'off';
            app.NFAViewField.Tooltip = {'Number of flip-angles'};
            app.NEViewFieldLabel = uilabel(app.ScanParametersTab);
            app.NEViewFieldLabel.Text = 'NE';
            app.NEViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.NEViewField.Editable = 'off';
            app.NEViewField.Tooltip = {'Number of echoes'};
            app.MRDfileViewFieldLabel = uilabel(app.ScanParametersTab);
            app.MRDfileViewFieldLabel.Text = 'MRD file';
            app.MRDfileViewField = uieditfield(app.ScanParametersTab, 'text');
            app.MRDfileViewField.Editable = 'off';
            app.MRDfileViewField.Tooltip = {'MRD file name'};
            app.FAViewFieldLabel = uilabel(app.ScanParametersTab);
            app.FAViewFieldLabel.Text = 'FA (deg)';
            app.FAViewField = uieditfield(app.ScanParametersTab, 'text');
            app.FAViewField.Editable = 'off';
            app.FAViewField.Tooltip = {'Flip angle(s)'};
            app.TrajectoryViewFieldLabel = uilabel(app.ScanParametersTab);
            app.TrajectoryViewFieldLabel.Text = 'trajectory';
            app.TrajectoryViewField = uieditfield(app.ScanParametersTab, 'text');
            app.TrajectoryViewField.Editable = 'off';
            app.TrajectoryViewField.Tooltip = {'Trajectory file'};
            app.ScanTimeViewFieldLabel = uilabel(app.ScanParametersTab);
            app.ScanTimeViewFieldLabel.Text = 'scan time';
            app.ScanTimeViewField = uieditfield(app.ScanParametersTab, 'text');
            app.ScanTimeViewField.Editable = 'off';
            app.ScanTimeViewField.Tooltip = {'Scan time'};
            app.TimeDynViewFieldLabel = uilabel(app.ScanParametersTab);
            app.TimeDynViewFieldLabel.Text = 'time / dyn';
            app.TimeDynViewField = uieditfield(app.ScanParametersTab, 'text');
            app.TimeDynViewField.Editable = 'off';
            app.TimeDynViewField.Tooltip = {'Time per dynamic'};
            app.VencViewFieldLabel = uilabel(app.ScanParametersTab);
            app.VencViewFieldLabel.Text = 'Venc (DAC)';
            app.VencViewField = uieditfield(app.ScanParametersTab, 'text');
            app.VencViewField.Editable = 'off';
            app.VencViewField.Tooltip = {'Flow encoding Venc'};
            app.GeometryTab = uitab(app.ScanParameterTabGroup);
            app.GeometryTab.Title = 'Geometry';
            app.FieldOfViewViewField2Label = uilabel(app.GeometryTab);
            app.FieldOfViewViewField2Label.Tooltip = {'Field of view'};
            app.FieldOfViewViewField2Label.Text = 'FOV (mm)';
            app.FieldOfViewViewField2 = uieditfield(app.GeometryTab, 'numeric');
            app.FieldOfViewViewField2.Limits = [0 Inf];
            app.FieldOfViewViewField2.ValueDisplayFormat = '%.2f';
            app.FieldOfViewViewField2.Editable = 'off';
            app.FieldOfViewViewField2.Tooltip = {'Field of view'};
            app.AspectRatioViewFieldLabel = uilabel(app.GeometryTab);
            app.AspectRatioViewFieldLabel.Tooltip = {'Aspect ratio'};
            app.AspectRatioViewFieldLabel.Text = 'aspect ratio';
            app.AspectRatioViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AspectRatioViewField.Limits = [0 Inf];
            app.AspectRatioViewField.ValueDisplayFormat = '%.2f';
            app.AspectRatioViewField.Editable = 'off';
            app.AspectRatioViewField.Tooltip = {'Aspect ratio'};
            app.PhaseOrientationViewFieldLabel = uilabel(app.GeometryTab);
            app.PhaseOrientationViewFieldLabel.Tooltip = {'Phase orientation'};
            app.PhaseOrientationViewFieldLabel.Text = 'phase orientation';
            app.PhaseOrientationViewField = uieditfield(app.GeometryTab, 'numeric');
            app.PhaseOrientationViewField.Limits = [0 Inf];
            app.PhaseOrientationViewField.ValueDisplayFormat = '%.0f';
            app.PhaseOrientationViewField.Editable = 'off';
            app.PhaseOrientationViewField.Tooltip = {'Phase orientation, horizontal (1) or vertical (0)'};
            app.SliceThicknessViewFieldLabel = uilabel(app.GeometryTab);
            app.SliceThicknessViewFieldLabel.Tooltip = {'Slice thickness'};
            app.SliceThicknessViewFieldLabel.Text = 'slice thickness (mm)';
            app.SliceThicknessViewField = uieditfield(app.GeometryTab, 'numeric');
            app.SliceThicknessViewField.Limits = [0 Inf];
            app.SliceThicknessViewField.ValueDisplayFormat = '%.2f';
            app.SliceThicknessViewField.Editable = 'off';
            app.SliceThicknessViewField.Tooltip = {'Slice thickness'};
            app.OffsetXViewFieldLabel = uilabel(app.GeometryTab);
            app.OffsetXViewFieldLabel.Tooltip = {'X offset'};
            app.OffsetXViewFieldLabel.Text = 'offset X (mm)';
            app.OffsetXViewField = uieditfield(app.GeometryTab, 'numeric');
            app.OffsetXViewField.ValueDisplayFormat = '%.2f';
            app.OffsetXViewField.Editable = 'off';
            app.OffsetXViewField.Tooltip = {'X offset'};
            app.OffsetYViewFieldLabel = uilabel(app.GeometryTab);
            app.OffsetYViewFieldLabel.Tooltip = {'Y offset'};
            app.OffsetYViewFieldLabel.Text = 'offset Y (mm)';
            app.OffsetYViewField = uieditfield(app.GeometryTab, 'numeric');
            app.OffsetYViewField.ValueDisplayFormat = '%.2f';
            app.OffsetYViewField.Editable = 'off';
            app.OffsetYViewField.Tooltip = {'Y offset'};
            app.OffsetZViewField = uieditfield(app.GeometryTab, 'numeric');
            app.OffsetZViewField.ValueDisplayFormat = '%.2f';
            app.OffsetZViewField.Editable = 'off';
            app.OffsetZViewField.Tooltip = {'Z offset'};
            app.OffsetZViewFieldLabel = uilabel(app.GeometryTab);
            app.OffsetZViewFieldLabel.Tooltip = {'Z offset'};
            app.OffsetZViewFieldLabel.Text = 'offset Z (mm)';
            app.AngleXViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AngleXViewField.ValueDisplayFormat = '%.0f';
            app.AngleXViewField.Editable = 'off';
            app.AngleXViewField.Tooltip = {'X angle'};
            app.AngleXViewFieldLabel = uilabel(app.GeometryTab);
            app.AngleXViewFieldLabel.Tooltip = {'X angle'};
            app.AngleXViewFieldLabel.Text = 'angle X (deg)';
            app.AngleYViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AngleYViewField.ValueDisplayFormat = '%.0f';
            app.AngleYViewField.Editable = 'off';
            app.AngleYViewField.Tooltip = {'Y angle'};
            app.AngleYViewFieldLabel = uilabel(app.GeometryTab);
            app.AngleYViewFieldLabel.Tooltip = {'Y angle'};
            app.AngleYViewFieldLabel.Text = 'angle Y (deg)';
            app.AngleZViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AngleZViewField.ValueDisplayFormat = '%.0f';
            app.AngleZViewField.Editable = 'off';
            app.AngleZViewField.Tooltip = {'Z angle'};
            app.AngleZViewFieldLabel = uilabel(app.GeometryTab);
            app.AngleZViewFieldLabel.Tooltip = {'Z angle'};
            app.AngleZViewFieldLabel.Text = 'angle Z (deg)';
            app.SliceGapViewFieldLabel = uilabel(app.GeometryTab);
            app.SliceGapViewFieldLabel.Tooltip = {'Slice gap'};
            app.SliceGapViewFieldLabel.Text = 'slice gap (mm)';
            app.SliceGapViewField = uieditfield(app.GeometryTab, 'numeric');
            app.SliceGapViewField.ValueDisplayFormat = '%.2f';
            app.SliceGapViewField.Editable = 'off';
            app.SliceGapViewField.Tooltip = {'Slice gap'};
            app.SlabOverlapEditFieldLabel = uilabel(app.GeometryTab);
            app.SlabOverlapEditFieldLabel.Tooltip = {'Slab overlap (pixels)'};
            app.SlabOverlapEditFieldLabel.Text = 'slab overlap (pixels)';
            app.SlabOverlapEditField = uieditfield(app.GeometryTab, 'numeric');
            app.SlabOverlapEditField.Limits = [0 Inf];
            app.SlabOverlapEditField.ValueDisplayFormat = '%.0f';
            app.SlabOverlapEditField.Tooltip = {'Slab overlap (pixels)'};
            app.SlabOverlapEditField.Value = 1;
            app.ReconstructionTab = uitab(app.ScanParameterTabGroup);
            app.ReconstructionTab.Title = 'Reconstruction';
            app.DeRingCheckBox = uicheckbox(app.ReconstructionTab);
            app.DeRingCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.DeRingCheckBox.Tooltip = {'Check for Gibbs ringing filter'};
            app.DeRingCheckBox.Text = '';
            app.DeRingLabel = uilabel(app.ReconstructionTab);
            app.DeRingLabel.Tooltip = {'Gibbs ringing filter'};
            app.DeRingLabel.Text = 'deRing';
            app.NLMFdeNoiseLabel = uilabel(app.ReconstructionTab);
            app.NLMFdeNoiseLabel.Tooltip = {'NLMF denoising'};
            app.NLMFdeNoiseLabel.Text = 'NLMF';
            app.NLMFdeNoiseCheckBox = uicheckbox(app.ReconstructionTab);
            app.NLMFdeNoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.NLMFdeNoiseCheckBox.Tooltip = {'Check for PCA denoising'};
            app.NLMFdeNoiseCheckBox.Text = '';
            app.NLMFdeNoiseFilterStrengthLabel = uilabel(app.ReconstructionTab);
            app.NLMFdeNoiseFilterStrengthLabel.Tooltip = {'NLMF denoising'};
            app.NLMFdeNoiseFilterStrengthLabel.Text = 'filter strength';
            app.NLMFdeNoiseFilterStrengthEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.NLMFdeNoiseFilterStrengthEditField.Limits = [0.0001 1];
            app.NLMFdeNoiseFilterStrengthEditField.ValueDisplayFormat = '%.4f';
            app.NLMFdeNoiseFilterStrengthEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.NLMFdeNoiseFilterStrengthEditField.Tooltip = {'PCA denoising filter window (pixels)'};
            app.NLMFdeNoiseFilterStrengthEditField.Value = 0.005;
            app.CDTVdeNoiseLabel = uilabel(app.ReconstructionTab);
            app.CDTVdeNoiseLabel.Tooltip = {'CDTV denoising'};
            app.CDTVdeNoiseLabel.Text = 'CDTV';
            app.CDTVdeNoiseCheckBox = uicheckbox(app.ReconstructionTab);
            app.CDTVdeNoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.CDTVdeNoiseCheckBox.Tooltip = {'Check for PCA denoising'};
            app.CDTVdeNoiseCheckBox.Text = '';
            app.CDTVdeNoiseLamdaLabel = uilabel(app.ReconstructionTab);
            app.CDTVdeNoiseLamdaLabel.Tooltip = {'CDTV denoising'};
            app.CDTVdeNoiseLamdaLabel.Text = 'lambda';
            app.CDTVdeNoiseLambdaEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.CDTVdeNoiseLambdaEditField.Limits = [5 10000];
            app.CDTVdeNoiseLambdaEditField.RoundFractionalValues = 'on';
            app.CDTVdeNoiseLambdaEditField.ValueDisplayFormat = '%.0f';
            app.CDTVdeNoiseLambdaEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.CDTVdeNoiseLambdaEditField.Tooltip = {'PCA denoising filter window (pixels)'};
            app.CDTVdeNoiseLambdaEditField.Value = 5000;
            app.AFGPdeNoiseLabel = uilabel(app.ReconstructionTab);
            app.AFGPdeNoiseLabel.Tooltip = {'AFGP denoising'};
            app.AFGPdeNoiseLabel.Text = 'AFGP';
            app.AFGPdeNoiseCheckBox = uicheckbox(app.ReconstructionTab);
            app.AFGPdeNoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.AFGPdeNoiseCheckBox.Tooltip = {'Check for PCA denoising'};
            app.AFGPdeNoiseCheckBox.Text = '';
            app.AFGPdeNoiseIterationsLabel = uilabel(app.ReconstructionTab);
            app.AFGPdeNoiseIterationsLabel.Tooltip = {'AFGP denoising'};
            app.AFGPdeNoiseIterationsLabel.Text = 'iterations';
            app.AFGPdeNoiseIterationsEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.AFGPdeNoiseIterationsEditField.Limits = [2 500];
            app.AFGPdeNoiseIterationsEditField.RoundFractionalValues = 'on';
            app.AFGPdeNoiseIterationsEditField.ValueDisplayFormat = '%.0f';
            app.AFGPdeNoiseIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.AFGPdeNoiseIterationsEditField.Tooltip = {'PCA denoising filter window (pixels)'};
            app.AFGPdeNoiseIterationsEditField.Value = 50;
            app.PCAdeNoiseLabel = uilabel(app.ReconstructionTab);
            app.PCAdeNoiseLabel.Tooltip = {'PCA denoising'};
            app.PCAdeNoiseLabel.Text = 'PCA';
            app.PCAdeNoiseCheckBox = uicheckbox(app.ReconstructionTab);
            app.PCAdeNoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.PCAdeNoiseCheckBox.Tooltip = {'Check for PCA denoising'};
            app.PCAdeNoiseCheckBox.Text = '';
            app.PCAdeNoiseWindowLabel = uilabel(app.ReconstructionTab);
            app.PCAdeNoiseWindowLabel.Tooltip = {'PCA denoising'};
            app.PCAdeNoiseWindowLabel.Text = 'window';
            app.PCAdeNoiseWindowEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.PCAdeNoiseWindowEditField.Limits = [2 64];
            app.PCAdeNoiseWindowEditField.RoundFractionalValues = 'on';
            app.PCAdeNoiseWindowEditField.ValueDisplayFormat = '%.0f';
            app.PCAdeNoiseWindowEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.PCAdeNoiseWindowEditField.Tooltip = {'PCA denoising filter window (pixels)'};
            app.PCAdeNoiseWindowEditField.Value = 8;
            app.TVxyzEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.TVxyzEditField.Limits = [0 2];
            app.TVxyzEditField.ValueDisplayFormat = '%.4f';
            app.TVxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.TVxyzEditField.Tooltip = {'Total Varation regularization in spatial domain'};
            app.TVxyzEditFieldLabel = uilabel(app.ReconstructionTab);
            app.TVxyzEditFieldLabel.Text = 'TVxyz';
            app.TVtimeEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.TVtimeEditField.Limits = [0 2];
            app.TVtimeEditField.ValueDisplayFormat = '%.4f';
            app.TVtimeEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.TVtimeEditField.Tooltip = {'Total Variation regularization in time domain'};
            app.TVtimeEditField.Value = 0.001;
            app.TVtimeEditFieldLabel = uilabel(app.ReconstructionTab);
            app.TVtimeEditFieldLabel.Text = 'TVtime';
            app.WVxyzEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.WVxyzEditField.Limits = [0 2];
            app.WVxyzEditField.ValueDisplayFormat = '%.4f';
            app.WVxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.WVxyzEditField.Tooltip = {'L1 Wavelet regularization in spatial domain '};
            app.WVxyzEditField.Value = 0.001;
            app.WVxyzEditFieldLabel = uilabel(app.ReconstructionTab);
            app.WVxyzEditFieldLabel.Text = 'WVxyz';
            app.LLRxyzEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.LLRxyzEditField.Limits = [0 2];
            app.LLRxyzEditField.ValueDisplayFormat = '%.4f';
            app.LLRxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.LLRxyzEditField.Tooltip = {'Locally Low-rank regularization in spatial domain'};
            app.LLRxyzEditField.Value = 0.001;
            app.LLRxyzEditFieldLabel = uilabel(app.ReconstructionTab);
            app.LLRxyzEditFieldLabel.Text = 'LLRxyz';
            app.CompressedsensingLabel = uilabel(app.ReconstructionTab);
            app.CompressedsensingLabel.Text = 'Compressed sensing';
            app.DLdenoiseCheckBox = uicheckbox(app.ReconstructionTab);
            app.DLdenoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.DLdenoiseCheckBox.Text = '';
            app.DeeplearningLabel = uilabel(app.ReconstructionTab);
            app.DeeplearningLabel.Text = 'Deep learning';
            app.DeNoisingLabel = uilabel(app.ReconstructionTab);
            app.DeNoisingLabel.Text = 'Denoising';
            app.DLModelDropDownLabel = uilabel(app.ReconstructionTab);
            app.DLModelDropDownLabel.Text = 'model';
            app.DLModelDropDown = uidropdown(app.ReconstructionTab);
            app.DLModelDropDown.Items = {'ircnn_gray', 'dncnn3', 'dncnn_15', 'dncnn_25', 'dncnn_50', 'dncnn_gray_blind'};
            app.DLModelDropDown.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.DLModelDropDown.Value = 'ircnn_gray';
            app.ZTEIterationsEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.ZTEIterationsEditField.Limits = [10 1000];
            app.ZTEIterationsEditField.RoundFractionalValues = 'on';
            app.ZTEIterationsEditField.ValueDisplayFormat = '%.0f';
            app.ZTEIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.ZTEIterationsEditField.Enable = 'off';
            app.ZTEIterationsEditField.Tooltip = {'ZTE max number of iterations during CS reconstruction'};
            app.ZTEIterationsEditField.Value = 500;
            app.ZTEIterationsLabel = uilabel(app.ReconstructionTab);
            app.ZTEIterationsLabel.Enable = 'off';
            app.ZTEIterationsLabel.Text = 'CS iterations';
            app.MultiEchoLabel = uilabel(app.ReconstructionTab);
            app.MultiEchoLabel.Text = 'Multi-echo';
            app.SumEchosCheckBox = uicheckbox(app.ReconstructionTab);
            app.SumEchosCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.SumEchosCheckBox.Text = '';
            app.SumEchosLabel = uilabel(app.ReconstructionTab);
            app.SumEchosLabel.Text = 'sum echos';
            app.RadialTab = uitab(app.ScanParameterTabGroup);
            app.RadialTab.Title = 'Radial';
            app.GradientdelaysPanel = uipanel(app.RadialTab);
            app.GradientdelaysPanel.Title = 'Gradient delays';
            app.GradDelayCalibrationCheckBox = uicheckbox(app.GradientdelaysPanel);
            app.GradDelayCalibrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.GradDelayCalibrationCheckBox.Tooltip = {'Automatic calibration of gradient delays'};
            app.GradDelayCalibrationCheckBox.Enable = 'off';
            app.GradDelayCalibrationCheckBox.Text = '';
            app.GradDelayCalibrationCheckBoxLabel = uilabel(app.GradientdelaysPanel);
            app.GradDelayCalibrationCheckBoxLabel.Tooltip = {'Automatic calibration of gradient delays'};
            app.GradDelayCalibrationCheckBoxLabel.Text = 'calibration';
            app.RingMethodCheckBox = uicheckbox(app.GradientdelaysPanel);
            app.RingMethodCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.RingMethodCheckBox.Tooltip = {'Ring method calibration [Rosenzweig et al., MRM 81:1898-1906 (2019)]'};
            app.RingMethodCheckBox.Enable = 'off';
            app.RingMethodCheckBox.Text = '';
            app.RingMethodCheckBoxLabel = uilabel(app.GradientdelaysPanel);
            app.RingMethodCheckBoxLabel.Tooltip = {'Ring method calibration [Rosenzweig et al., MRM 81:1898-1906 (2019)]'};
            app.RingMethodCheckBoxLabel.Text = 'ring method';
            app.AbortGradCalButton = uibutton(app.GradientdelaysPanel, 'push');
            app.AbortGradCalButton.ButtonPushedFcn = createCallbackFcn(app, @AbortGradCalButtonPushed, true);
            app.AbortGradCalButton.Enable = 'off';
            app.AbortGradCalButton.Tooltip = {'Abort gradient calibration'};
            app.AbortGradCalButton.Text = 'abort';
            app.GxdelayEditFieldLabel = uilabel(app.GradientdelaysPanel);
            app.GxdelayEditFieldLabel.Text = 'Gx delay';
            app.GxDelayEditField = uieditfield(app.GradientdelaysPanel, 'numeric');
            app.GxDelayEditField.Limits = [-10 10];
            app.GxDelayEditField.ValueDisplayFormat = '%.5f';
            app.GxDelayEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.GxDelayEditField.Enable = 'off';
            app.GxDelayEditField.Tooltip = {'X-gradient delay'};
            app.GydelayEditFieldLabel = uilabel(app.GradientdelaysPanel);
            app.GydelayEditFieldLabel.Text = 'Gy delay';
            app.GyDelayEditField = uieditfield(app.GradientdelaysPanel, 'numeric');
            app.GyDelayEditField.Limits = [-10 10];
            app.GyDelayEditField.ValueDisplayFormat = '%.5f';
            app.GyDelayEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.GyDelayEditField.Enable = 'off';
            app.GyDelayEditField.Tooltip = {'Y-gradient delay'};
            app.GzdelayEditFieldLabel = uilabel(app.GradientdelaysPanel);
            app.GzdelayEditFieldLabel.Text = 'Gz delay';
            app.GzDelayEditField = uieditfield(app.GradientdelaysPanel, 'numeric');
            app.GzDelayEditField.Limits = [-10 10];
            app.GzDelayEditField.ValueDisplayFormat = '%.5f';
            app.GzDelayEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.GzDelayEditField.Enable = 'off';
            app.GzDelayEditField.Tooltip = {'Z-gradient delay'};
            app.DataOffsetRadialEditFieldLabel = uilabel(app.GradientdelaysPanel);
            app.DataOffsetRadialEditFieldLabel.Tooltip = {'Number of discarded datapoints at the beginning of UTE readout'};
            app.DataOffsetRadialEditFieldLabel.Text = 'data offset';
            app.DataOffsetRadialEditField = uieditfield(app.GradientdelaysPanel, 'numeric');
            app.DataOffsetRadialEditField.Limits = [0 512];
            app.DataOffsetRadialEditField.RoundFractionalValues = 'on';
            app.DataOffsetRadialEditField.ValueDisplayFormat = '%.0f';
            app.DataOffsetRadialEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.DataOffsetRadialEditField.Enable = 'off';
            app.DataOffsetRadialEditField.Tooltip = {'Number of discarded datapoints at the beginning of UTE readout'};
            app.CorrectionsPanel = uipanel(app.RadialTab);
            app.CorrectionsPanel.Title = 'Corrections';
            app.PhaseCorrectCheckBox = uicheckbox(app.CorrectionsPanel);
            app.PhaseCorrectCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.PhaseCorrectCheckBox.Tooltip = {'Check for phase correction'};
            app.PhaseCorrectCheckBox.Enable = 'off';
            app.PhaseCorrectCheckBox.Text = '';
            app.PhaseCorrectCheckBox.Value = true;
            app.PhaseCorrectCheckBoxLabel = uilabel(app.CorrectionsPanel);
            app.PhaseCorrectCheckBoxLabel.Tooltip = {'Check for phase correction'};
            app.PhaseCorrectCheckBoxLabel.Text = 'phase correction';
            app.CenterEchoCheckBox = uicheckbox(app.CorrectionsPanel);
            app.CenterEchoCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.CenterEchoCheckBox.Tooltip = {'Check to center the echo'};
            app.CenterEchoCheckBox.Enable = 'off';
            app.CenterEchoCheckBox.Text = '';
            app.CenterEchoCheckBox.Value = true;
            app.CenterEchoCheckBoxLabel = uilabel(app.CorrectionsPanel);
            app.CenterEchoCheckBoxLabel.Tooltip = {'Check to center the echo'};
            app.CenterEchoCheckBoxLabel.Text = 'center echo';
            app.DensityCorrectCheckBox = uicheckbox(app.CorrectionsPanel);
            app.DensityCorrectCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.DensityCorrectCheckBox.Tooltip = {'Check for density correction'};
            app.DensityCorrectCheckBox.Enable = 'off';
            app.DensityCorrectCheckBox.Text = '';
            app.DensityCorrectCheckBoxLabel = uilabel(app.CorrectionsPanel);
            app.DensityCorrectCheckBoxLabel.Tooltip = {'Check for density correction'};
            app.DensityCorrectCheckBoxLabel.Text = 'density correction';
            app.AmplitudeCorrectionCheckBox = uicheckbox(app.CorrectionsPanel);
            app.AmplitudeCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.AmplitudeCorrectionCheckBox.Tooltip = {'Check for amplitude correction'};
            app.AmplitudeCorrectionCheckBox.Enable = 'off';
            app.AmplitudeCorrectionCheckBox.Text = '';
            app.AmplitudeCorrectionCheckBoxLabel = uilabel(app.CorrectionsPanel);
            app.AmplitudeCorrectionCheckBoxLabel.Tooltip = {'Check for amplitude correction'};
            app.AmplitudeCorrectionCheckBoxLabel.Text = 'amplitude correction';
            app.TukeyFilterCheckBox = uicheckbox(app.CorrectionsPanel);
            app.TukeyFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParametersChanged, true);
            app.TukeyFilterCheckBox.Tooltip = {'Check for application of k-space Tukey filter'};
            app.TukeyFilterCheckBox.Enable = 'off';
            app.TukeyFilterCheckBox.Text = '';
            app.TukeyFilterCheckBoxLabel = uilabel(app.CorrectionsPanel);
            app.TukeyFilterCheckBoxLabel.Tooltip = {'Check for application of k-space Tukey filter'};
            app.TukeyFilterCheckBoxLabel.Text = 'Tukey filter';
            app.ZTEPanel = uipanel(app.RadialTab);
            app.ZTEPanel.Title = 'ZTE';
            app.ZTEFullEchoCheckBox = uicheckbox(app.ZTEPanel);
            app.ZTEFullEchoCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZTEDeadTimeEditFieldValueChanged, true);
            app.ZTEFullEchoCheckBox.Tooltip = {'ZTE full echo reconstruction'};
            app.ZTEFullEchoCheckBox.Enable = 'off';
            app.ZTEFullEchoCheckBox.Text = '';
            app.ZTEFullEchoLabel = uilabel(app.ZTEPanel);
            app.ZTEFullEchoLabel.Tooltip = {'ZTE full echo reconstruction'};
            app.ZTEFullEchoLabel.Text = 'full echo';
            app.ZTEDeadTimeEditField = uieditfield(app.ZTEPanel, 'numeric');
            app.ZTEDeadTimeEditField.Limits = [0 20];
            app.ZTEDeadTimeEditField.ValueDisplayFormat = '%.1f';
            app.ZTEDeadTimeEditField.ValueChangedFcn = createCallbackFcn(app, @ZTEDeadTimeEditFieldValueChanged, true);
            app.ZTEDeadTimeEditField.Enable = 'off';
            app.ZTEDeadTimeEditField.Tooltip = {'ZTE receiver dead time'};
            app.ZTEDeadTimeEditField.Value = 14;
            app.ZTEDeadTimeLabel = uilabel(app.ZTEPanel);
            app.ZTEDeadTimeLabel.Text = 'dead time (μs)';
            app.ZTEShiftEditField = uieditfield(app.ZTEPanel, 'numeric');
            app.ZTEShiftEditField.Limits = [1 7];
            app.ZTEShiftEditField.RoundFractionalValues = 'on';
            app.ZTEShiftEditField.ValueDisplayFormat = '%.0f';
            app.ZTEShiftEditField.ValueChangedFcn = createCallbackFcn(app, @ZTEDeadTimeEditFieldValueChanged, true);
            app.ZTEShiftEditField.Enable = 'off';
            app.ZTEShiftEditField.Tooltip = {'ZTE sample shift'};
            app.ZTEShiftEditField.Value = 4;
            app.ZTEShiftLabel = uilabel(app.ZTEPanel);
            app.ZTEShiftLabel.Text = 'shift (samples)';
            app.ReconstructionTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.ImagesTab = uitab(app.ReconstructionTabGroup);
            app.ImagesTab.Title = 'Images';
            app.RecoFig = uiaxes(app.ImagesTab);
            app.RecoFig.XTick = [];
            app.RecoFig.YTick = [];
            app.Dim6Slider = uislider(app.ImagesTab);
            app.Dim6Slider.Limits = [1 200];
            app.Dim6Slider.MajorTicks = [];
            app.Dim6Slider.ValueChangedFcn = createCallbackFcn(app, @Dim6SliderValueChanged, true);
            app.Dim6Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.Dim6Slider.Value = 1;
            app.Dim5Slider = uislider(app.ImagesTab);
            app.Dim5Slider.Limits = [1 200];
            app.Dim5Slider.MajorTicks = [];
            app.Dim5Slider.ValueChangedFcn = createCallbackFcn(app, @Dim5SliderValueChanged, true);
            app.Dim5Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.Dim5Slider.Value = 1;
            app.Dim4Slider = uislider(app.ImagesTab);
            app.Dim4Slider.Limits = [1 200];
            app.Dim4Slider.MajorTicks = [];
            app.Dim4Slider.ValueChangedFcn = createCallbackFcn(app, @Dim4SliderValueChanged, true);
            app.Dim4Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.Dim4Slider.Value = 1;
            app.Dim3Slider = uislider(app.ImagesTab);
            app.Dim3Slider.Limits = [1 200];
            app.Dim3Slider.MajorTicks = [];
            app.Dim3Slider.ValueChangedFcn = createCallbackFcn(app, @Dim3SliderValueChanged, true);
            app.Dim3Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.Dim3Slider.Value = 1;
            app.Dim6EditField = uieditfield(app.ImagesTab, 'numeric');
            app.Dim6EditField.Limits = [1 Inf];
            app.Dim6EditField.ValueDisplayFormat = '%.0f';
            app.Dim6EditField.ValueChangedFcn = createCallbackFcn(app, @Dim6EditFieldValueChanged, true);
            app.Dim6EditField.Value = 1;
            app.Dim5EditField = uieditfield(app.ImagesTab, 'numeric');
            app.Dim5EditField.Limits = [1 Inf];
            app.Dim5EditField.ValueDisplayFormat = '%.0f';
            app.Dim5EditField.ValueChangedFcn = createCallbackFcn(app, @Dim5EditFieldValueChanged, true);
            app.Dim5EditField.Value = 1;
            app.Dim4EditField = uieditfield(app.ImagesTab, 'numeric');
            app.Dim4EditField.Limits = [1 Inf];
            app.Dim4EditField.ValueDisplayFormat = '%.0f';
            app.Dim4EditField.ValueChangedFcn = createCallbackFcn(app, @Dim4EditFieldValueChanged, true);
            app.Dim4EditField.Value = 1;
            app.Dim3EditField = uieditfield(app.ImagesTab, 'numeric');
            app.Dim3EditField.Limits = [1 Inf];
            app.Dim3EditField.ValueDisplayFormat = '%.0f';
            app.Dim3EditField.ValueChangedFcn = createCallbackFcn(app, @Dim3EditFieldValueChanged, true);
            app.Dim3EditField.Value = 1;
            app.YLabel = uilabel(app.ImagesTab);
            app.YLabel.Text = '     Y     ';
            app.XLabel = uilabel(app.ImagesTab);
            app.XLabel.Text = '     X     ';
            app.Dim6Label = uilabel(app.ImagesTab);
            app.Dim6Label.Text = 'echo';
            app.Dim5Label = uilabel(app.ImagesTab);
            app.Dim5Label.Text = 'FA';
            app.Dim4Label = uilabel(app.ImagesTab);
            app.Dim4Label.Text = '             NR';
            app.Dim3Label = uilabel(app.ImagesTab);
            app.Dim3Label.Text = '              Z';
            app.LevelSlider = uislider(app.ImagesTab);
            app.LevelSlider.Limits = [0 65536];
            app.LevelSlider.MajorTicks = [];
            app.LevelSlider.ValueChangedFcn = createCallbackFcn(app, @LevelSliderValueChanged, true);
            app.LevelSlider.MinorTicks = [0 8192 16384 24576 32768 40960 49152 57344 65536];
            app.LevelSlider.Tooltip = {'Level'};
            app.WLabel = uilabel(app.ImagesTab);
            app.WLabel.Text = 'W';
            app.LLabel = uilabel(app.ImagesTab);
            app.LLabel.Text = 'L';
            app.WindowSlider = uislider(app.ImagesTab);
            app.WindowSlider.Limits = [0 65536];
            app.WindowSlider.MajorTicks = [];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.MinorTicks = [0 8192 16384 24576 32768 40960 49152 57344 65536];
            app.WindowSlider.Tooltip = {'Window'};
            app.LevelEditField = uieditfield(app.ImagesTab, 'numeric');
            app.LevelEditField.Limits = [0 Inf];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Tooltip = {'Level'};
            app.WindowEditField = uieditfield(app.ImagesTab, 'numeric');
            app.WindowEditField.Limits = [0 Inf];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.WindowEditField.Tooltip = {'Window'};
            app.SetButton = uibutton(app.ImagesTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Tooltip = {'Set level and window'};
            app.SetButton.Text = 'Set';
            app.MoviePlayButton = uibutton(app.ImagesTab, 'push');
            app.MoviePlayButton.ButtonPushedFcn = createCallbackFcn(app, @MovieMoviePlayButtonPushed, true);
            app.MoviePlayButton.Tooltip = {'Start movie'};
            app.MoviePlayButton.Text = '';
            app.MovieStopButton = uibutton(app.ImagesTab, 'push');
            app.MovieStopButton.ButtonPushedFcn = createCallbackFcn(app, @MovieMovieStopButtonPushed, true);
            app.MovieStopButton.Tooltip = {'Stop movie'};
            app.MovieStopButton.Text = '';
            app.AutoIntensityCheckBox = uicheckbox(app.ImagesTab);
            app.AutoIntensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoIntensityCheckBoxValueChanged, true);
            app.AutoIntensityCheckBox.Tooltip = {'Check for auto level and window'};
            app.AutoIntensityCheckBox.Text = '';
            app.AutoIntensityCheckBox.Value = true;
            app.AutoIntensityCheckBoxLabel = uilabel(app.ImagesTab);
            app.AutoIntensityCheckBoxLabel.Tooltip = {'Auto level and window'};
            app.AutoIntensityCheckBoxLabel.Text = 'auto';
            app.MovieImage = uiimage(app.ImagesTab);
            app.MovieImage.Enable = 'off';
            app.MovieImage.Tooltip = {'Movie dimension'};
            app.OrientationSpinnerLabel = uilabel(app.ImagesTab);
            app.OrientationSpinnerLabel.Text = 'orientation';
            app.OrientationSpinner = uispinner(app.ImagesTab);
            app.OrientationSpinner.Limits = [1 12];
            app.OrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @OrientationSpinnerValueChanged, true);
            app.OrientationSpinner.Tooltip = {'Image orienation'};
            app.OrientationSpinner.Value = 1;
            app.ThresholdEditFieldLabel = uilabel(app.ImagesTab);
            app.ThresholdEditFieldLabel.Text = 'threshold';
            app.ThresholdEditField = uieditfield(app.ImagesTab, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.ValueDisplayFormat = '%.0f';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Tooltip = {'Mask threshold'};
            app.ThresholdSlider = uislider(app.ImagesTab);
            app.ThresholdSlider.Limits = [0 4095];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [0 455 910 1365 1820 2275 2730 3185 3640 4095];
            app.ThresholdSlider.Tooltip = {'Mask threshold slider'};
            app.ErodeButton = uibutton(app.ImagesTab, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Tooltip = {'Mask erosion'};
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.ImagesTab, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Tooltip = {'Mask dilation'};
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.ImagesTab, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Mask closing'};
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.ImagesTab, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Mask opening'};
            app.OpenButton.Text = 'Open';
            app.MaskButton = uibutton(app.ImagesTab, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Tooltip = {'Image masking/segmentation'};
            app.MaskButton.Text = 'Mask';
            app.MIPTab = uitab(app.ReconstructionTabGroup);
            app.MIPTab.Tooltip = {''};
            app.MIPTab.Title = 'MIP';
            app.MIPFig = uiaxes(app.MIPTab);
            app.MIPFig.XTick = [];
            app.MIPFig.YTick = [];
            app.MIPyAngleSlider = uislider(app.MIPTab);
            app.MIPyAngleSlider.Limits = [-180 180];
            app.MIPyAngleSlider.MajorTicks = [];
            app.MIPyAngleSlider.ValueChangedFcn = createCallbackFcn(app, @MIPAngleSliderValueChanged, true);
            app.MIPyAngleSlider.MinorTicks = [-180 -162 -144 -126 -108 -90 -72 -54 -36 -18 0 18 36 54 72 90 108 126 144 162 180];
            app.MIPxAngleSlider = uislider(app.MIPTab);
            app.MIPxAngleSlider.Limits = [-180 180];
            app.MIPxAngleSlider.MajorTicks = [];
            app.MIPxAngleSlider.ValueChangedFcn = createCallbackFcn(app, @MIPAngleSliderValueChanged, true);
            app.MIPxAngleSlider.MinorTicks = [-180 -162 -144 -126 -108 -90 -72 -54 -36 -18 0 18 36 54 72 90 108 126 144 162 180];
            app.MIPyAngleLabel = uilabel(app.MIPTab);
            app.MIPyAngleLabel.Text = 'Y';
            app.MIPxAngleLabel = uilabel(app.MIPTab);
            app.MIPxAngleLabel.Text = 'X';
            app.MIPRotateLabel = uilabel(app.MIPTab);
            app.MIPRotateLabel.Text = 'Rotate';
            app.MIPAngleResetButton = uibutton(app.MIPTab, 'push');
            app.MIPAngleResetButton.ButtonPushedFcn = createCallbackFcn(app, @MIPAngleSliderResetButtonPushed, true);
            app.MIPAngleResetButton.Tooltip = {'Reset MIP orientation and stop the movie'};
            app.MIPAngleResetButton.Text = 'Reset';
            app.MIPLevelSlider = uislider(app.MIPTab);
            app.MIPLevelSlider.Limits = [0 65536];
            app.MIPLevelSlider.MajorTicks = [];
            app.MIPLevelSlider.ValueChangedFcn = createCallbackFcn(app, @MIPLevelSliderValueChanged, true);
            app.MIPLevelSlider.MinorTicks = [0 8192 16384 24576 32768 40960 49152 57344 65536];
            app.MIPLevelSlider.Tooltip = {'Level'};
            app.MIPWLabel = uilabel(app.MIPTab);
            app.MIPWLabel.Text = 'W';
            app.MIPLLabel = uilabel(app.MIPTab);
            app.MIPLLabel.Text = 'L';
            app.MIPWindowSlider = uislider(app.MIPTab);
            app.MIPWindowSlider.Limits = [0 65536];
            app.MIPWindowSlider.MajorTicks = [];
            app.MIPWindowSlider.ValueChangedFcn = createCallbackFcn(app, @MIPWindowSliderValueChanged, true);
            app.MIPWindowSlider.MinorTicks = [0 8192 16384 24576 32768 40960 49152 57344 65536];
            app.MIPWindowSlider.Tooltip = {'window'};
            app.MIPLevelEditField = uieditfield(app.MIPTab, 'numeric');
            app.MIPLevelEditField.Limits = [0 Inf];
            app.MIPLevelEditField.ValueDisplayFormat = '%.0f';
            app.MIPLevelEditField.ValueChangedFcn = createCallbackFcn(app, @MIPLevelEditFieldValueChanged, true);
            app.MIPLevelEditField.Tooltip = {'Level'};
            app.MIPWindowEditField = uieditfield(app.MIPTab, 'numeric');
            app.MIPWindowEditField.Limits = [0 Inf];
            app.MIPWindowEditField.ValueDisplayFormat = '%.0f';
            app.MIPWindowEditField.ValueChangedFcn = createCallbackFcn(app, @MIPWindowEditFieldValueChanged, true);
            app.MIPWindowEditField.Tooltip = {'Window'};
            app.MIPSetButton = uibutton(app.MIPTab, 'push');
            app.MIPSetButton.ButtonPushedFcn = createCallbackFcn(app, @MIPSetButtonPushed, true);
            app.MIPSetButton.Tooltip = {'Set Level and Window'};
            app.MIPSetButton.Text = 'Set';
            app.MIPYLabel = uilabel(app.MIPTab);
            app.MIPYLabel.Text = '     Y     ';
            app.MIPXLabel = uilabel(app.MIPTab);
            app.MIPXLabel.Text = '     X     ';
            app.MIPOrientationSpinnerLabel = uilabel(app.MIPTab);
            app.MIPOrientationSpinnerLabel.Text = 'orientation';
            app.MIPOrientationSpinner = uispinner(app.MIPTab);
            app.MIPOrientationSpinner.Limits = [1 12];
            app.MIPOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @MIPOrientationSpinnerValueChanged, true);
            app.MIPOrientationSpinner.Tooltip = {'Image orienation'};
            app.MIPOrientationSpinner.Value = 1;
            app.MIPyPlayButton = uibutton(app.MIPTab, 'push');
            app.MIPyPlayButton.ButtonPushedFcn = createCallbackFcn(app, @MIPyPlayButtonPushed, true);
            app.MIPyPlayButton.Tooltip = {'Start MIP movie'};
            app.MIPyPlayButton.Text = '';
            app.MIPxPlayButton = uibutton(app.MIPTab, 'push');
            app.MIPxPlayButton.ButtonPushedFcn = createCallbackFcn(app, @MIPxPlayButtonPushed, true);
            app.MIPxPlayButton.Tooltip = {'Play MIP movie'};
            app.MIPxPlayButton.Text = '';
            app.MIPResizeButton = uibutton(app.MIPTab, 'state');
            app.MIPResizeButton.ValueChangedFcn = createCallbackFcn(app, @MIPResizeButtonValueChanged, true);
            app.MIPResizeButton.Tooltip = {'Downsample MIP for speed'};
            app.MIPResizeButton.Text = 'Resize';
            app.PhaseTab = uitab(app.ReconstructionTabGroup);
            app.PhaseTab.Title = 'Phase';
            app.PhaseRecoFig = uiaxes(app.PhaseTab);
            app.PhaseRecoFig.XTick = [];
            app.PhaseRecoFig.YTick = [];
            app.PhaseYLabel = uilabel(app.PhaseTab);
            app.PhaseYLabel.Text = '     Y     ';
            app.PhaseXLabel = uilabel(app.PhaseTab);
            app.PhaseXLabel.Text = '     X     ';
            app.PhaseMovieStartButton = uibutton(app.PhaseTab, 'push');
            app.PhaseMovieStartButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseMovieStartButtonPushed, true);
            app.PhaseMovieStartButton.Tooltip = {'Start movie'};
            app.PhaseMovieStartButton.Text = '';
            app.PhaseMovieStopButton = uibutton(app.PhaseTab, 'push');
            app.PhaseMovieStopButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseMovieStopButtonPushed, true);
            app.PhaseMovieStopButton.Tooltip = {'Stop movie'};
            app.PhaseMovieStopButton.Text = '';
            app.PhaseDim3Slider = uislider(app.PhaseTab);
            app.PhaseDim3Slider.Limits = [1 200];
            app.PhaseDim3Slider.MajorTicks = [];
            app.PhaseDim3Slider.ValueChangedFcn = createCallbackFcn(app, @PhaseDim3SliderValueChanged, true);
            app.PhaseDim3Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.PhaseDim3Slider.Value = 1;
            app.PhaseDim5Slider = uislider(app.PhaseTab);
            app.PhaseDim5Slider.Limits = [1 200];
            app.PhaseDim5Slider.MajorTicks = [];
            app.PhaseDim5Slider.ValueChangedFcn = createCallbackFcn(app, @PhaseDim5SliderValueChanged, true);
            app.PhaseDim5Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.PhaseDim5Slider.Value = 1;
            app.PhaseDim4Slider = uislider(app.PhaseTab);
            app.PhaseDim4Slider.Limits = [1 200];
            app.PhaseDim4Slider.MajorTicks = [];
            app.PhaseDim4Slider.ValueChangedFcn = createCallbackFcn(app, @PhaseDim4SliderValueChanged, true);
            app.PhaseDim4Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.PhaseDim4Slider.Value = 1;
            app.PhaseDim3EditField = uieditfield(app.PhaseTab, 'numeric');
            app.PhaseDim3EditField.Limits = [1 Inf];
            app.PhaseDim3EditField.ValueDisplayFormat = '%.0f';
            app.PhaseDim3EditField.ValueChangedFcn = createCallbackFcn(app, @PhaseDim3EditFieldValueChanged, true);
            app.PhaseDim3EditField.Value = 1;
            app.PhaseDim5EditField = uieditfield(app.PhaseTab, 'numeric');
            app.PhaseDim5EditField.Limits = [1 Inf];
            app.PhaseDim5EditField.ValueDisplayFormat = '%.0f';
            app.PhaseDim5EditField.ValueChangedFcn = createCallbackFcn(app, @PhaseDim5EditFieldValueChanged, true);
            app.PhaseDim5EditField.Value = 1;
            app.PhaseDim4EditField = uieditfield(app.PhaseTab, 'numeric');
            app.PhaseDim4EditField.Limits = [1 Inf];
            app.PhaseDim4EditField.ValueDisplayFormat = '%.0f';
            app.PhaseDim4EditField.ValueChangedFcn = createCallbackFcn(app, @PhaseDim4EditFieldValueChanged, true);
            app.PhaseDim4EditField.Value = 1;
            app.PhaseDim6EditField = uieditfield(app.PhaseTab, 'numeric');
            app.PhaseDim6EditField.Limits = [1 Inf];
            app.PhaseDim6EditField.ValueDisplayFormat = '%.0f';
            app.PhaseDim6EditField.ValueChangedFcn = createCallbackFcn(app, @PhaseDim6EditFieldValueChanged, true);
            app.PhaseDim6EditField.Value = 1;
            app.PhaseDim6Slider = uislider(app.PhaseTab);
            app.PhaseDim6Slider.Limits = [1 200];
            app.PhaseDim6Slider.MajorTicks = [];
            app.PhaseDim6Slider.ValueChangedFcn = createCallbackFcn(app, @PhaseDim6SliderValueChanged, true);
            app.PhaseDim6Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.PhaseDim6Slider.Value = 1;
            app.PhaseDim3Label = uilabel(app.PhaseTab);
            app.PhaseDim3Label.Text = '              Z';
            app.PhaseDim4Label = uilabel(app.PhaseTab);
            app.PhaseDim4Label.Text = '             NR';
            app.PhaseDim5Label = uilabel(app.PhaseTab);
            app.PhaseDim5Label.Text = 'FA';
            app.PhaseDim6Label = uilabel(app.PhaseTab);
            app.PhaseDim6Label.Text = 'echo';
            app.PhaseMovieImage = uiimage(app.PhaseTab);
            app.PhaseMovieImage.Enable = 'off';
            app.PhaseMovieImage.Tooltip = {'Movie dimension'};
            app.PhaseOrientationSpinnerLabel = uilabel(app.PhaseTab);
            app.PhaseOrientationSpinnerLabel.Text = 'orientation';
            app.PhaseOrientationSpinner = uispinner(app.PhaseTab);
            app.PhaseOrientationSpinner.Limits = [1 12];
            app.PhaseOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @PhaseOrientationSpinnerValueChanged, true);
            app.PhaseOrientationSpinner.Tooltip = {'Image orientation'};
            app.PhaseOrientationSpinner.Value = 1;
            app.PhaseUnwrapButton = uibutton(app.PhaseTab, 'push');
            app.PhaseUnwrapButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseUnwrapButtonPushed, true);
            app.PhaseUnwrapButton.Tooltip = {'Phase unwrapping'};
            app.PhaseUnwrapButton.Text = 'Unwrap';
            app.PhaseMaxLabel = uilabel(app.PhaseTab);
            app.PhaseMaxLabel.Text = 'max';
            app.PhaseMinLabel = uilabel(app.PhaseTab);
            app.PhaseMinLabel.Text = 'min';
            app.FlowTab = uitab(app.ReconstructionTabGroup);
            app.FlowTab.Title = '4D-Flow';
            app.FlowRecoFig = uiaxes(app.FlowTab);
            app.FlowRecoFig.XTick = [];
            app.FlowRecoFig.YTick = [];
            app.FlowYLabel = uilabel(app.FlowTab);
            app.FlowYLabel.Text = '     Y     ';
            app.FlowXLabel = uilabel(app.FlowTab);
            app.FlowXLabel.Text = '     X     ';
            app.FlowDim3Slider = uislider(app.FlowTab);
            app.FlowDim3Slider.Limits = [1 200];
            app.FlowDim3Slider.MajorTicks = [];
            app.FlowDim3Slider.ValueChangedFcn = createCallbackFcn(app, @FlowDim3SliderValueChanged, true);
            app.FlowDim3Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.FlowDim3Slider.Value = 1;
            app.FlowDim5Slider = uislider(app.FlowTab);
            app.FlowDim5Slider.Limits = [1 200];
            app.FlowDim5Slider.MajorTicks = [];
            app.FlowDim5Slider.ValueChangedFcn = createCallbackFcn(app, @FlowDim5SliderValueChanged, true);
            app.FlowDim5Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.FlowDim5Slider.Value = 1;
            app.FlowDim4Slider = uislider(app.FlowTab);
            app.FlowDim4Slider.Limits = [1 200];
            app.FlowDim4Slider.MajorTicks = [];
            app.FlowDim4Slider.ValueChangedFcn = createCallbackFcn(app, @FlowDim4SliderValueChanged, true);
            app.FlowDim4Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.FlowDim4Slider.Value = 1;
            app.FlowDim3EditField = uieditfield(app.FlowTab, 'numeric');
            app.FlowDim3EditField.Limits = [1 Inf];
            app.FlowDim3EditField.ValueDisplayFormat = '%.0f';
            app.FlowDim3EditField.ValueChangedFcn = createCallbackFcn(app, @FlowDim3EditFieldValueChanged, true);
            app.FlowDim3EditField.Value = 1;
            app.FlowDim5EditField = uieditfield(app.FlowTab, 'numeric');
            app.FlowDim5EditField.Limits = [1 Inf];
            app.FlowDim5EditField.ValueDisplayFormat = '%.0f';
            app.FlowDim5EditField.ValueChangedFcn = createCallbackFcn(app, @FlowDim5EditFieldValueChanged, true);
            app.FlowDim5EditField.Value = 1;
            app.FlowDim4EditField = uieditfield(app.FlowTab, 'numeric');
            app.FlowDim4EditField.Limits = [1 Inf];
            app.FlowDim4EditField.ValueDisplayFormat = '%.0f';
            app.FlowDim4EditField.ValueChangedFcn = createCallbackFcn(app, @FlowDim4EditFieldValueChanged, true);
            app.FlowDim4EditField.Value = 1;
            app.FlowDim6EditField = uieditfield(app.FlowTab, 'numeric');
            app.FlowDim6EditField.Limits = [1 Inf];
            app.FlowDim6EditField.ValueDisplayFormat = '%.0f';
            app.FlowDim6EditField.ValueChangedFcn = createCallbackFcn(app, @FlowDim6EditFieldValueChanged, true);
            app.FlowDim6EditField.Value = 1;
            app.FlowDim6Slider = uislider(app.FlowTab);
            app.FlowDim6Slider.Limits = [1 200];
            app.FlowDim6Slider.MajorTicks = [];
            app.FlowDim6Slider.ValueChangedFcn = createCallbackFcn(app, @FlowDim6SliderValueChanged, true);
            app.FlowDim6Slider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.FlowDim6Slider.Value = 1;
            app.FlowDim3Label = uilabel(app.FlowTab);
            app.FlowDim3Label.Text = '              Z';
            app.FlowDim4Label = uilabel(app.FlowTab);
            app.FlowDim4Label.Text = '             NR';
            app.FlowDim5Label = uilabel(app.FlowTab);
            app.FlowDim5Label.Text = 'FA';
            app.FlowDim6Label = uilabel(app.FlowTab);
            app.FlowDim6Label.Text = 'echo';
            app.FlowMovieImage = uiimage(app.FlowTab);
            app.FlowMovieImage.Enable = 'off';
            app.FlowOrientationSpinnerLabel = uilabel(app.FlowTab);
            app.FlowOrientationSpinnerLabel.Text = 'orientation';
            app.FlowOrientationSpinner = uispinner(app.FlowTab);
            app.FlowOrientationSpinner.Limits = [1 12];
            app.FlowOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @FlowOrientationSpinnerValueChanged, true);
            app.FlowOrientationSpinner.Tooltip = {'Image orientation'};
            app.FlowOrientationSpinner.Value = 1;
            app.FlowMovieStartButton = uibutton(app.FlowTab, 'push');
            app.FlowMovieStartButton.ButtonPushedFcn = createCallbackFcn(app, @FlowMovieStartButtonPushed, true);
            app.FlowMovieStartButton.Tooltip = {'Start movie'};
            app.FlowMovieStartButton.Text = '';
            app.FlowMovieStopButton = uibutton(app.FlowTab, 'push');
            app.FlowMovieStopButton.ButtonPushedFcn = createCallbackFcn(app, @FlowMovieStopButtonPushed, true);
            app.FlowMovieStopButton.Tooltip = {'Stop movie'};
            app.FlowMovieStopButton.Text = '';
            app.FlowCalcButton = uibutton(app.FlowTab, 'push');
            app.FlowCalcButton.ButtonPushedFcn = createCallbackFcn(app, @FlowCalcButtonPushed, true);
            app.FlowCalcButton.Enable = 'off';
            app.FlowCalcButton.Tooltip = {'Calculate 4D flow'};
            app.FlowCalcButton.Text = 'Calc flow';
            app.FlowLoadVencTableButton = uibutton(app.FlowTab, 'push');
            app.FlowLoadVencTableButton.ButtonPushedFcn = createCallbackFcn(app, @FlowLoadVencTableButtonPushed, true);
            app.FlowLoadVencTableButton.Tooltip = {'Load flow encoding table'};
            app.FlowLoadVencTableButton.Text = 'Venc table';
            app.FlowDirectionSpinnerLabel = uilabel(app.FlowTab);
            app.FlowDirectionSpinnerLabel.Text = 'Vz (cm/s)';
            app.FlowDirectionSpinner = uispinner(app.FlowTab);
            app.FlowDirectionSpinner.Limits = [1 5];
            app.FlowDirectionSpinner.ValueChangedFcn = createCallbackFcn(app, @FlowDirectionSpinnerValueChanged, true);
            app.FlowDirectionSpinner.Tooltip = {'Flow encoding direction'};
            app.FlowDirectionSpinner.Value = 1;
            app.FlowVencLamp = uilamp(app.FlowTab);
            app.FlowMinLabel = uilabel(app.FlowTab);
            app.FlowMinLabel.Text = 'min';
            app.FlowMaxLabel = uilabel(app.FlowTab);
            app.FlowMaxLabel.Text = 'max';
            app.MessagesTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.MessagesTab = uitab(app.MessagesTabGroup);
            app.MessagesTab.Title = 'Messages';
            app.MessageBox = uitextarea(app.MessagesTab);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Tooltip = {'Messages'};
            app.MessageBox.Value = {'Messages ...'};
            app.StatusBox = uitextarea(app.MessagesTab);
            app.StatusBox.Editable = 'off';
            app.StatusBox.Tooltip = {'App status'};
            app.StatusLabel = uilabel(app.MessagesTab);
            app.StatusLabel.Text = 'STATUS';
            app.StatusImage = uiimage(app.MessagesTab);
            app.SettingsTab = uitab(app.MessagesTabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SoundsLabel = uilabel(app.SettingsTab);
            app.SoundsLabel.Text = 'Sounds';
            app.AutoSoundsCheckBox = uicheckbox(app.SettingsTab);
            app.AutoSoundsCheckBox.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBox.Text = '';
            app.AutoSoundsCheckBox.Value = true;
            app.AutoSoundsCheckBoxLabel = uilabel(app.SettingsTab);
            app.AutoSoundsCheckBoxLabel.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBoxLabel.Text = 'sounds on/off';
            app.ColorsLabel = uilabel(app.SettingsTab);
            app.ColorsLabel.Text = 'Colors';
            app.ColorsButtonGroup = uibuttongroup(app.SettingsTab);
            app.ColorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorsButtonGroupSelectionChanged, true);
            app.ColorsButtonGroup.Tooltip = {'App color scheme selection'};
            app.ClassicButton = uiradiobutton(app.ColorsButtonGroup);
            app.ClassicButton.Text = 'classic';
            app.ClassicButton.Interpreter = 'html';
            app.BlackWhiteButton = uiradiobutton(app.ColorsButtonGroup);
            app.BlackWhiteButton.Text = 'black/white';
            app.BlackWhiteButton.Interpreter = 'html';
            app.BlackWhiteButton.Value = true;
            app.MessageLevelSpinner = uispinner(app.SettingsTab);
            app.MessageLevelSpinner.Limits = [1 3];
            app.MessageLevelSpinner.Value = 1;
            app.MessageLevelSpinnerLabel = uilabel(app.SettingsTab);
            app.MessageLevelSpinnerLabel.Text = 'message level';
            app.MessagesLabel = uilabel(app.SettingsTab);
            app.MessagesLabel.Text = 'Messages';
            app.Hyperlink = uihyperlink(app.P2ROUD_UIFigure);
            app.Hyperlink.URL = 'https://github.com/Moby1971/';
            app.Hyperlink.Text = 'Github';
            % Show the figure after all components are created
            app.P2ROUD_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P2ROUD

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.P2ROUD_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.P2ROUD_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.P2ROUD_UIFigure)
        end
    end
end